classdef MANOVA_pop_out_data < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        SaveRawDataButton             matlab.ui.control.Button
        SelectZMarkerBinListBox       matlab.ui.control.ListBox
        SelectZMarkerBinListBoxLabel  matlab.ui.control.Label
        CellLinesUsedListBox          matlab.ui.control.ListBox
        CellLinesUsedListBoxLabel     matlab.ui.control.Label
        RightPanel                    matlab.ui.container.Panel
        TabGroup                      matlab.ui.container.TabGroup
        MANOVATable                   matlab.ui.container.Tab
        manova_summary_table          matlab.ui.control.Table
        SaveMANOVATableButton         matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    properties (Access = public)
        manova_app_data
        marker_bin_widths % Description

        xvar
        yvar
        zvar

        %% Tables
        manova_table_data = [];
    end

    methods (Access = public)
        function update_data_display(app)
            bin_indx = app.SelectZMarkerBinListBox.Value;
            manova_data = app.manova_app_data.zvar_bin_cell_lines_plot_data{bin_indx};

            switch app.TabGroup.SelectedTab.Title
                case "MANOVA Table"
                    % Generate manova table
                    manova_result = manova(manova_data,[app.xvar,app.yvar], ...
                        CategoricalFactors="cell line", ...
                        FactorNames="cell line", ...
                        TestStatistic="all");
                    stats_table = stats(manova_result);

                    % Reformat table to display
                    new_stats_table = table2cell(stats_table);
                    [nrows,ncols] = size(new_stats_table);

                    for row_indx = 1:nrows
                        for col_indx = 1:ncols
                            if all(isnan(new_stats_table{row_indx,col_indx}))
                                new_stats_table{row_indx,col_indx} = '         ';
                            end
                        end
                    end

                    new_stats_table = cell2table(new_stats_table);

                    % nrows = height(stats_table);
                    stats_table_var_names = ["Source","DF","Test Statistic","Value","F","DF Numerator","DF Denominator","p-Value"];
                    new_stats_table.Properties.VariableNames = stats_table_var_names;
                    % stats_table.Properties.VariableNames = stats_table_var_names;
                    % new_stats_table = table('Size',[height(stats_table),8], ...
                    %     'VariableTypes',["string","double","string",repelem("string",5)], ...
                    %     'VariableNames',stats_table_var_names);
                    %
                    %
                    % new_stats_table.("Source") = stats_table.("Source");
                    % new_stats_table.("DF") = num2cell(stats_table.("DF"));
                    % new_stats_table.("Test Statistic") = stats_table.("Test Statistic");
                    % % new_stats_table.("Value") = stats_table.("Value");
                    % % new_stats_table.("F") = stats_table.("F");
                    % % new_stats_table.("DF Numerator") = stats_table.("DF Numerator");
                    % % new_stats_table.("DF Denominator") = stats_table.("DF Denominator");
                    % % new_stats_table.("p-Value") = stats_table.("p-Value");
                    %
                    % new_stats_table(:,["Value","F","DF Numerator","DF Denominator","p-Value"]) = num2cell(stats_table(:,["Value","F","DF Numerator","DF Denominator","p-Value"]).Variables);

                    app.manova_summary_table.Data = new_stats_table;
                    app.manova_summary_table.ColumnName = new_stats_table.Properties.VariableNames;

                    app.manova_table_data = new_stats_table;
            end
        end

        function manova_app_save_table(app,table,name)
            %% Ask where to save file
            [file,path,indx] = uiputfile('*.*','File Selection',name + ".csv");
            figure(app.UIFigure);
            %% Save file
            if indx
                writetable(table,fullfile(path,file));
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, manova_app_data)
            app.manova_app_data = manova_app_data;
            app.xvar = manova_app_data.xvar;
            app.yvar = manova_app_data.yvar;
            app.zvar = manova_app_data.zvar;

            %% Setup marker bin options list
            app.marker_bin_widths = string.empty;
            for bin_indx = 1:(numel(manova_app_data.zvar_bin_boundaries)-1)
                app.marker_bin_widths = [app.marker_bin_widths;manova_app_data.zvar_bin_boundaries(bin_indx) + " - " + manova_app_data.zvar_bin_boundaries(bin_indx+1)];
            end

            app.SelectZMarkerBinListBox.Items = app.marker_bin_widths;
            app.SelectZMarkerBinListBox.ItemsData = 1:(numel(manova_app_data.zvar_bin_boundaries)-1);

            %% Setup cell line list
            app.CellLinesUsedListBox.Items = manova_app_data.selected_cell_lines;

        end

        % Value changed function: SelectZMarkerBinListBox
        function SelectZMarkerBinListBoxValueChanged(app, event)
            app.update_data_display();
        end

        % Button pushed function: SaveMANOVATableButton
        function SaveMANOVATableButtonPushed(app, event)
            app.manova_app_save_table(app.manova_table_data,"MANOVA " + app.zvar + " with range " + app.marker_bin_widths(app.SelectZMarkerBinListBox.Value));
        end

        % Button pushed function: SaveRawDataButton
        function SaveRawDataButtonPushed(app, event)
            bin_indx = app.SelectZMarkerBinListBox.Value;
            manova_data = app.manova_app_data.zvar_bin_cell_lines_plot_data{bin_indx};
            app.manova_app_save_table(manova_data,"Data for MANOVA " + app.zvar + " with range " + app.marker_bin_widths(app.SelectZMarkerBinListBox.Value));
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.CellLinesUsedListBoxLabel = uilabel(app.LeftPanel);
            app.CellLinesUsedListBoxLabel.Enable = 'off';
            app.CellLinesUsedListBoxLabel.Text = 'Cell Lines Used';
            app.CellLinesUsedListBox = uilistbox(app.LeftPanel);
            app.CellLinesUsedListBox.Items = {};
            app.CellLinesUsedListBox.Enable = 'off';
            app.CellLinesUsedListBox.Value = {};
            app.SelectZMarkerBinListBoxLabel = uilabel(app.LeftPanel);
            app.SelectZMarkerBinListBoxLabel.Text = 'Select Z Marker Bin';
            app.SelectZMarkerBinListBox = uilistbox(app.LeftPanel);
            app.SelectZMarkerBinListBox.Items = {};
            app.SelectZMarkerBinListBox.ValueChangedFcn = createCallbackFcn(app, @SelectZMarkerBinListBoxValueChanged, true);
            app.SelectZMarkerBinListBox.Value = {};
            app.SaveRawDataButton = uibutton(app.LeftPanel, 'push');
            app.SaveRawDataButton.ButtonPushedFcn = createCallbackFcn(app, @SaveRawDataButtonPushed, true);
            app.SaveRawDataButton.Icon = 'floppy-disk.png';
            app.SaveRawDataButton.Text = 'Save Raw Data';
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.RightPanel);
            app.MANOVATable = uitab(app.TabGroup);
            app.MANOVATable.Title = 'MANOVA Table';
            app.SaveMANOVATableButton = uibutton(app.MANOVATable, 'push');
            app.SaveMANOVATableButton.ButtonPushedFcn = createCallbackFcn(app, @SaveMANOVATableButtonPushed, true);
            app.SaveMANOVATableButton.Icon = 'floppy-disk.png';
            app.SaveMANOVATableButton.Text = 'Save Table';
            app.manova_summary_table = uitable(app.MANOVATable);
            app.manova_summary_table.ColumnName = '';
            app.manova_summary_table.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MANOVA_pop_out_data(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end