classdef settings_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
    end


    properties (Access = public)
        loadup_values % Description
        daddy
        title
        edit_objects
        parameter_names
        parameter_set_name
        nvars
        button_dad
    end

    properties (Access = private)

    end

    methods (Access = public)

        function save_settings(app)
            new_settings = app.loadup_values;
        
            % Save values
            for j = 1:app.nvars
                % Detect if the control is a button
                if isa(app.edit_objects{j}, 'matlab.ui.control.Button')
                    % Skip buttons — they don't have a Value property
                    continue
                end
                new_settings.(app.parameter_names(j)) = app.edit_objects{j}.Value;
            end

                settings_app_update(app.daddy, app.parameter_set_name, new_settings, app.title);
            end


        function revert_changes(app)
            new_settings = app.loadup_values;

            %save values
            for j = 1:app.nvars
                app.edit_objects{j}.Value = new_settings.(app.parameter_names(j));
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, daddy, position, title, parameter_set_name, parameters, input_meta, input_settings, button_dad)
            %% Function arguments
            % required inputs
            arguments (Input)
                app
                daddy
                position
                title {mustBeA(title,{'string','char'})}
                parameter_set_name
                parameters
                input_meta
                input_settings
                button_dad = [];
            end

            %% Function begins
            %save relevant variables
            app.title = title;
            app.UIFigure.Name = app.title;
            app.daddy = daddy;
            app.parameter_set_name = parameter_set_name;
            app.button_dad = button_dad;
            %set app position
            app.UIFigure.Position = position;
            %update fcn
            updatefcn = @(~,~) save_settings(app);
            %% Initialise making objects
            % load up values
            app.loadup_values = parameters;
            app.parameter_names = fieldnames(parameters);
            app.parameter_names = string(app.parameter_names);
            app.nvars = numel(app.parameter_names);

            %initialise starting postion
            leftposgapbase = 15;
            leftposgap = repelem(leftposgapbase,app.nvars);
            sub_setting_indent = 10;
            rightposgap = 20;
            thighgap = 45;
            bottompos = 10;

            %initialise edit objects and labels
            object_text = cell(app.nvars,1);
            app.edit_objects = cell(app.nvars,1);

            %% Complete button struct settings and calculate thigh gap
            max_label_width = 0;
            max_edit_object_width = 0;
            for i = app.nvars:-1:1
                temp_parameter_name = app.parameter_names{i};
                button_meta = input_meta(temp_parameter_name);
                button_meta = button_meta{1};

                button_meta = settings_filler(button_meta);

                % Check if the button should be indented
                if isequal(extractBefore(button_meta.label,3),'--')
                    leftposgap(i) = leftposgap(i)+sub_setting_indent;
                end

                max_label_width = max(max_label_width,button_meta.text_width);
                max_edit_object_width = max(max_label_width,button_meta.edit_object_width);

                input_meta(temp_parameter_name) = {button_meta};
            end

            max_label_width = min(max_label_width,app.daddy.UIFigure.Position(3));
            max_edit_object_width = min(max_edit_object_width,app.daddy.UIFigure.Position(3));

            for i = app.nvars:-1:1
                temp_parameter_name = app.parameter_names{i};
                button_meta = input_meta(temp_parameter_name);
                button_meta = button_meta{1};

                button_meta.text_width = max_label_width;
                button_meta.edit_object_width = max_edit_object_width;

                button_meta.depth = max(button_meta.depth,ceil(strlength(button_meta.label)/(button_meta.text_width*0.17))*21);

                input_meta(temp_parameter_name) = {button_meta};
            end

            rightpos = min(thighgap+max_edit_object_width+max_label_width+leftposgapbase+rightposgap,app.daddy.UIFigure.Position(3));

            %% Add save and cancel button
            save_button_width = 100;
            save_button_depth = 40;

            cancel_button_width = 100;
            cancel_button_depth = 40;

            %'save' button
            save_button = uibutton(app.UIFigure, ...
                'Position',[(rightpos/4)-(save_button_width/2), (bottompos), save_button_width, save_button_depth],...
                "Text","Save and apply settings", ...
                'ButtonPushedFcn',@(~,~) save_settings(app), ...
                'WordWrap','on');

            %'Cancel' button
            revert_change_button = uibutton(app.UIFigure, ...
                "Text","Revert changes",...
                'Position',[(rightpos/4)*3-(cancel_button_width/2), (bottompos), cancel_button_width, cancel_button_depth],...    "Text","Cancel", ...
                'ButtonPushedFcn',@(~,~) revert_changes(app));

            bottompos = bottompos+max(cancel_button_depth,save_button_depth)+20;

            %% Create edit objects and labels
            for i = app.nvars:-1:1
                temp_parameter_name = app.parameter_names{i};
                button_meta = input_meta(temp_parameter_name);
                button_meta = button_meta{1};
                button_settings = input_settings(temp_parameter_name);
                button_settings = button_settings{1};

                %% Start creating edit objects
                %create text label
                object_text{i} = uilabel(app.UIFigure,"Text",button_meta.label, ...
                    "WordWrap","on", ...
                    'Position',[leftposgap(i),bottompos,button_meta.text_width,button_meta.depth]);

                %create edit field
                switch button_meta.type
                    case "numeric"
                        app.edit_objects{i} = uieditfield(app.UIFigure,'numeric', ...
                            'Value',parameters.(temp_parameter_name), ...
                            "Position",[rightpos-rightposgap-button_meta.edit_object_width,bottompos,button_meta.edit_object_width,button_meta.depth], ...
                            'ValueChangedFcn',updatefcn, ...
                            button_settings);
                    case "spinner"
                        app.edit_objects{i} = uispinner(app.UIFigure, ...
                            'Value',parameters.(temp_parameter_name), ...
                            "Position",[rightpos-rightposgap-button_meta.edit_object_width,bottompos,button_meta.edit_object_width,button_meta.depth], ...
                            'ValueChangedFcn',updatefcn, ...
                            button_settings);
                    case "checkbox"
                        app.edit_objects{i} = uicheckbox(app.UIFigure, ...
                            'Value',parameters.(temp_parameter_name), ...
                            "Position",[rightpos-rightposgap-button_meta.edit_object_width,bottompos,button_meta.edit_object_width,button_meta.depth], ...
                            'ValueChangedFcn',updatefcn, ...
                            button_settings);
                    case "heading"
                        object_text{i}.FontWeight = 'bold';
                        app.edit_objects{i}.Value = [];
                    case "button"
                            if isfield(button_settings, "ButtonPushedFcn")
                                app.edit_objects{i} = uibutton(app.UIFigure, ...
                                    "Text", button_meta.label, ...
                                    "Position", [rightpos-rightposgap-button_meta.edit_object_width, bottompos, button_meta.edit_object_width, button_meta.depth], ...
                                    button_settings);
                            else
                                warning("Button type '%s' missing 'ButtonPushedFcn'.", temp_parameter_name);
                            end

                    case "dropdown"
                        if numel(button_settings)>1
                            items = string(extractfield(button_settings,'Items'));
                            if isfield(button_settings,"ItemsData")
                                itemsdata = button_settings(1).ItemsData;
                            else
                                itemsdata = items;
                            end
                            button_settings = struct('Items',items,"ItemsData",itemsdata);
                        end
                        
                        app.edit_objects{i} = uidropdown(app.UIFigure, ...
                            "Value",parameters.(temp_parameter_name), ...
                            "Position",[rightpos-rightposgap-button_meta.edit_object_width,bottompos,button_meta.edit_object_width,button_meta.depth], ...
                            'ValueChangedFcn',updatefcn, ...
                            button_settings);
                end

                %% Update offset bottom position
                bottompos = bottompos+button_meta.vertical_seperation+button_meta.depth;
            end
            %% Update app position
            app.UIFigure.Position = [app.UIFigure.Position(1:2),rightpos,min(bottompos,app.daddy.UIFigure.Position(4))];
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            try
                if isfield(app.button_dad,'Value')
                    app.button_dad.Value = 0;
                    app.button_dad.Enable = "on";
                end
                close_settings(app.daddy,app.title,app.button_dad);
            end

            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.Icon = 'More settings.png';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = settings_app(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end