classdef settings_app_input_struct_generator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        SubsettingCheckBox              matlab.ui.control.CheckBox
        UITable                         matlab.ui.control.Table
        ParameterlabelEditField         matlab.ui.control.EditField
        ParameterlabelEditFieldLabel    matlab.ui.control.Label
        ParameterpropertyEditField      matlab.ui.control.EditField
        ParameterpropertyEditFieldLabel  matlab.ui.control.Label
        SettingParametersTextArea       matlab.ui.control.TextArea
        SettingParametersTextAreaLabel  matlab.ui.control.Label
        RemoveButton                    matlab.ui.control.Button
        AddButton                       matlab.ui.control.Button
        SettingboxrowchoiceDropDown     matlab.ui.control.DropDown
        SettingboxrowchoiceLabel        matlab.ui.control.Label
        CenterPanel                     matlab.ui.container.Panel
        InputMetaTextArea               matlab.ui.control.TextArea
        InputMetaTextAreaLabel          matlab.ui.control.Label
        RightPanel                      matlab.ui.container.Panel
        InputSettingsTextArea           matlab.ui.control.TextArea
        InputSettingsTextAreaLabel      matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    methods (Access = public)
        
        function update_edit_boxes(app)
            setting_items = app.UITable.Data(:,1);

            %% initialise intput meta
            input_meta_string = ["input_meta = dictionary(parameters_fields, ...";"{..."];
            %% initialise input settings
            input_settings_string = ["input_settings = dictionary(parameters_fields, ...";"{..."];
            %% initialise setting parameters
            setting_parameter_string = "parameters = struct( ...";

            for i = 1:numel(setting_items)
                propertyname = app.UITable.Data(i,2);
                lbl = app.UITable.Data(i,3);

                switch string(setting_items(i))
                    case "heading"
                        input_meta_string = [input_meta_string;strcat("... ",lbl);strcat("struct(""type"",""heading"",""label"",""",lbl,""")")];
                        input_settings_string = [input_settings_string;strcat("...| ",propertyname," | ",lbl);"struct(), ...";];
                        setting_parameter_string = [setting_parameter_string;strcat("... ",lbl);strcat("""",propertyname,""",[]")];
                    case "numeric"
                        input_meta_string = [input_meta_string;strcat("struct(""type"",""numeric"",""label"",""",lbl,""")")];
                        input_settings_string = [input_settings_string;strcat("... ",propertyname," | ",lbl);"struct(""Limits"",[0,1],""LowerLimitInclusive"",""off"",""UpperLimitInclusive"",""on"")"];
                        setting_parameter_string = [setting_parameter_string;strcat("""",propertyname,""",100")];
                    case "dropdown"
                        input_meta_string = [input_meta_string;strcat("struct(""type"",""dropdown"",""label"",""",lbl,""")")];
                        input_settings_string = [input_settings_string;strcat("... ",propertyname," | ",lbl);"struct(""Items"",mkrs)"];
                        setting_parameter_string = [setting_parameter_string;strcat("""",propertyname,""",'x'")];
                    case "spinner"
                        input_meta_string = [input_meta_string;strcat("struct(""type"",""spinner"",""label"",""",lbl,""")")];
                        input_settings_string = [input_settings_string;strcat("... ",propertyname," | ",lbl);"struct(""Limits"",[0,Inf],""LowerLimitInclusive"",""off"",""RoundFractionalValues"",""on"")"];
                        setting_parameter_string = [setting_parameter_string;strcat("""",propertyname,""",5")];
                    case "checkbox"
                        input_meta_string = [input_meta_string;strcat("struct(""type"",""checkbox"",""label"",""",lbl,""")")];
                        input_settings_string = [input_settings_string;strcat("... ",propertyname," | ",lbl);"struct(""Text"","""")"];
                        setting_parameter_string = [setting_parameter_string;strcat("""",propertyname,""",1")];
                end

                if i==numel(setting_items)
                    %% close strings
                    input_meta_string(end) = strcat(input_meta_string(end),"});");
                    input_settings_string(end) = strcat(input_settings_string(end),"});");
                    setting_parameter_string(end) = strcat(setting_parameter_string(end),");");
                else
                    %% open strings
                    input_meta_string(end) = strcat(input_meta_string(end),", ...");
                    input_settings_string(end) = strcat(input_settings_string(end),", ...");
                    setting_parameter_string(end) = strcat(setting_parameter_string(end),", ...");
                end
            end
%             %% close strings
%             input_meta_string = [input_meta_string;"});"];
%             input_settings_string = [input_settings_string;"});"];
%             setting_parameter_string = [setting_parameter_string;");"];

            %% Assign text
            app.InputMetaTextArea.Value = input_meta_string;
            app.InputSettingsTextArea.Value = input_settings_string;
            app.SettingParametersTextArea.Value = setting_parameter_string;

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
app.UITable.Data = [string.empty,string.empty,string.empty];
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            %             app.SettingsboxrowsListBox.Items = [string(app.SettingsboxrowsListBox.Items), string(app.SettingboxrowchoiceDropDown.Value)];
            %             app.PropertyListBox.Items = [string(app.PropertyListBox.Items), string(app.ParameterpropertyEditField.Value)];
            %             app.LabelListBox.Items = [string(app.LabelListBox.Items), string(app.ParameterlabelEditField.Value)];
            %
            %             app.SettingsboxrowsListBox.ItemsData = 1:numel(app.SettingsboxrowsListBox.Items);
            %             app.PropertyListBox.ItemsData = 1:numel(app.PropertyListBox.Items);
            %             app.LabelListBox.ItemsData = 1:numel(app.LabelListBox.Items);


            app.UITable.Data(end+1,:) = [string(app.SettingboxrowchoiceDropDown.Value),string(app.ParameterpropertyEditField.Value),string(app.ParameterlabelEditField.Value)];

            update_edit_boxes(app)
        end

        % Selection changed function: UITable
        function UITableSelectionChanged(app, event)
            update_edit_boxes(app)
        end

        % Button pushed function: RemoveButton
        function RemoveButtonPushed(app, event)
            rown = app.UITable.Selection(:,1);
            app.UITable.Data(rown,:) = [];

            update_edit_boxes(app)
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {644, 644, 644};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {644, 644};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {540, '1x', 412};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.SettingboxrowchoiceLabel = uilabel(app.LeftPanel);
            app.SettingboxrowchoiceLabel.Text = 'Setting box row choice';
            app.SettingboxrowchoiceDropDown = uidropdown(app.LeftPanel);
            app.SettingboxrowchoiceDropDown.Items = {'heading', 'numeric', 'dropdown', 'spinner', 'checkbox'};
            app.SettingboxrowchoiceDropDown.Value = 'heading';
            app.AddButton = uibutton(app.LeftPanel, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.RemoveButton = uibutton(app.LeftPanel, 'push');
            app.RemoveButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveButtonPushed, true);
            app.RemoveButton.Text = 'Remove';
            app.SettingParametersTextAreaLabel = uilabel(app.LeftPanel);
            app.SettingParametersTextAreaLabel.Text = 'Setting Parameters';
            app.SettingParametersTextArea = uitextarea(app.LeftPanel);
            app.SettingParametersTextArea.Editable = 'off';
            app.ParameterpropertyEditFieldLabel = uilabel(app.LeftPanel);
            app.ParameterpropertyEditFieldLabel.Text = 'Parameter property';
            app.ParameterpropertyEditField = uieditfield(app.LeftPanel, 'text');
            app.ParameterlabelEditFieldLabel = uilabel(app.LeftPanel);
            app.ParameterlabelEditFieldLabel.Text = 'Parameter label';
            app.ParameterlabelEditField = uieditfield(app.LeftPanel, 'text');
            app.UITable = uitable(app.LeftPanel);
            app.UITable.ColumnName = {'Setting type'; 'Property'; 'Label'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [false true true];
            app.UITable.SelectionChangedFcn = createCallbackFcn(app, @UITableSelectionChanged, true);
            app.SubsettingCheckBox = uicheckbox(app.LeftPanel);
            app.SubsettingCheckBox.Text = 'Sub setting';
            app.CenterPanel = uipanel(app.GridLayout);
            app.InputMetaTextAreaLabel = uilabel(app.CenterPanel);
            app.InputMetaTextAreaLabel.Text = 'Input Meta';
            app.InputMetaTextArea = uitextarea(app.CenterPanel);
            app.InputMetaTextArea.Editable = 'off';
            app.RightPanel = uipanel(app.GridLayout);
            app.InputSettingsTextAreaLabel = uilabel(app.RightPanel);
            app.InputSettingsTextAreaLabel.Text = 'Input Settings';
            app.InputSettingsTextArea = uitextarea(app.RightPanel);
            app.InputSettingsTextArea.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = settings_app_input_struct_generator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end