classdef sub_app_marker_segment_analysis_converted < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        SelectvariantListBox         matlab.ui.control.ListBox
        SelectvariantListBoxLabel    matlab.ui.control.Label
        SelectSegmentListBox         matlab.ui.control.ListBox
        SelectSegmentListBoxLabel    matlab.ui.control.Label
        RightPanel                   matlab.ui.container.Panel
        TabGroup                     matlab.ui.container.TabGroup
        SegmentsampledataTab         matlab.ui.container.Tab
        MoreSettingsButton           matlab.ui.control.StateButton
        PopoutButton                 matlab.ui.control.Button
        PlottypeDropDown             matlab.ui.control.DropDown
        PlottypeLabel                matlab.ui.control.Label
        UIAxes                       matlab.ui.control.UIAxes
        FitdataTab                   matlab.ui.container.Tab
        ConfidenceintervalEditField  matlab.ui.control.NumericEditField
        ConfidenceintervalEditFieldLabel  matlab.ui.control.Label
        Data_label                   matlab.ui.control.Label
        PopoutButton_2               matlab.ui.control.Button
        DataDropDown                 matlab.ui.control.DropDown
        DataDropDownLabel            matlab.ui.control.Label
        UITable                      matlab.ui.control.Table
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    properties (Access = private)
        segmentdata % Description
        %% More options params
        plot_params = struct( ...
            "Sample",[], ...
            "do_scatter",1, ...
            "scatter_type","scatter", ...
            "scatter_marker",'x', ...
            "do_segment_line_fit",1, ...
            "do_segment_line_standard_error",1, ...
            "do_legend",1, ...
            "Histogram",[], ...
            "autobins",1, ...
            "manualbins",30, ...
            "Residuals",[], ...
            "residual_type","scatter", ...
            "residual_marker",'.', ...
            "do_zeroline",1, ...
            "do_residual_legend",1); % Description

        plot_params_input_meta = dictionary();
        plot_params_input_settings = dictionary();

        exist_table = 0;
        exist_plot = 0;
    end

    methods (Access = private)

        function update_plot(app)
            if numel(app.SelectvariantListBox.Value)>0&&numel(app.SelectSegmentListBox.Value)>0
                sub_app_segment_analysis_plot(app.UIAxes,app.PlottypeDropDown.Value, ...
                    app.segmentdata,app.SelectvariantListBox.Value,app.SelectSegmentListBox.Value,app.plot_params)
                app.exist_plot = 1;
            end
        end

        function update_table(app)
            if isequal(app.DataDropDown.Value,'Fit formula')
                app.ConfidenceintervalEditFieldLabel.Visible = "on";
                app.ConfidenceintervalEditField.Visible = "on";
            else
                app.ConfidenceintervalEditFieldLabel.Visible = "off";
                app.ConfidenceintervalEditField.Visible = "off";
            end

            if numel(app.SelectvariantListBox.Value)>0&&numel(app.SelectSegmentListBox.Value)>0
                sub_app_segment_analysis_table(app.UITable,app.Data_label,app.DataDropDown.Value,app.segmentdata, ...
                    app.SelectvariantListBox.Value,app.SelectSegmentListBox.Value,app.ConfidenceintervalEditField.Value)
                app.exist_table = 1;
            end
        end

        function update_both(app)
            update_table(app)
            update_plot(app)
        end


    end

    methods (Access = public)

        function app = settings_app_update(app,parameter_set_name,new_parameter_set,title)
            app.(parameter_set_name) = new_parameter_set;

            switch title
                case "Segment details plot settings"
                    update_both(app);
            end
        end

        function app = close_settings(app,title,button_dad)

            try
                button_dad.Value = 0;
                button_dad.Enable = "on";
            end
            
            switch title
                case "Segment details plot settings"
                    app.MoreSettingsButton.Value = 0;
                    app.MoreSettingsButton.Enable = "on";
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, segmentdata)
            app.segmentdata  = segmentdata;
            %Fill boxes
            app.SelectvariantListBox.Items = app.segmentdata.selected_cell_lines;
            app.SelectvariantListBox.ItemsData = 1:numel(app.segmentdata.selected_cell_lines);

            app.ConfidenceintervalEditFieldLabel.Visible = "off";
            app.ConfidenceintervalEditField.Visible = "off";
            %% Plot options
            mkrs = string({'*','o','^','.','+','x','s','p','h'});

            param_fieldnames = string(fieldnames(app.plot_params))';

            app.plot_params_input_meta = dictionary(param_fieldnames, ...
                {struct("type","heading","label","Sample"), ...
                struct("type","checkbox","label","Show scatter"), ...
                struct("type","dropdown","label","Scatter type"), ...
                struct("type","dropdown","label","Scatter marker"), ...
                struct("type","checkbox","label","Show fit"), ...
                struct("type","checkbox","label","Show prediction interval"), ...
                struct("type","checkbox","label","Show legend"), ...
                struct("type","heading","label","Histogram"), ...
                struct("type","checkbox","label","Manually specify histogram bins"), ...
                struct("type","numeric","label","Number of bins"), ...
                struct("type","heading","label","Residuals"), ...
                struct("type","dropdown","label","Scatter type"), ...
                struct("type","dropdown","label","Scatter marker"), ...
                struct("type","checkbox","label","Show zero line"), ...
                struct("type","checkbox","label","Show legend")});

            app.plot_params_input_settings = dictionary(param_fieldnames, ...
                {struct(), ...
                struct("Text",""), ...
                struct("Items",string({'scatter','bin scatter'})), ...
                struct("Items",mkrs), ...
                struct("Text",""), ...
                struct("Text",""), ...
                struct("Text",""), ...
                struct(), ...
                struct("Text",""), ...
                struct("Limits",[0,Inf],"LowerLimitInclusive","off"), ...
                struct(), ...
                struct("Items",string({'scatter','bin scatter'})), ...
                struct("Items",mkrs), ...
                struct("Text",""), ...
                struct("Text","")});
        end

        % Value changed function: SelectvariantListBox
        function SelectvariantListBoxValueChanged(app, event)
            app.SelectSegmentListBox.Items = string(1:(numel(app.segmentdata.tindex{app.SelectvariantListBox.Value})+1));
            app.SelectSegmentListBox.ItemsData = 1:(numel(app.segmentdata.tindex{app.SelectvariantListBox.Value})+1);
            update_both(app)
        end

        % Value changed function: SelectSegmentListBox
        function SelectSegmentListBoxValueChanged(app, event)
            update_both(app)
        end

        % Value changed function: PlottypeDropDown
        function PlottypeDropDownValueChanged(app, event)
            update_plot(app)
        end

        % Value changed function: MoreSettingsButton
        function MoreSettingsButtonValueChanged(app, event)
%             if app.MoreSettingsButton.Value
%                 app.MoreSettingsButton.Enable = "off";
%                 settings_app(app, ...
%                     [app.UIFigure.Position],"Segment details plot settings", ...
%                     "plot_params",app.plot_params, ...
%                     app.plot_params_input_meta,app.plot_params_input_settings)
%             end

    more_settings_button_push(app.MoreSettingsButton,app,[], ...
                "Segment details plot settings","plot_params",app.plot_params,app.plot_params_input_meta,app.plot_params_input_settings);
        end

        % Value changed function: DataDropDown
        function DataDropDownValueChanged(app, event)
            update_table(app)
        end

        % Button pushed function: PopoutButton
        function PopoutButtonPushed(app, event)
            if app.exist_plot
                name = strcat(app.segmentdata.selected_cell_lines(app.SelectvariantListBox.Value),": segment ", string(app.SelectSegmentListBox.Value));
                pop_out_button([],name,app.UIAxes,"figfather",app.UIFigure);
            end
        end

        % Button pushed function: PopoutButton_2
        function PopoutButton_2Pushed(app, event)
            if app.exist_table
                name = strcat(app.segmentdata.selected_cell_lines(app.SelectvariantListBox.Value),": segment ", string(app.SelectSegmentListBox.Value));
                pop_out_button([],name,app.UITable,"figfather",app.UIFigure);
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {608, 608};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {293, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.SelectSegmentListBoxLabel = uilabel(app.LeftPanel);
            app.SelectSegmentListBoxLabel.Text = 'Select Segment';
            app.SelectSegmentListBox = uilistbox(app.LeftPanel);
            app.SelectSegmentListBox.Items = {};
            app.SelectSegmentListBox.ValueChangedFcn = createCallbackFcn(app, @SelectSegmentListBoxValueChanged, true);
            app.SelectSegmentListBox.Value = {};
            app.SelectvariantListBoxLabel = uilabel(app.LeftPanel);
            app.SelectvariantListBoxLabel.Text = 'Select variant';
            app.SelectvariantListBox = uilistbox(app.LeftPanel);
            app.SelectvariantListBox.Items = {};
            app.SelectvariantListBox.ValueChangedFcn = createCallbackFcn(app, @SelectvariantListBoxValueChanged, true);
            app.SelectvariantListBox.Value = {};
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.RightPanel);
            app.SegmentsampledataTab = uitab(app.TabGroup);
            app.SegmentsampledataTab.Title = 'Segment sample data';
            app.UIAxes = uiaxes(app.SegmentsampledataTab);
            zlabel(app.UIAxes, 'Z')
            app.PlottypeLabel = uilabel(app.SegmentsampledataTab);
            app.PlottypeLabel.Text = 'Plot type:';
            app.PlottypeDropDown = uidropdown(app.SegmentsampledataTab);
            app.PlottypeDropDown.Items = {'Sample', 'Histogram', 'Residuals'};
            app.PlottypeDropDown.ValueChangedFcn = createCallbackFcn(app, @PlottypeDropDownValueChanged, true);
            app.PlottypeDropDown.Value = 'Sample';
            app.PopoutButton = uibutton(app.SegmentsampledataTab, 'push');
            app.PopoutButton.ButtonPushedFcn = createCallbackFcn(app, @PopoutButtonPushed, true);
            app.PopoutButton.Text = 'Pop out';
            app.MoreSettingsButton = uibutton(app.SegmentsampledataTab, 'state');
            app.MoreSettingsButton.ValueChangedFcn = createCallbackFcn(app, @MoreSettingsButtonValueChanged, true);
            app.MoreSettingsButton.Text = 'More Settings';
            app.FitdataTab = uitab(app.TabGroup);
            app.FitdataTab.Title = 'Fit data';
            app.UITable = uitable(app.FitdataTab);
            app.UITable.ColumnName = '';
            app.UITable.RowName = {};
            app.DataDropDownLabel = uilabel(app.FitdataTab);
            app.DataDropDownLabel.Text = 'Data:';
            app.DataDropDown = uidropdown(app.FitdataTab);
            app.DataDropDown.Items = {'Sample features', 'Fit formula', 'Goodness of fit'};
            app.DataDropDown.ValueChangedFcn = createCallbackFcn(app, @DataDropDownValueChanged, true);
            app.DataDropDown.Value = 'Sample features';
            app.PopoutButton_2 = uibutton(app.FitdataTab, 'push');
            app.PopoutButton_2.ButtonPushedFcn = createCallbackFcn(app, @PopoutButton_2Pushed, true);
            app.PopoutButton_2.Text = 'Pop out';
            app.Data_label = uilabel(app.FitdataTab);
            app.Data_label.Text = '';
            app.ConfidenceintervalEditFieldLabel = uilabel(app.FitdataTab);
            app.ConfidenceintervalEditFieldLabel.Text = 'Confidence interval';
            app.ConfidenceintervalEditField = uieditfield(app.FitdataTab, 'numeric');
            app.ConfidenceintervalEditField.LowerLimitInclusive = 'off';
            app.ConfidenceintervalEditField.UpperLimitInclusive = 'off';
            app.ConfidenceintervalEditField.Limits = [0 1];
            app.ConfidenceintervalEditField.Value = 0.95;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = sub_app_marker_segment_analysis_converted(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end