classdef selection_inspection < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        DrawnewselectionMenu     matlab.ui.container.Menu
        DeleteallselectionsMenu  matlab.ui.container.Menu
        ExportselectionasnewdatasetMenu  matlab.ui.container.Menu
        Panel                    matlab.ui.container.Panel
        UITable                  matlab.ui.control.Table
    end


    properties (Access = public)
        %% Input Vars
        ax
        Y
        variant_ID
        daddy
        new_ax

        %% App vars
        ydims
        table_dat
        selections;
        master_table

        %% data vars
        selected_cell_lines
        num_cell_lines
        num_cells
        table_data
        rowlabel

        index_in

        %% Listeners

        mov_listener;
        del_listener;


    end

    methods (Access = private)

        function update_roi_table(app)
            app.index_in = zeros(app.ydims(1),1);
            for i = 1:numel(app.selections)
                if isvalid(app.selections(i))
                    % Find cells inside
                    switch app.ydims(2)
                        case 2
                            temp_index_in = inROI(app.selections(i),app.Y(:,1),app.Y(:,2));
                        case 3
                            temp_index_in = inROI(app.selections(i),app.Y(:,1),app.Y(:,2),app.Y(:,3));
                    end
                    app.index_in = app.index_in|temp_index_in;
                end
            end

            total_selected_cells = sum(app.index_in);
            selected_cell_categories = app.variant_ID(app.index_in);
            for i = 1:app.num_cell_lines
                current_line = app.selected_cell_lines(i);
                app.table_data(1,i) = sum(selected_cell_categories==current_line);
                app.table_data(2,i) = sum(selected_cell_categories==current_line)/app.num_cells(i)*100;
                app.table_data(3,i) = sum(selected_cell_categories==current_line)/sum(app.index_in)*100;
            end
            app.UITable.Data = array2table(app.table_data,"VariableNames",app.selected_cell_lines',"RowNames",app.rowlabel');
            app.UIFigure.Name = strcat("Total selected cells: ",string(total_selected_cells));
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, ax, Y, variant_ID, master_table)
            arguments (Input)
                app
                ax
                Y
                variant_ID
                master_table
            end

            %Save input vars
            app.ax = ax;
            app.Y = Y;
            app.variant_ID = variant_ID;
            app.master_table = master_table;

            %% Function starts
            app.new_ax = copyobj(ax,app.Panel);
            app.new_ax.Units = 'Normalized';
            app.new_ax.Position = [0.1,0.1,0.8,0.8];
            legend(app.new_ax,ax.Legend.String{:})

            %% Initialise table
            % Initialise table data
            app.selected_cell_lines = unique(variant_ID);
            app.num_cell_lines = numel(app.selected_cell_lines);
            app.num_cells = zeros(app.num_cell_lines,1);
            for i = 1:app.num_cell_lines
                app.num_cells(i) = sum(variant_ID==app.selected_cell_lines(i));
            end

            app.table_data = zeros(3,app.num_cell_lines);

            % table names
            app.rowlabel = ["Number of cells selected","% of cells of variant","% of variant within selection"];
            plot_table = array2table(app.table_data,"VariableNames",app.selected_cell_lines',"RowNames",app.rowlabel');

            app.UITable.ColumnName = app.selected_cell_lines';
            app.UITable.RowName = app.rowlabel';
            app.UITable.Data = plot_table;

            %% Draw polygon
            app.ydims = size(Y);

            switch app.ydims(2)
                case 2
                    app.selections = images.roi.Polygon.empty;
                case 3
                    app.selections = images.roi.Cuboid.empty;
            end
        end

        % Menu selected function: DrawnewselectionMenu
        function DrawnewselectionMenuSelected(app, event)
            axes(app.new_ax)
            switch app.ydims(2)
                case 2
                    app.selections(end+1) = drawpolygon(app.new_ax);
                case 3
                    app.selections(end+1) = drawcuboid(app.new_ax);
            end

            if isempty(app.selections(end).Position)
                app.selections(end) = [];
            end
            %% Listeners
            app.selections = app.selections(isvalid(app.selections));

            delete(app.mov_listener);
            delete(app.del_listener);
            app.mov_listener = listener(app.selections,'MovingROI',@(~,~)update_roi_table(app));
            app.del_listener = listener(app.selections,'DeletingROI',@(~,~)update_roi_table(app));

            update_roi_table(app);
        end

        % Menu selected function: DeleteallselectionsMenu
        function DeleteallselectionsMenuSelected(app, event)
            delete(app.mov_listener);
            delete(app.del_listener);
            delete(app.selections);

            switch app.ydims(2)
                case 2
                    app.selections = images.roi.Polygon.empty;
                case 3
                    app.selections = images.roi.Cuboid.empty;
            end
        end

        % Menu selected function: ExportselectionasnewdatasetMenu
        function ExportselectionasnewdatasetMenuSelected(app, event)
            %% Specify save location
            folder_path = uigetdir(cd,'Select save location for dataset folder');
            if folder_path == 0
                return
            end

            %% Specify save name
            save_location_dir = dir(folder_path);
            save_location_dir(1:2) = [];

            existing_folder_names = string.empty;

            for i = 1:numel(save_location_dir)
                if save_location_dir(i).isdir
                    existing_folder_names(end+1) = string(save_location_dir(i).name);
                end
            end

            [new_dataset_name] = same_data_set_name_pop_up("dimension reduction sample 1",existing_folder_names, ...
                "position",app.UIFigure.Position);

            figure(app.UIFigure);

            [status_mkdir,~,~] = mkdir(fullfile(folder_path,new_dataset_name));

            if ~status_mkdir
                return
            end

            %% Make a new master table for cells within selection
            selection_table = app.master_table;

            selection_table = selection_table(app.index_in,:);

            %% Save each cell line within master table
            cell_line_names = unique(selection_table.variant_ID);

            n_cell_lines = numel(cell_line_names);

            for i = 1:n_cell_lines
                temp_idx = selection_table.variant_ID==cell_line_names(i);

                temp_table = selection_table(temp_idx,:);

                %% write table
                writetable(temp_table,fullfile(folder_path,new_dataset_name,strcat(cell_line_names(i),".csv")));
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Dimensionality Reduction Inspector';
            app.UIFigure.Icon = 'tSNE shortcut.png';
            app.DrawnewselectionMenu = uimenu(app.UIFigure);
            app.DrawnewselectionMenu.MenuSelectedFcn = createCallbackFcn(app, @DrawnewselectionMenuSelected, true);
            app.DrawnewselectionMenu.Text = 'Draw new selection';
            app.DeleteallselectionsMenu = uimenu(app.UIFigure);
            app.DeleteallselectionsMenu.MenuSelectedFcn = createCallbackFcn(app, @DeleteallselectionsMenuSelected, true);
            app.DeleteallselectionsMenu.Text = 'Delete all selections';
            app.ExportselectionasnewdatasetMenu = uimenu(app.UIFigure);
            app.ExportselectionasnewdatasetMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportselectionasnewdatasetMenuSelected, true);
            app.ExportselectionasnewdatasetMenu.Text = 'Export selection as new data set';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.Panel = uipanel(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = selection_inspection(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end