classdef SCADR < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Toolbar                         matlab.ui.container.Toolbar
        Toolbar_define_channels         matlab.ui.container.toolbar.PushTool
        Toolbar_filter_data             matlab.ui.container.toolbar.PushTool
        Toolbar_analysis_tab            matlab.ui.container.toolbar.PushTool
        Toolbar_dimensionality_reduction_tab  matlab.ui.container.toolbar.PushTool
        Slider                          matlab.ui.control.Slider
        SliderLabel                     matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        LoadinDataTab                   matlab.ui.container.Tab
        DeleteFile_Original             matlab.ui.control.Button
        AddAnotherFile_Original         matlab.ui.control.Button
        Table_OriginalFiles             matlab.ui.control.Table
        OriginalFilesListBox            matlab.ui.control.ListBox
        FilesFoundListBoxLabel          matlab.ui.control.Label
        UpdateTableViewer_Original      matlab.ui.control.Button
        DatasetDropDown_load            matlab.ui.control.DropDown
        DatasetDropDown_load_label      matlab.ui.control.Label
        FileNameDropDown_Load           matlab.ui.control.DropDown
        FileNameDropDown_LoadLabel      matlab.ui.control.Label
        Load_In_Folder_Original         matlab.ui.control.Button
        AppSettingsButton               matlab.ui.control.StateButton
        VisualisedataLabel              matlab.ui.control.Label
        LoadindataLabel                 matlab.ui.control.Label
        DefineChannelsTab               matlab.ui.container.Tab
        LogDataSwitchLabel              matlab.ui.control.Label
        ZeroAdjustmandatoryifdataisbeingloggedSwitchLabel  matlab.ui.control.Label
        NormaliseallButton              matlab.ui.control.Button
        LogDataSwitch                   matlab.ui.control.ToggleSwitch
        Zero_adjust_switch              matlab.ui.control.ToggleSwitch
        NormalisingchannelifrelevantDropDown  matlab.ui.control.DropDown
        NormalisingchannelifrelevantLabel  matlab.ui.control.Label
        LogDataListListBox              matlab.ui.control.ListBox
        LogDataListListBoxLabel         matlab.ui.control.Label
        RemoveMarkerButton              matlab.ui.control.Button
        AddmarkerchannelfromoriginalcolumnnamesButton  matlab.ui.control.Button
        AddallavailablemarkersButton    matlab.ui.control.Button
        MarkerchannelsListBox           matlab.ui.control.ListBox
        MarkerchannelsListBoxLabel      matlab.ui.control.Label
        NewcolumnnamesListBox           matlab.ui.control.ListBox
        NewcolumnnamesLabel             matlab.ui.control.Label
        OriginalcolumnnamesListBox      matlab.ui.control.ListBox
        OriginalcolumnnamesLabel        matlab.ui.control.Label
        RemoveselectednamesButton       matlab.ui.control.Button
        AddchanneltorenameButton        matlab.ui.control.Button
        normalise_channel_buttons_panel  matlab.ui.container.Panel
        RenameachannelButton            matlab.ui.control.StateButton
        NormaliseanddataadjustmentoptionsLabel  matlab.ui.control.Label
        UpdatingthislistwillupdatethemarkerchannelslistLabel  matlab.ui.control.Label
        RenameChannelsLabel             matlab.ui.control.Label
        QCChannelFilteringTab           matlab.ui.container.Tab
        UpdatequantilechannelfilterButton  matlab.ui.control.Button
        UpdateabsolutechannelfilterButton  matlab.ui.control.Button
        LowerquantileEditField          matlab.ui.control.NumericEditField
        LowerquantileEditFieldLabel     matlab.ui.control.Label
        LowerboundEditField             matlab.ui.control.NumericEditField
        LowerboundEditFieldLabel        matlab.ui.control.Label
        ResetselectedButton             matlab.ui.control.Button
        UpperboundEditField             matlab.ui.control.NumericEditField
        UpperboundEditFieldLabel        matlab.ui.control.Label
        FilterSliderPanel               matlab.ui.container.Panel
        UpperquantileEditField          matlab.ui.control.NumericEditField
        UpperquantileEditFieldLabel     matlab.ui.control.Label
        MoreSettingsButton_channel_filter  matlab.ui.control.StateButton
        Variants_ListBox_channel_filtering  matlab.ui.control.ListBox
        SelectwhichvariantstoviewListBoxLabel  matlab.ui.control.Label
        PickwhichmarkertoplotDropDown   matlab.ui.control.DropDown
        PickwhichmarkertoplotLabel      matlab.ui.control.Label
        SelectmarkerchannelstofilterusingquantilesListBox  matlab.ui.control.ListBox
        WhichfiltergoesfirstSwitch      matlab.ui.control.ToggleSwitch
        SelectmarkerchannelstofilterusingquantilesListBoxLabel  matlab.ui.control.Label
        WhichfiltergoesfirstSwitchLabel  matlab.ui.control.Label
        FilterbyquantileLabel           matlab.ui.control.Label
        Dataset_pick_channel_filter     matlab.ui.control.DropDown
        DatasetDropDownLabel            matlab.ui.control.Label
        FilterchanneldataLabel          matlab.ui.control.Label
        Filter_by_channel_plot          matlab.ui.control.UIAxes
        QCScatterPlotFilteringTab       matlab.ui.container.Tab
        ClearpolygonButton              matlab.ui.control.Button
        SavepolygonfilterButton         matlab.ui.control.Button
        DrawPolygonButton               matlab.ui.control.StateButton
        Filter_scatter_do_bin_width     matlab.ui.control.Switch
        Binwidth_scatter_filter         matlab.ui.control.NumericEditField
        BinwidthEditField_3Label        matlab.ui.control.Label
        Filter_scatter_plot_type        matlab.ui.control.DropDown
        PlottypeDropDown_2Label         matlab.ui.control.Label
        Yaxismarker_scatter_filter      matlab.ui.control.DropDown
        YaxismarkerDropDownLabel_2      matlab.ui.control.Label
        Xaxismarker_scatter_filter      matlab.ui.control.DropDown
        XaxischannelLabel               matlab.ui.control.Label
        Variants_ListBox_scatter_filtering  matlab.ui.control.ListBox
        SelectwhichvariantstoviewListBox_2Label  matlab.ui.control.Label
        Dataset_pick_scatter_filter     matlab.ui.control.DropDown
        DatasetDropDown_2Label_2        matlab.ui.control.Label
        FilterscatterplotLabel          matlab.ui.control.Label
        axes_scatter_filter_plot        matlab.ui.control.UIAxes
        ProcessalldataTab               matlab.ui.container.Tab
        FilterandprocessdataButton      matlab.ui.control.Button
        FilterorderPanel                matlab.ui.container.Panel
        filter3                         matlab.ui.control.DropDown
        ThirdFilterDropDownLabel        matlab.ui.control.Label
        filter2                         matlab.ui.control.DropDown
        SecondFilterDropDownLabel       matlab.ui.control.Label
        filter1                         matlab.ui.control.DropDown
        FirstFilterDropDownLabel        matlab.ui.control.Label
        ChangeoutputplotsdirectoryButton  matlab.ui.control.Button
        OutputplotsdirectoryEditField   matlab.ui.control.EditField
        OutputplotsdirectoryLabel       matlab.ui.control.Label
        DatafiletypeDropDown            matlab.ui.control.DropDown
        DatafiletypeDropDownLabel       matlab.ui.control.Label
        ChangeoutputdatadirectoryButton  matlab.ui.control.Button
        OutputdatadirectoryEditField    matlab.ui.control.EditField
        OutputdatadirectoryEditFieldLabel  matlab.ui.control.Label
        D13Tab                          matlab.ui.container.Tab
        PopoutButton_nd_plot            matlab.ui.control.Button
        MoreSettingsButton_2ND          matlab.ui.control.StateButton
        MoreSettingsButton_1ND          matlab.ui.control.StateButton
        Zaxismarker_ND_scatter          matlab.ui.control.DropDown
        ZaxismarkerDropDownLabel_3      matlab.ui.control.Label
        Yaxismarker_ND_scatter          matlab.ui.control.DropDown
        YaxismarkerDropDownLabel_6      matlab.ui.control.Label
        Xaxismarker_ND_scatter          matlab.ui.control.DropDown
        XaxismarkerDropDownLabel_4      matlab.ui.control.Label
        ND_variant_select_box           matlab.ui.control.ListBox
        SelectwhichvariantstoplotLabel_6  matlab.ui.control.Label
        Panel_ND_scatter                matlab.ui.container.Panel
        Dataset_pick_ND_scatter         matlab.ui.control.DropDown
        DatasetLabel_4                  matlab.ui.control.Label
        D2D3Don2DPlotLabel              matlab.ui.control.Label
        Dploton2DTab                    matlab.ui.container.Tab
        PopoutButton_3D_fit_on_2D       matlab.ui.control.Button
        MoreSettingsButton_3D_fit_on_2D  matlab.ui.control.StateButton
        UpdateplotButton_3d_on_2d       matlab.ui.control.Button
        Zaxismarker_3d_scatter_on_2d    matlab.ui.control.DropDown
        ZaxismarkerDropDownLabel_2      matlab.ui.control.Label
        Yaxismarker_3d_scatter_on_2d    matlab.ui.control.DropDown
        YaxismarkerDropDownLabel_5      matlab.ui.control.Label
        Xaxismarker_3d_scatter_on_2d    matlab.ui.control.DropDown
        XaxismarkerDropDownLabel_3      matlab.ui.control.Label
        Scatter_3d_fit_on_2d_variant_select_box  matlab.ui.control.ListBox
        SelectwhichvariantstoplotLabel_5  matlab.ui.control.Label
        Panel_3D_scatter_on_2D_plot     matlab.ui.container.Panel
        Dataset_pick_3D_scatter_on_2d   matlab.ui.control.DropDown
        DatasetLabel_Dataset_pick_3D_scatter_on_2d  matlab.ui.control.Label
        Dfitson2DsurfaceLabel           matlab.ui.control.Label
        CorrelationGridTab              matlab.ui.container.Tab
        Popout_vc_grid                  matlab.ui.control.Button
        MoreSettings_variant_channel_grid  matlab.ui.control.StateButton
        correlation_table_vc_grid       matlab.ui.control.Button
        UpdatePlot_vc_grid              matlab.ui.control.Button
        Marker_pick_correlation_plot_list_box  matlab.ui.control.ListBox
        SelectwhichmarkerstoincludeListBoxLabel  matlab.ui.control.Label
        Correlation_plot_variant_select_box  matlab.ui.control.ListBox
        SelectwhichvariantstoplotLabel_3  matlab.ui.control.Label
        panel_variant_marker_correlation  matlab.ui.container.Panel
        Dataset_pick_variant_correlation  matlab.ui.control.DropDown
        DatasetLabel_2                  matlab.ui.control.Label
        VariantChannelCorrelationLabel  matlab.ui.control.Label
        CorrelationTestsTab             matlab.ui.container.Tab
        Hyperlink3                      matlab.ui.control.Hyperlink
        PopoutplotButton_correlation_tests  matlab.ui.control.Button
        MoreSettings_corrtests          matlab.ui.control.StateButton
        PopoutnormalitytesttableButton  matlab.ui.control.Button
        Update_normality_test_table_button  matlab.ui.control.Button
        correlation_test_table_panel    matlab.ui.container.Panel
        correlation_test_table          matlab.ui.control.Table
        correlation_comparison_table_button  matlab.ui.control.Button
        UpdateplotButton                matlab.ui.control.Button
        marker_pick_correlation_tests   matlab.ui.control.ListBox
        marker_pick_correlation_tests_label  matlab.ui.control.Label
        correlation_tests_variant_select_box  matlab.ui.control.ListBox
        correlation_tests_variant_select_Label  matlab.ui.control.Label
        correlation_test_plot_panel     matlab.ui.container.Panel
        Dataset_pick_correlation_tests  matlab.ui.control.DropDown
        Dataset_pick_correlation_tests_label  matlab.ui.control.Label
        CorrelationtestsLabel           matlab.ui.control.Label
        ExpressionSegmentationTab       matlab.ui.container.Tab
        Popoutfigure_expression_segmentation  matlab.ui.control.Button
        MoreSettings_expression_marker  matlab.ui.control.StateButton
        LinearsegmentdetailsButton      matlab.ui.control.Button
        UpdateplotButton_expression_segmentation  matlab.ui.control.Button
        GenerateplotsforallYaxismarkercombinationsButton  matlab.ui.control.StateButton
        Yaxismarker_expression_marker_plot  matlab.ui.control.DropDown
        YaxismarkerDropDownLabel_4      matlab.ui.control.Label
        Xaxismarker_expression_marker_plot  matlab.ui.control.DropDown
        XaxismarkerLabel                matlab.ui.control.Label
        Expression_marker_plot_variant_select_box  matlab.ui.control.ListBox
        SelectwhichvariantstoplotLabel_4  matlab.ui.control.Label
        expression_marker_panel         matlab.ui.container.Panel
        Dataset_pick_expression_marker  matlab.ui.control.DropDown
        DatasetLabel_3                  matlab.ui.control.Label
        ExpressionvsMarkerCorrelationLabel  matlab.ui.control.Label
        tSNETab                         matlab.ui.container.Tab
        Hyperlink                       matlab.ui.control.Hyperlink
        Hyperlink5                      matlab.ui.control.Hyperlink
        Hyperlink4                      matlab.ui.control.Hyperlink
        PopoutButton_tsne               matlab.ui.control.Button
        MoreSettings_tsne               matlab.ui.control.StateButton
        PauseButton_tsne                matlab.ui.control.StateButton
        InspectSelectionButton_tsne     matlab.ui.control.StateButton
        StoptSNEButton                  matlab.ui.control.StateButton
        StarttSNEButton                 matlab.ui.control.Button
        marker_select_box_tsne          matlab.ui.control.ListBox
        marker_select_box_tsne_label    matlab.ui.control.Label
        variant_select_box_tsne         matlab.ui.control.ListBox
        variant_select_box_tsne_label   matlab.ui.control.Label
        Panel_tsne                      matlab.ui.container.Panel
        dataset_pick_tsne               matlab.ui.control.DropDown
        dataset_pick_tsne_label         matlab.ui.control.Label
        tsne_lamp                       matlab.ui.control.Lamp
        tsne_lamp_label                 matlab.ui.control.Label
        tSNEclusterLabel                matlab.ui.control.Label
        UMAPTab                         matlab.ui.container.Tab
        Hyperlink2                      matlab.ui.control.Hyperlink
        Hyperlink6                      matlab.ui.control.Hyperlink
        PopoutButton_umap               matlab.ui.control.Button
        MoreSettings_umap               matlab.ui.control.StateButton
        PauseButton_umap                matlab.ui.control.StateButton
        InspectSelection_umap           matlab.ui.control.StateButton
        StopUMAPButton                  matlab.ui.control.StateButton
        StartUMAPButton                 matlab.ui.control.Button
        marker_select_box_umap          matlab.ui.control.ListBox
        marker_select_box_tsne_label_2  matlab.ui.control.Label
        variant_select_box_umap         matlab.ui.control.ListBox
        variant_select_box_tsne_label_2  matlab.ui.control.Label
        Panel_umap                      matlab.ui.container.Panel
        dataset_pick_umap               matlab.ui.control.DropDown
        dataset_pick_umap_label         matlab.ui.control.Label
        umap_lamp                       matlab.ui.control.Lamp
        umap_lamp_label                 matlab.ui.control.Label
        UMAPclusterLabel                matlab.ui.control.Label
        PCATab                          matlab.ui.container.Tab
        PCADimensionOptionDropDown      matlab.ui.control.DropDown
        PCADimensionOptionDropDownLabel  matlab.ui.control.Label
        dataset_pick_PCA                matlab.ui.control.DropDown
        dataset_pick_umap_label_2       matlab.ui.control.Label
        Hyperlink2_2                    matlab.ui.control.Hyperlink
        Hyperlink6_2                    matlab.ui.control.Hyperlink
        PopoutButton_umap_2             matlab.ui.control.Button
        StopPCAButton                   matlab.ui.control.StateButton
        StartPCAButton                  matlab.ui.control.Button
        marker_select_box_PCA           matlab.ui.control.ListBox
        marker_select_box_tsne_label_3  matlab.ui.control.Label
        variant_select_box_PCA          matlab.ui.control.ListBox
        variant_select_box_tsne_label_3  matlab.ui.control.Label
        Panel_PCA                       matlab.ui.container.Panel
        PrincipleComponentAnalysisLabel  matlab.ui.control.Label
        BiexponentialPlotTab            matlab.ui.container.Tab
        marker_select_box_biexp2        matlab.ui.control.ListBox
        marker_select_box_tsne_label_5  matlab.ui.control.Label
        dataset_pick_biexp              matlab.ui.control.DropDown
        dataset_pick_umap_label_3       matlab.ui.control.Label
        Hyperlink6_3                    matlab.ui.control.Hyperlink
        PopoutButton_biexp              matlab.ui.control.Button
        StopBiexponentialPlotButton     matlab.ui.control.StateButton
        StartBiexponentialPlotButton    matlab.ui.control.Button
        marker_select_box_biexp         matlab.ui.control.ListBox
        marker_select_box_tsne_label_4  matlab.ui.control.Label
        variant_select_box_biexp        matlab.ui.control.ListBox
        variant_select_box_tsne_label_4  matlab.ui.control.Label
        Panel_biexp                     matlab.ui.container.Panel
        BiexponentialAnalysisLabel      matlab.ui.control.Label
        AGNESTab                        matlab.ui.container.Tab
        Panel_AGNES_yaxis               matlab.ui.container.Panel
        yaxisLabel                      matlab.ui.control.Label
        xaxisLabel                      matlab.ui.control.Label
        SaveAGNESPlotButton             matlab.ui.control.Button
        Hyperlink6_4                    matlab.ui.control.Hyperlink
        RunAGNESButton                  matlab.ui.control.Button
        excel_select_box_agnes          matlab.ui.control.ListBox
        variant_select_box_AGNES_label  matlab.ui.control.Label
        Panel_AGNES_xaxis               matlab.ui.container.Panel
        AgglomerativeNestingHierachicalClusteringLabel  matlab.ui.control.Label
        ContextMenu_filter_channel_absolute  matlab.ui.container.ContextMenu
        Apply_on_all_absolute_filter    matlab.ui.container.Menu
        ContextMenu_filter_channel_quantile  matlab.ui.container.ContextMenu
        Apply_on_all_quantile_filter    matlab.ui.container.Menu
        ContextMenu_scatter_filter_apply  matlab.ui.container.ContextMenu
        apply_scatter_filter_to_all_variants  matlab.ui.container.Menu
    end


    properties (Access = public)
        %% Importing data
        file_types_supported = [".txt", ".csv", ".xls", ".xlsb", ".xlsm", ".xlsx", ".dat",".xltm", ".xltx", ".ods"]; % Supported input file types (not all tested!)
        inputdata_dir = ".\Input Data\"; %directory to input data folder, at same level as app
        app_settings_dir = fullfile(cd,"Temp","app_settings.mat");

        selected_data_set
        cell_line_names %name for current dataset variants

        datasets_dictionary_original = dictionary(string.empty,dictionary());
        datasets_dictionary_processed = dictionary(string.empty,dictionary());

        dataset_list = string.empty;

        %% Marker list setting parameters
        old2new_column_names = dictionary(); %store column names to change
        new2old_column_names = dictionary();
        available_markers = {};
        marker_list = {}; %store column names to change
        marker_do_normalise_button_group %group for buttons to normalise
        marker_do_normalise_buttons %store buttons for which channels to normalise
        marker_do_normalise = dictionary(); %store whether to normalise channels

        %Do comparison and original data set match
        comparison_match = 0;

        %% Filtering
        dataset_dictionary_marker_absolute_limits = dictionary(string.empty,dictionary());
        dataset_dictionary_marker_absolute_filter_bounds = dictionary(string.empty,dictionary());
        dataset_dictionary_marker_quantile_filter_bounds = dictionary(string.empty,dictionary());
        dataset_dictionary_marker_polygon_filter = dictionary(string.empty,dictionary());

        boundary_lines %Histogram plot boundary lines

        %% Filter slider
        ax;    % for the axes
        l;     % for the line
        filter_listener %listen for change in slider
        qc_hist_plot_data

        %% Filter polygon
        polygon_plots;
        current_polygon;
        allow_save_polygon = 0;

        %% nd plot
        tiles_nd_plot
        axes_nd_plot

        %% Correlation plot
        correlation_tiles

        %% Correlation tests
        tiles_correlation_test
        axes_correlation_test = 0;
        %% Expression Marker plot
        segmentdata = struct();
        axes_expression_marker
        tiles_expression_marker

        %% 3D fit on 2d
        axes_3D_scatter_on_2D_plot
        tiles_3D_scatter_on_2D_plot

        %% Does the plot exist currently?
        exist_correlation_plot = 0;
        exist_expression_marker_plot = 0;
        exist_nd_plot = 0;
        exist_3d_on_2d_fit = 0;
        exist_tsne_plot = 0;

        %% Saving data
        outputdata_dir = fullfile(cd,"Output Data");
        outputplot_dir = fullfile(cd,"Output Plots");
        save_file_type = ".csv";

        %% tSNE output
        tsne_output
        tsne_plot_tiles
        tsne_master_table

        %% UMAP output
        umap_output
        umap_plot_tiles
        umap_master_table

        umap_input_meta
        umap_input_settings

        %% PCA output
        PCA_output
        PCA_plot_tiles
        PCA_master_table

        %% Biexp output
        biexp_output
        biexp_plot_tiles
        biexp_master_table

        %% AGNES output
        agnes_output
        agnes_xaxis_plot_tiles
        agnes_yaxis_plot_tiles
        agnes_excel_path
        agnes_master_table

        AGNESXDistanceMatrix
        AGNESYDistanceMatrix
        AGNESXSimilarityMatrix
        AGNESYSimilarityMatrix
    
        % Processed AGNES data
        AGNESUniqueXLabels
        AGNESUniqueYLabels
        AGNESGroupedData
    
        % Hierarchical clustering results
        XClusterTree
        YClusterTree
    
        % Final label orders
        ClusteredColumnOrder
        ClusteredRowOrder


        %% App settings
        app_parameters = struct( ...
            ... App Settings
            "app_settings",[], ...
            "live_update_plots",'Simple plots only', ...
            "collate_repeat_cell_line_names",0, ...
            "enable_force",0);
        app_input_meta
        app_input_settings

        %% More settings parameters
        % nD plot settings
        % 1D plot
        d1_parameters = struct( ...
            ... 1D Plot Settings
            "plot_settings_1d",[], ...
            "do_histogram",1, ...
            "do_histogram_edge_line",0, ...
            "do_1d_legend",1, ...
            ...  Histogram Settings
            "hist_settings",[], ...
            "specify_bins_1d",0, ...
            "num_bins_1d",15, ...
            "specify_bin_width_1d",0, ...
            "bin_width_1d",100, ...
            "Normalization_1d",'count', ...
            "display_style_1d",'bar', ...
            "face_alpha_1d",0.6, ...
            "orientation_1d",'vertical');

        d1_input_meta
        d1_input_settings

        % 2d plot
        d2_parameters = struct( ...
            ... 2D Plot Settings
            "plot_settings",[], ...
            "do_scatter_2d",1, ...
            "do_legend_2d",1, ...
            "type_2d",'scatter', ...
            ... Scatter Plot settings
            "scatter_plot_settings",[], ...
            "markertype_2d",'s', ...
            ... Bin Scatter Settings
            "bin_scatter_settings",[], ...
            "do_bins_2d",0, ...
            "num_bins_2d",15, ...
            "do_bin_width_2d",0, ...
            "binwidth_2d",15, ...
            ... 3D Histogram Settings
            "histogram_3d_settings",[], ...
            "do_histogram_bins_2d",0, ...
            "num_histogram_bins_2d",15, ...
            "do_histogram_bin_width_2d",0, ...
            "histogram_bin_width_2d",15, ...
            ... Bin Bar settings
            "bin_bar_setting_title",[], ...
            "binbar_nbins_2d",6, ...
            "binbar_bin_method_2d", 'linear', ...
            "save_mean_median",[]);

        d2_input_meta
        d2_input_settings

        %%%%%%%%%%%%%%% Variants plot matrix tab
        vc_parameters = struct( ...
            ... Variant-Channel Plot Settings
            "plot_settings",[], ...
            "do_scatter",1, ...
            "do_linear_fit",0, ...
            "do_annotate_plots",1, ...
            "do_drawnow",1, ...
            ... Plot Settings
            "sub_plot_settings",[], ...
            "type",'scatter', ...
            "marker_type",'x', ...
            "do_histbins", 0, ...
            "histbins",30, ...
            "do_scatterbins", 0, ...
            "scatterbins",180, ...
            ... Correlation Settings
            "corr_settings",[], ...
            "correlation_method",'Spearman');

        vc_input_meta
        vc_input_settings

        %%%%%%%%%%%%%%%%%%%%% Correlation tests tab
        cortests_parameters = struct( ...
            ... Plot Settings
            "plot_settings",[], ...
            "which_plot_type",'Histogram', ...
            "do_all_marker_combinations",0, ...
            "do_histogram",1, ...
            "do_histogram_edge_line",1, ...
            "do_histogram_ci",1, ...
            "do_legend",1, ...
            ... Histogram Settings
            "hist_settings",[], ...
            "specify_bins_1d",0, ...
            "num_bins_1d",15, ...
            "specify_bin_width_1d",0, ...
            "bin_width_1d",100, ...
            "Normalization_1d",'count', ...
            "display_style_1d",'bar', ...
            "face_alpha_1d",0.6, ...
            "orientation_1d",'vertical', ...
            ... Box Chart Settings
            "boxchart_settings",[], ...
            "MarkerStyle",0, ...
            "Notch",1, ...
            "JitterOutliers",1, ...
            ... Correlation Settings
            "corr_settings",[], ...
            "correlation_method",'Spearman', ...
            "do_bootstrap",1, ...
            "bootstrap_repeat_samples",1000, ...
            "bootstrap_sample_size",100, ...
            "bootstrap_replace",1, ...
            "histogram_ci_percent",95, ...
            "comparison_method", "Percentile Bootstrap", ...
            ... Normality Test Settings
            "norm_test_table_settings",[], ...
            "normality_alpha",5);

        cortests_input_meta
        cortests_input_settings

        % Expression marker plot settings
        expression_marker_parameters = struct( ...
            ... Display plots
            "which_plots_show",[], ...
            "do_scatter",1, ...
            "do_smooth_data",1, ...
            "do_segmentations",1, ...
            "do_segment_line_fit",1, ...
            "do_segment_line_standard_error",1, ...
            "do_legend",1, ...
            ... Plot settings
            "plot_settings",[], ...
            "type",'scatter', ...
            "marker_type",'x', ...
            "marker_face_alpha",0.5, ...
            ... Smooth data settings
            "smooth_settings",[], ...
            "smooth_method",'movmean', ...
            "specify_smooth_span",0, ...
            "smooth_span",5, ...
            "auto_smooth_span_factor",0.5, ...
            ... Segmentation settings
            "segmentation_settings",[], ...
            "segment_on_smooth",1, ...
            "segment_method",'linear', ...
            "max_segments",2, ...
            "segment_using_threshold",0, ...
            "threshold",1, ...
            ... Fit settings
            "fit_settings",[], ...
            "fit_on_smooth",1, ...
            "fit_type",'poly1', ...
            "pred_interval",0.95, ...
            "prediction_bounds_type","observation", ...
            "prediction_bounds_simultaneous",0);

        expression_marker_input_meta
        expression_marker_input_settings

        % 3D on 2D fit
        scatter_3d_on_2d_parameters = struct( ...
            ... 3D on 2D Plot Settings
            "plot_settings",[], ...
            "do_scatter",1, ...
            "do_error_bars",1, ...
            "do_legend",1, ...
            "do_scatter_line",1, ...
            "do_manova",1, ...
            ... Scatter Plot Settings
            "scatter_settings",[], ...
            "point",'o', ...
            "error_bar_type",'standard error', ...
            "scatter_size_type",'linear', ...
            "binning_type",'percentile', ...
            "zvar_bins",10, ...
            "bin_across_cell_lines",1, ...
            "use_smoothed_data",0, ...
            ... Data Smoothing Settings
            "smooth_settings",[], ...
            "smooth_method",'rloess', ...
            "auto_smooth_span_factor",0.5, ...
            "specify_smooth_span",0, ...
            "smooth_span",5);

        scatter_3d_on_2d_input_meta
        scatter_3d_on_2d_input_settings

        % tSNE
        tSNE_parameters = struct( ...
            ... Display Plots
            "which_plots_show",[], ...
            "show_live_update",1, ...
            "show_training_divergence",0, ...
            "do_legend",1, ...
            ... tSNE Training Options
            "tsne_opts_header",[], ...
            "MaxIter",10000, ...
            "UseParallel",1, ...
            "NumDimensions",2, ...
            "NumPrint",30, ...
            "Standardize",0, ...
            "Algorithm",'barneshut', ...
            "Distance",'euclidean', ...
            "Exaggeration",4, ...
            "NumPCAComponents",0, ...
            "Perplexity",30, ...
            "LearnRate",500, ...
            "Theta",0.5);
        tSNE_input_meta
        tSNE_input_settings

        % UMAP
        umap_parameters = struct( ...
            ... Display Plots
            "h_display_plots",[], ...
            "show_live_update",1, ...
            "do_legend",1, ...
            ... UMAP Settings
            "h_umap_settings",[], ...
            "do_supervised_umap",1, ...
            "n_components",2, ...
            "min_dist",0.3, ...
            "spread",1, ...
            "n_neighbors",15, ...
            "metric",'euclidean', ...
            "P",2, ...
            "NSMethod",'nn_descent', ...
            "IncludeTies",0, ...
            "BucketSize",50, ...
            ... UMAP pop-out app settings
            "h_umap_app_settings",[], ...
            "cluster_method_2D",'dbm', ...
            "dbscan_distance",'euclidean', ...
            "epsilon",0.6, ...
            "minpts",5, ...
            "contour_percent",0);


    end

    methods (Access = public)
        
        function generate_mean_median(app)
            disp('worked');
            if app.d2_parameters.type_2d ~= "bin bars"

                d = errordlg('Error: plot type is incorrect.', 'Invalid Input');
        
                % Pause briefly to let the dialog fully appear
                pause(0.1);
                
                % Find the text object inside the dialog
                txt = findall(d, 'Type', 'text');
                
                % Change the font size
                set(txt, 'FontSize', 10, 'FontWeight', 'bold');  % You can adjust the size as needed

                btn = findall(d, 'Type', 'uicontrol', 'Style', 'pushbutton');
            
                % Find the OK button
                btn = findall(d, 'Type', 'uicontrol', 'Style', 'pushbutton');
        
                % Get current button size and position
                btnPos = btn.Position;  % [left bottom width height]
        
                % Get dialog size
                dialogPos = d.Position; % [left bottom width height]
        
                % Center button horizontally without touching its height
                btnPos(1) = (dialogPos(3) - btnPos(3)) / 2;  % New left position
                btn.Position = btnPos;  % Apply new position


                return;

            else
                xvar = app.Xaxismarker_ND_scatter.Value;
                yvar = app.Yaxismarker_ND_scatter.Value;
                zvar = app.Zaxismarker_ND_scatter.Value;
                vars = {xvar, yvar, zvar};
    
                % Find the unique variables
                unique_vars = unique(vars);
                
                % Check how many unique variables exist
                if numel(unique_vars) ~= 2
                    d = errordlg('Error: The dimension of data is incorrect.', 'Dimension Error');
                    pause(0.1);  % Allow the dialog to build
                    txt = findall(d, 'Type', 'text');
                    set(txt, 'FontSize', 10, 'FontWeight', 'bold');

                    d.Position(3) = 250; % width

                    % Find the OK button
                    btn = findall(d, 'Type', 'uicontrol', 'Style', 'pushbutton');
            
                    % Get current button size and position
                    btnPos = btn.Position;  % [left bottom width height]
            
                    % Get dialog size
                    dialogPos = d.Position; % [left bottom width height]
            
                    % Center button horizontally without touching its height
                    btnPos(1) = (dialogPos(3) - btnPos(3)) / 2;  % New left position
                    btn.Position = btnPos;  % Apply new position

                    
                    return;

                end

                selected_dataset = app.Dataset_pick_ND_scatter.Value;
                selected_pack = app.datasets_dictionary_processed(selected_dataset);
                selected_cell_lines = app.ND_variant_select_box.Value;
                if isempty(selected_cell_lines)
                    % Code to execute if empty
                    d = errordlg('Error: Did not select any cell lines.', 'No Selection Error');
                    pause(0.1);  % Allow the dialog to build
                    txt = findall(d, 'Type', 'text');
                    set(txt, 'FontSize', 10, 'FontWeight', 'bold');

                    d.Position(3) = 230; % width

                    % Find the OK button
                    btn = findall(d, 'Type', 'uicontrol', 'Style', 'pushbutton');
            
                    % Get current button size and position
                    btnPos = btn.Position;  % [left bottom width height]
            
                    % Get dialog size
                    dialogPos = d.Position; % [left bottom width height]
            
                    % Center button horizontally without touching its height
                    btnPos(1) = (dialogPos(3) - btnPos(3)) / 2;  % New left position
                    btn.Position = btnPos;  % Apply new position

                    return;
                end
                

                %praise
                save_mean_median_binlength(app.d2_parameters.binbar_nbins_2d,selected_pack,selected_cell_lines,yvar,xvar);


            end
            %save_mean_median_binlength();


        end

        function datalist_update(app)
            %% Load in data
            if app.datasets_dictionary_original.numEntries<1
                return
            end

            progbar = uiprogressdlg(app.UIFigure,"Indeterminate","on","Message",'Updating app...');

            %% Show datalist options
            app.dataset_list = app.datasets_dictionary_original.keys;

            property_change(["Items","ItemsData"],app.dataset_list, ...
                app.DatasetDropDown_load,...%Load page
                app.Dataset_pick_channel_filter,...%1D filter page
                app.Dataset_pick_scatter_filter,...%2D scatter filter page
                app.Dataset_pick_ND_scatter,...%ND scatter
                app.Dataset_pick_variant_correlation, ...%Correlation grid page
                app.Dataset_pick_expression_marker, ... %Expression segmentation
                app.Dataset_pick_correlation_tests, ... %Correlation tests
                app.Dataset_pick_3D_scatter_on_2d, ...%Expression marker page
                app.dataset_pick_tsne, ... %tSNE
                app.dataset_pick_umap, ...
                app.dataset_pick_PCA, ...
                app.dataset_pick_biexp); %tSNE

            app.selected_data_set = app.DatasetDropDown_load.Value;

            %% Update views of cell lines [Original]
            app.OriginalFilesListBox = data_set_change(app,app.selected_data_set,app.OriginalFilesListBox);

            %% Update list of available channels consistent between all loaded datasets
            [app.available_markers] = consistency_check_channel_names(app.datasets_dictionary_original, ...
                "fig",app.UIFigure);

            %% Close progress bar
            close(progbar)

            %% Update marker list
            update_marker_list(app)

        end

        function variants_select_box = data_set_change(app,selected_set,variants_select_box)

            old_cells = variants_select_box.Value;
            app.selected_data_set = selected_set;

            %% Update selected data set
            property_change("Value",app.selected_data_set, ...
                app.DatasetDropDown_load,...%Load page
                app.Dataset_pick_channel_filter,...%1D filter page
                app.Dataset_pick_scatter_filter,...%2D scatter filter page
                app.Dataset_pick_ND_scatter,...%ND scatter
                app.Dataset_pick_variant_correlation, ...%Correlation grid page
                app.Dataset_pick_expression_marker, ... % expression segmentation
                app.Dataset_pick_correlation_tests, ... % correlation tests
                app.Dataset_pick_3D_scatter_on_2d, ...%3d on 2d page
                app.dataset_pick_tsne, ... %tSNE
                app.dataset_pick_umap, ...%UMAP
                app.dataset_pick_PCA, ... %PCA
                app.dataset_pick_biexp); %biexp

            %% Update views of cell lines [Original]
            app.cell_line_names = app.datasets_dictionary_original(app.selected_data_set);
            app.cell_line_names = app.cell_line_names.keys;

            property_change(["Items","ItemsData"],app.cell_line_names, ...
                app.OriginalFilesListBox, ...%Loading page %update items list
                app.FileNameDropDown_Load, ...%update table viewer list
                app.Variants_ListBox_channel_filtering, ...%1D Filter Page
                app.Variants_ListBox_scatter_filtering, ...%2D Scatter Filter page
                app.ND_variant_select_box,...%ND scatter
                app.Correlation_plot_variant_select_box, ...%Correlation page
                app.correlation_tests_variant_select_box, ...% correlation tests
                app.Expression_marker_plot_variant_select_box, ...% expression segmentation
                app.Scatter_3d_fit_on_2d_variant_select_box, ...%3d on 2d page
                app.variant_select_box_tsne, ... %tSNE
                app.variant_select_box_umap, ...% UMAP
                app.variant_select_box_PCA, ... % PCA
                app.variant_select_box_biexp); % biexp

            variants_select_box.Value = intersect(variants_select_box.Items,old_cells);
        end

        function update_marker_list(app)

            progbar = uiprogressdlg(app.UIFigure,"Indeterminate","on","Message",'Updating app...');

            app.marker_list = intersect(app.marker_list,app.available_markers);

            %% Update dictionary of renames
            [app.old2new_column_names,app.new2old_column_names] = rename_dictionary_updates(app.OriginalcolumnnamesListBox.Items,app.NewcolumnnamesListBox.Items);

            %% Update views of marker list
            property_change(["Items","ItemsData"],app.marker_list, ...
                app.MarkerchannelsListBox, ...%marker edit page
                app.NormalisingchannelifrelevantDropDown,...
                app.PickwhichmarkertoplotDropDown, ...% 1D filter page
                app.SelectmarkerchannelstofilterusingquantilesListBox, ...
                app.Xaxismarker_scatter_filter, ...% 2D scatter filter page
                app.Yaxismarker_scatter_filter, ...
                app.Xaxismarker_ND_scatter, ... % ND scatter
                app.Yaxismarker_ND_scatter, ...
                app.Zaxismarker_ND_scatter, ...
                app.Marker_pick_correlation_plot_list_box, ... % Correlation grid page
                app.marker_pick_correlation_tests, ... % Correlation tests
                app.Yaxismarker_expression_marker_plot, ...%Expression marker page
                app.Xaxismarker_expression_marker_plot, ...
                app.Xaxismarker_3d_scatter_on_2d, ...%3D scatter on 2D plot page
                app.Yaxismarker_3d_scatter_on_2d, ...
                app.Zaxismarker_3d_scatter_on_2d, ...
                app.marker_select_box_tsne, ... %tSNE
                app.marker_select_box_umap, ... %UMAP
                app.marker_select_box_PCA, ...
                app.marker_select_box_biexp, ...
                app.marker_select_box_biexp2); %PCA

            %% Update do normalise toggle buttons
            [app.marker_do_normalise_buttons,app.marker_do_normalise_button_group] = toggle_do_normalise_markers_2(app.normalise_channel_buttons_panel,app.marker_list);

            %% Update filters on marker channels for entire datasets dictionary
            [app.dataset_dictionary_marker_absolute_limits,...
                app.dataset_dictionary_marker_absolute_filter_bounds, ...
                app.dataset_dictionary_marker_quantile_filter_bounds, ...
                app.dataset_dictionary_marker_polygon_filter] = ...
                initialise_filter_dictionaries_upon_marker_change_2( ...
                app.marker_list, app.new2old_column_names, ...
                app.datasets_dictionary_original, ...
                app.dataset_dictionary_marker_absolute_limits,...
                app.dataset_dictionary_marker_absolute_filter_bounds, ...
                app.dataset_dictionary_marker_quantile_filter_bounds, ...
                app.dataset_dictionary_marker_polygon_filter);

            %% Close progress bar
            drawnow;
            close(progbar)
        end

        function lMoving(app,plot_data)
            %no vertical movement
            app.l.Position(3) = 0;
            app.l.Position(4) = 0;
            %update editfield boxes
            min_marker = min(app.l.Position(1),app.l.Position(2));
            max_marker = max(app.l.Position(1),app.l.Position(2));

            app.LowerboundEditField.Value = min_marker;
            app.UpperboundEditField.Value = max_marker;

            % update bounds shown
            delete(app.boundary_lines)
            switch app.d1_parameters.orientation_1d
                case 'vertical'
                    app.boundary_lines = xline(app.Filter_by_channel_plot,[app.LowerboundEditField.Value, app.UpperboundEditField.Value],'--r',{'Lower Bound','Upper Bound'},'HandleVisibility','off');
                case 'horizontal'
                    app.boundary_lines = yline(app.Filter_by_channel_plot,[app.LowerboundEditField.Value, app.UpperboundEditField.Value],'--r',{'Lower Bound','Upper Bound'},'HandleVisibility','off');
            end

            filtered_data = plot_data(plot_data>=min_marker&plot_data<=max_marker);
            if isempty(filtered_data)
                numcells = 0;
            else
                numcells = numel(filtered_data);
            end
            app.FilterSliderPanel.Title = sprintf("Filter Slider | Cells within boundaries: %d",numcells);
        end

        function update_slider(app,slide_min,slide_max,marker_min,marker_max,plot_data)
            if numel(app.marker_list)>0&&numel(app.Variants_ListBox_channel_filtering.Value)>0

                %% Update limits
                app.UpperboundEditField.Limits = [-inf,inf];
                app.LowerboundEditField.Limits = [-inf,inf];

                app.LowerboundEditField.Value = marker_min;
                app.UpperboundEditField.Value = marker_max;

                if slide_min==slide_max
                    app.UpperboundEditField.Enable = "off";
                    app.LowerboundEditField.Enable = "off";
                else
                    app.UpperboundEditField.Enable = "on";
                    app.LowerboundEditField.Enable = "on";
                    app.UpperboundEditField.Limits = [slide_min,slide_max];
                    app.LowerboundEditField.Limits = [slide_min,slide_max];

                    %% Create the 1D axes
                    delete(app.ax)

                    app.ax = axes( ...
                        app.FilterSliderPanel,'Color','none','YColor','none', ...
                        'YTick',[], ...
                        'XLim', ...
                        [slide_min,slide_max], ...
                        'TickDir','both','XMinorTick', ...
                        'on','Units','normalized','Position',[0.01,0.6,0.98,0],'LineWidth',1 ...
                        );

                    %% Disable the interactivity & toolbar visibility
                    disableDefaultInteractivity(app.ax);
                    app.ax.Toolbar.Visible = 'off';

                    %% Add the line ROI
                    delete(app.l)
                    app.l = images.roi.Line(app.ax,'Position',[marker_min,0;marker_max,0],'LineWidth',4);

                    %% Add a listener that will trigger a callback function titled "lMoving" when user
                    % moves the ROI endpoints or the line ROI as a whole
                    delete(app.filter_listener)
                    app.filter_listener = addlistener(app.l,'MovingROI',@(varargin)lMoving(app,plot_data));

                    lMoving(app,plot_data)

                end

            end
        end

        function update_absolute_filter_plot(app)

            progressbar = uiprogressdlg(app.UIFigure,"Title","Progress bar...","Message","Updating plots...","Indeterminate","on");

            %% Conditions
            condition1 = numel(app.marker_list)>0; %make sure there are identified markers
            condition2 = numel(app.dataset_list)>0; %make sure there is data loaded in still
            condition3 = numel(app.Variants_ListBox_channel_filtering.Value)>0; %check variants are selected

            if condition1&&condition2&&condition3
                %% Set up plot axis
                reset(app.Filter_by_channel_plot)
                cla(app.Filter_by_channel_plot)
                delete(app.boundary_lines)
                hold(app.Filter_by_channel_plot,"on")
                app.Filter_by_channel_plot.XLimMode = "auto";
                grid(app.Filter_by_channel_plot,"on")

                %% Get min and max data for selected cell lines
                selected_marker = app.PickwhichmarkertoplotDropDown.Value;
                selected_cell_lines = app.Variants_ListBox_channel_filtering.Value;
                selected_dataset = app.Dataset_pick_channel_filter.Value;

                [slide_min,slide_max,marker_min,marker_max,quantile_min,quantile_max] = filters_min_max_2( ...
                    selected_dataset,selected_cell_lines,selected_marker, ...
                    app.dataset_dictionary_marker_absolute_limits, ...
                    app.dataset_dictionary_marker_absolute_filter_bounds, ...
                    app.dataset_dictionary_marker_quantile_filter_bounds);

                if isnan(slide_min)
                    close(progressbar)
                    return
                end

                marker_max = min(slide_max,marker_max);
                marker_min = max(marker_min,slide_min);

                %% Get histogram plot data
                % get plot data from both original data set and/or
                % comparison
                pack_data = app.datasets_dictionary_original(selected_dataset);

                % do histogram
                plot_params1 = app.d1_parameters;
                fields = fieldnames(plot_params1);
                plot_params1 = rmfield(plot_params1, fields(structfun(@isempty, plot_params1)));
                plot_params1 = namedargs2cell(plot_params1);

                [~,plot_data] = histogram_plot_1d(pack_data, ...
                    selected_cell_lines,selected_marker, ...
                    "axis",app.Filter_by_channel_plot, ...
                    plot_params1{:}, ...
                    "absolute_filters",[marker_min,marker_max], ...
                    "quantile_filters",[quantile_min,quantile_max], ...
                    "first_filter",app.WhichfiltergoesfirstSwitch.Value);

                legend(app.Filter_by_channel_plot,'boxoff')

                app.Filter_by_channel_plot.Visible = "on";
                app.Filter_by_channel_plot.XLimMode = "manual";

                app.qc_hist_plot_data = plot_data;

                %%update slider
                update_slider(app,slide_min,slide_max,marker_min,marker_max,plot_data)
            end

            close(progressbar)

        end

        function filter_process_data(app)
            %% Conditions
            condition1 = numel(app.marker_list)>0; %make sure there are identified markers
            condition2 = numel(app.dataset_list)>0; %make sure there is data loaded in still

            %% initiate
            if condition1&&condition2

                %% Get marker normalise options
                app.marker_do_normalise = register_do_normalise_markers_from_buttons(app.marker_do_normalise_buttons);

                datafile_type = string(app.DatafiletypeDropDown.Value);
                normalising_channel =string(app.NormalisingchannelifrelevantDropDown.Value);

                filter_order = [string(app.filter1.Value),string(app.filter2.Value),string(app.filter3.Value)];

                [app.datasets_dictionary_processed,~] = master_process_library_of_packs( ...
                    app.datasets_dictionary_original,app.dataset_list, ... %import variables
                    app.outputdata_dir,app.outputplot_dir,datafile_type,  ... %saving directories and data filetype
                    app.new2old_column_names, ... %which channels to rename
                    app.marker_list, ... which channels are relevant markers
                    app.dataset_dictionary_marker_absolute_filter_bounds, ... lower and upper absolute marker filters
                    app.dataset_dictionary_marker_quantile_filter_bounds, ... lower and upper quantile marker filters
                    app.dataset_dictionary_marker_polygon_filter, ... %marker polygon filter
                    normalising_channel, app.marker_do_normalise, ... normalising channel name and which channels to normalise
                    app.LogDataListListBox.ItemsData, ...
                    app.Zero_adjust_switch.Value, ..., ...
                    filter_order, ...
                    "app",app);

                if app.app_parameters.enable_force
                    [y, Fs] = audioread('i-have-the-high-ground.mp3');
                    sound(y,Fs);
                end

            end
        end

        function update_nd_plot(app)
            progressbar = uiprogressdlg(app.UIFigure,"Title","Progress bar...","Message","Updating plots...","Indeterminate","on");

            %% Conditions
            condition1 = numel(app.marker_list)>0; %make sure there are identified markers
            condition2 = numel(app.dataset_list)>0; %make sure there is data loaded in still
            condition3 = numel(app.ND_variant_select_box.Value)>0;

            if condition1&&condition2&&condition3
                app.exist_nd_plot = 1;
                %refresh plot
                app.tiles_nd_plot = tiledlayout(app.Panel_ND_scatter,1,1,"TileSpacing","tight","Padding","tight");
                app.axes_nd_plot = nexttile(app.tiles_nd_plot,1);
                reset(app.axes_nd_plot)
                cla(app.axes_nd_plot)
                app.axes_nd_plot.XLimMode = "auto";
                grid(app.axes_nd_plot,"on")

                %do plot
                selected_cell_lines = string(app.ND_variant_select_box.Value);
                selected_dataset = app.Dataset_pick_ND_scatter.Value;
                selected_pack = app.datasets_dictionary_processed(selected_dataset);

                xvar = string(app.Xaxismarker_ND_scatter.Value);
                yvar = string(app.Yaxismarker_ND_scatter.Value);
                zvar = string(app.Zaxismarker_ND_scatter.Value);

                % do histogram
                plot_params1 = app.d1_parameters;
                fields = fieldnames(plot_params1);
                plot_params1 = rmfield(plot_params1, fields(structfun(@isempty, plot_params1)));
                plot_params1 = namedargs2cell(plot_params1);

                % do 2d
                plot_params2 = app.d2_parameters;
                fields = fieldnames(plot_params2);
                plot_params2 = rmfield(plot_params2, fields(structfun(@isempty, plot_params2)));
                plot_params2 = namedargs2cell(plot_params2);

                [~,cell_count] = plot_nD_vars(selected_pack,app.cell_line_names,selected_cell_lines, ...
                    xvar,yvar,zvar, ...
                    "axis",app.axes_nd_plot, ...
                    "app",app, ...
                    plot_params1{:}, ...
                    plot_params2{:});

                app.Panel_ND_scatter.Title = strcat("Number of cells: ",string(cell_count));

            end

            close(progressbar)

        end

        function update_scatter_filter_plot(app)
            progressbar = uiprogressdlg(app.UIFigure,"Title","Progress bar...","Message","Updating plots...","Indeterminate","on");

            %% Conditions
            condition1 = numel(app.marker_list)>0; %make sure there are identified markers
            condition2 = numel(app.dataset_list)>0; %make sure there is data loaded in still
            condition3 = numel(app.cell_line_names)>0; %make sure there is data loaded in still

            %% initiate
            if condition1&&condition2&&condition3
                %refresh plot
                reset(app.axes_scatter_filter_plot)
                cla(app.axes_scatter_filter_plot)
                app.axes_scatter_filter_plot.XLimMode = "auto";
                app.allow_save_polygon = 0;
                grid(app.axes_scatter_filter_plot,"on")


                %do plot
                selected_dataset = app.datasets_dictionary_original(app.Dataset_pick_scatter_filter.Value);
                selected_cell_lines = string(app.Variants_ListBox_scatter_filtering.Value);

                xvar = string(app.new2old_column_names({app.Xaxismarker_scatter_filter.Value}));
                yvar = string(app.new2old_column_names({app.Yaxismarker_scatter_filter.Value}));

                variants_pack = app.datasets_dictionary_original(app.Dataset_pick_scatter_filter.Value);
                variants_pack_polygon_filter = app.dataset_dictionary_marker_polygon_filter(app.Dataset_pick_scatter_filter.Value);

                %% Plot data set
                filter_scatter_plot(variants_pack, ...
                    selected_cell_lines, ...
                    xvar,yvar, ...
                    "app",app, ...
                    "axis",app.axes_scatter_filter_plot, ...
                    "binwidth",app.Binwidth_scatter_filter.Value, ...
                    "type",string(app.Filter_scatter_plot_type.Value), ...
                    "do_binwidth",string(app.Filter_scatter_do_bin_width.Value)=="Manual", ...
                    "legend_labels",selected_cell_lines);

                if ~app.DrawPolygonButton.Value
                    %plot polygons
                    filter_scatter_overlay_polygons(xvar,yvar, ...
                        selected_cell_lines,variants_pack_polygon_filter,app.axes_scatter_filter_plot)
                end

            end
            close(progressbar)

        end

        function update_variant_marker_correlation_plot(app)

            progressbar = uiprogressdlg(app.UIFigure,"Title","Progress bar...","Message","Updating plots...","Indeterminate","on");
            if numel(app.Correlation_plot_variant_select_box.Value)>0&&numel(app.Marker_pick_correlation_plot_list_box.Value)>1
                app.exist_correlation_plot = 1;
                %% refresh panel
                delete(app.correlation_tiles);

                %% Plot function inputs
                selected_dataset = app.Dataset_pick_variant_correlation.Value;
                selected_pack = app.datasets_dictionary_processed(selected_dataset);
                selected_cell_lines = string(app.Correlation_plot_variant_select_box.Value);

                selected_markers = app.Marker_pick_correlation_plot_list_box.Value;

                % params
                params = app.vc_parameters;
                fields = fieldnames(params);
                params = rmfield(params, fields(structfun(@isempty, params)));
                params = namedargs2cell(params);

                [~,app.correlation_tiles] = correlation_variant_plot( ...
                    selected_pack, ...
                    app.cell_line_names, ...
                    selected_cell_lines, ...
                    selected_markers, ...
                    "app",app, ...
                    "panel",app.panel_variant_marker_correlation, ...
                    params{:});

            end
            close(progressbar)
        end

        function update_correlation_tables(app)

            progressbar = uiprogressdlg(app.UIFigure,"Title","Progress bar...","Message","Updating plots...","Indeterminate","on");

            condition1 = numel(app.Correlation_plot_variant_select_box.Value)>0;
            condition2 = numel(app.Marker_pick_correlation_plot_list_box.Value)>1;

            if condition1&&condition2

                %% Plot function inputs
                selected_dataset = app.Dataset_pick_variant_correlation.Value;
                selected_pack = app.datasets_dictionary_processed(selected_dataset);
                selected_cell_lines = string(app.Correlation_plot_variant_select_box.Value);

                selected_markers = app.Marker_pick_correlation_plot_list_box.Value;

                generate_correlation_table(selected_pack,selected_cell_lines, ...
                    selected_markers, ...
                    "correlation_method",app.vc_parameters.correlation_method);

            end
            close(progressbar)
        end

        function update_correlation_tests_plot(app)

            progressbar = uiprogressdlg(app.UIFigure,"Title","Progress bar...","Message","Updating plots...","Indeterminate","on");
            condition1 = numel(app.correlation_tests_variant_select_box.Value)>0;
            condition2 = numel(app.marker_pick_correlation_tests.Value)>0;

            if condition1&&condition2
                %% refresh panel
                %% refresh plot
                %refresh plot
                app.tiles_correlation_test = tiledlayout(app.correlation_test_plot_panel,1,1,"TileSpacing","tight","Padding","tight");
                app.axes_correlation_test = nexttile(app.tiles_correlation_test,1);
                reset(app.axes_correlation_test)
                cla(app.axes_correlation_test)
                app.axes_correlation_test.XLimMode = "auto";
                app.axes_correlation_test.YGrid = "on";

                %% Plot function inputs
                selected_dataset = app.Dataset_pick_correlation_tests.Value;
                selected_pack = app.datasets_dictionary_processed(selected_dataset);
                selected_cell_lines = string(app.correlation_tests_variant_select_box.Value);

                selected_markers = app.marker_pick_correlation_tests.Value;

                params = params_2_args(app.cortests_parameters);

                correlation_tests_plot(selected_pack, ...
                    selected_cell_lines,selected_markers, ...
                    "app",app, ...
                    "axis",app.axes_correlation_test, ...
                    params{:});

            end

            if app.app_parameters.enable_force
                [y, Fs] = audioread('you-were-the-chosen-one-star-wars.mp3');
                sound(y,Fs);
            end

            close(progressbar)
        end

        function update_correlation_normality_table(app)

            progressbar = uiprogressdlg(app.UIFigure,"Title","Progress bar...","Message","Updating plots...","Indeterminate","on");
            condition1 = numel(app.correlation_tests_variant_select_box.Value)>0;
            condition2 = numel(app.marker_pick_correlation_tests.Value)>0;

            if condition1&&condition2
                %% refresh panel
                %% refresh table
                %% Plot function inputs
                selected_dataset = app.Dataset_pick_correlation_tests.Value;
                selected_pack = app.datasets_dictionary_processed(selected_dataset);
                selected_cell_lines = string(app.correlation_tests_variant_select_box.Value);

                selected_markers = app.marker_pick_correlation_tests.Value;

                [~,~] = correlation_normality_test_table(selected_pack, ...
                    selected_cell_lines,selected_markers, ...
                    "normality_test_table",app.correlation_test_table, ...
                    "normality_alpha",app.cortests_parameters.normality_alpha);

            end
            close(progressbar)
        end

        function update_correlation_comparison_tables(app)
            progressbar = uiprogressdlg(app.UIFigure,"Title","Progress bar...","Message","Updating plots...","Indeterminate","on");
            condition1 = numel(app.correlation_tests_variant_select_box.Value)>1;
            condition2 = numel(app.marker_pick_correlation_tests.Value)>1;

            if condition1&&condition2
                %% refresh panel
                %% refresh table
                %% Plot function inputs
                selected_dataset = app.Dataset_pick_correlation_tests.Value;
                selected_pack = app.datasets_dictionary_processed(selected_dataset);
                selected_cell_lines = string(app.correlation_tests_variant_select_box.Value);

                selected_markers = app.marker_pick_correlation_tests.Value;

                generate_correlation_comparison_table(selected_pack,selected_cell_lines, ...
                    selected_markers, ...
                    "bootstrap_repeat_samples",app.cortests_parameters.bootstrap_repeat_samples, ...
                    "bootstrap_replace",app.cortests_parameters.bootstrap_replace, ...
                    "bootstrap_sample_size",app.cortests_parameters.bootstrap_sample_size, ...
                    "comparison_method",app.cortests_parameters.comparison_method, ...
                    "correlation_method",app.cortests_parameters.correlation_method);

            end
            close(progressbar)
        end

        function update_expression_marker_plot(app)

            progressbar = uiprogressdlg(app.UIFigure,"Title","Progress bar...","Message","Updating plots...","Indeterminate","on");

            condition1 = numel(app.Expression_marker_plot_variant_select_box.Value)>0;
            condition2 = numel(app.marker_list)>1;

            if condition1&&condition2
                app.exist_expression_marker_plot = 1;

                %% refresh plot
                %refresh plot
                app.tiles_expression_marker = tiledlayout(app.expression_marker_panel,1,1,"TileSpacing","tight","Padding","tight");
                app.axes_expression_marker = nexttile(app.tiles_expression_marker,1);
                reset(app.axes_expression_marker)
                cla(app.axes_expression_marker)
                app.axes_expression_marker.XLimMode = "auto";
                grid(app.axes_expression_marker,"on")

                %% Plot function inputs
                selected_dataset = app.Dataset_pick_expression_marker.Value;
                selected_pack = app.datasets_dictionary_processed(selected_dataset);
                selected_cell_lines = string(app.Expression_marker_plot_variant_select_box.Value);

                expression_channel = app.Xaxismarker_expression_marker_plot.Value;

                selected_marker = app.Yaxismarker_expression_marker_plot.Value;

                params = app.expression_marker_parameters;
                fields = fieldnames(params);
                params = rmfield(params, fields(structfun(@isempty, params)));
                params = namedargs2cell(params);

                [~,app.segmentdata,smoothwind] = expression_marker_plot(selected_pack, ...
                    app.cell_line_names, ...
                    selected_cell_lines, ...
                    selected_marker, ...
                    expression_channel, ...
                    "app",app, ...
                    "axis",app.axes_expression_marker, ...
                    params{:});

                app.expression_marker_panel.Title = strcat("Largest smoothing window size: ",smoothwind);

            end
            close(progressbar)
        end

        function app = settings_app_update(app,parameter_set_name,new_parameter_set,title)
            app.(parameter_set_name) = new_parameter_set;

            switch title
                case "Absolute filter plot settings"
                    if isequal(app.app_parameters.live_update_plots,"Always")
                        update_absolute_filter_plot(app);
                    end
                case "Expression marker plot settings"
                    if isequal(app.app_parameters.live_update_plots,"Always")
                        update_expression_marker_plot(app);
                    end
                case "3D to 2D fit settings"
                    if isequal(app.app_parameters.live_update_plots,"Always")
                        update_3D_scatter_on_2D_plot(app);
                    end
                case "tSNE settings"

                case "1D Plot settings"
                    update_nd_plot(app)
                case "2D Plot settings"
                    update_nd_plot(app)
                case "App Settings"
                    audio_sample_list = ["light-saber.mp3","disco-version.mp3","ibuttons-sw-cantina-mp3cut.mp3","senator-palpatine-do-it_1_Q5DTNvC.mp3","star-wars-john-williams-duel-of-the-fates-rojo.mp3","tie-fighter-fire-1.mp3","tiro-de-plaster.mp3","yoda-stillmuch-to-learn.mp3","lightsaber-hummm.mp3","ankara-messi-best-sound.mp3","titan-tv-man-cinemaman-red-screen.mp3","michael-jackson-hee-hee.mp3"];
                    random_sound_fx(app,audio_sample_list)
            end
        end

        function app = close_settings(app,title,button_dad)
            try
                button_dad.Value = 0;
                button_dad.Enable = "on";
            end

            switch title
                case "Expression marker plot settings"
                    app.MoreSettings_expression_marker.Value = 0;
                    app.MoreSettings_expression_marker.Enable = "on";
                case "3D to 2D fit settings"
                    app.MoreSettingsButton_3D_fit_on_2D.Value = 0;
                    app.MoreSettingsButton_3D_fit_on_2D.Enable = "on";
                case "tSNE settings"
                    app.MoreSettings_tsne.Value = 0;
                    app.MoreSettings_tsne.Enable = "on";
                case "UMAP settings"
                    app.MoreSettings_umap.Value = 0;
                    app.MoreSettings_umap.Enable = "on";
            end

            % Close more settings sound
            audio_sample_list = ["slap.mp3","michael-jackson-hee-hee.mp3"];
            random_sound_fx(app,audio_sample_list)
        end

        function update_3D_scatter_on_2D_plot(app)
            progressbar = uiprogressdlg(app.UIFigure,"Title","Progress bar...","Message","Updating plots...","Indeterminate","on");

            %% Conditions
            condition1 = numel(app.marker_list)>0; %make sure there are identified markers
            condition2 = numel(app.dataset_list)>0; %make sure there is data loaded in still

            %% initiate
            if condition1&&condition2
                app.exist_3d_on_2d_fit = 1;
                %refresh plot
                app.tiles_3D_scatter_on_2D_plot = tiledlayout(app.Panel_3D_scatter_on_2D_plot,1,1,"TileSpacing","tight","Padding","tight");
                app.axes_3D_scatter_on_2D_plot = nexttile(app.tiles_3D_scatter_on_2D_plot,1);
                reset(app.axes_3D_scatter_on_2D_plot)
                cla(app.axes_3D_scatter_on_2D_plot)
                app.axes_3D_scatter_on_2D_plot.XLimMode = "auto";
                grid(app.axes_3D_scatter_on_2D_plot,"on")

                %do plot
                selected_dataset = app.Dataset_pick_3D_scatter_on_2d.Value;
                selected_pack = app.datasets_dictionary_processed(selected_dataset);
                selected_cell_lines = string(app.Scatter_3d_fit_on_2d_variant_select_box.Value);

                xvar = string(app.Xaxismarker_3d_scatter_on_2d.Value);
                yvar = string(app.Yaxismarker_3d_scatter_on_2d.Value);
                zvar = string(app.Zaxismarker_3d_scatter_on_2d.Value);

                params = app.scatter_3d_on_2d_parameters;
                fields = fieldnames(params);
                params = rmfield(params, fields(structfun(@isempty, params)));
                params = namedargs2cell(params);

                scatter_plot_2d_with_3d_fit(selected_pack,app.cell_line_names,selected_cell_lines,xvar,yvar,zvar, ...
                    'axis',app.axes_3D_scatter_on_2D_plot, ...
                    params{:});

            end
            close(progressbar)

        end

        function runAGNES(app)

            try
                %% Get the imported worksheet
                rawCells = app.agnes_master_table;
        
                if isempty(rawCells) || ~iscell(rawCells)
                    uialert(app.UIFigure, ...
                        ['No valid Excel data is loaded. Select an Excel ' ...
                         'worksheet before running AGNES.'], ...
                        'Missing AGNES Data');
                    return;
                end
        
                if size(rawCells, 1) < 2 || size(rawCells, 2) < 2
                    uialert(app.UIFigure, ...
                        ['The worksheet must contain a header row, a Y-label ' ...
                         'column, and numeric data.'], ...
                        'Invalid Worksheet');
                    return;
                end
        
                %% Read axis labels
                %
                % First row, columns 2:end:
                %     X-axis labels
                %
                % First column, rows 2:end:
                %     Y-axis labels
        
                xLabels = strtrim(string(rawCells(1, 2:end)));
                yLabels = strtrim(string(rawCells(2:end, 1)));
        
                xLabels = xLabels(:).';
                yLabels = yLabels(:);
        
                %% Validate labels
                invalidXLabels = ...
                    ismissing(xLabels) | ...
                    strlength(xLabels) == 0;
        
                invalidYLabels = ...
                    ismissing(yLabels) | ...
                    strlength(yLabels) == 0;
        
                if any(invalidXLabels)
                    uialert(app.UIFigure, ...
                        'The first worksheet row contains empty X-axis labels.', ...
                        'Invalid X Labels');
                    return;
                end
        
                if any(invalidYLabels)
                    uialert(app.UIFigure, ...
                        'The first worksheet column contains empty Y-axis labels.', ...
                        'Invalid Y Labels');
                    return;
                end
        
                %% Convert the data area into a numeric matrix
                dataCells = rawCells(2:end, 2:end);
        
                numberOfDataRows = size(dataCells, 1);
                numberOfDataColumns = size(dataCells, 2);
        
                data = nan(numberOfDataRows, numberOfDataColumns);
        
                for rowIndex = 1:numberOfDataRows
        
                    for columnIndex = 1:numberOfDataColumns
        
                        currentValue = ...
                            dataCells{rowIndex, columnIndex};
        
                        if isnumeric(currentValue) && isscalar(currentValue)
        
                            data(rowIndex, columnIndex) = ...
                                double(currentValue);
        
                        elseif islogical(currentValue) && isscalar(currentValue)
        
                            data(rowIndex, columnIndex) = ...
                                double(currentValue);
        
                        elseif ischar(currentValue)
        
                            data(rowIndex, columnIndex) = ...
                                str2double(currentValue);
        
                        elseif isstring(currentValue) && isscalar(currentValue)
        
                            data(rowIndex, columnIndex) = ...
                                str2double(currentValue);
        
                        else
                            data(rowIndex, columnIndex) = NaN;
                        end
                    end
                end
        
                %% Validate dimensions
                if numel(yLabels) ~= size(data, 1)
                    uialert(app.UIFigure, ...
                        sprintf(['The worksheet has %d data rows but %d ' ...
                                 'Y-axis labels.'], ...
                                 size(data, 1), numel(yLabels)), ...
                        'Y Label Mismatch');
                    return;
                end
        
                if numel(xLabels) ~= size(data, 2)
                    uialert(app.UIFigure, ...
                        sprintf(['The worksheet has %d data columns but %d ' ...
                                 'X-axis labels.'], ...
                                 size(data, 2), numel(xLabels)), ...
                        'X Label Mismatch');
                    return;
                end
        
                %% Find unique labels
                uniqueXLabels = unique(xLabels, 'stable');
                uniqueYLabels = unique(yLabels, 'stable');
        
                numberOfUniqueXLabels = numel(uniqueXLabels);
                numberOfUniqueYLabels = numel(uniqueYLabels);
        
                %% Average duplicate labels on both axes
                %
                % Each output value is the average of all cells having the
                % corresponding X-label and Y-label combination.
        
                groupedData = nan( ...
                    numberOfUniqueYLabels, ...
                    numberOfUniqueXLabels);
        
                for yIndex = 1:numberOfUniqueYLabels
        
                    matchingRows = ...
                        yLabels == uniqueYLabels(yIndex);
        
                    for xIndex = 1:numberOfUniqueXLabels
        
                        matchingColumns = ...
                            xLabels == uniqueXLabels(xIndex);
        
                        matchingValues = data( ...
                            matchingRows, ...
                            matchingColumns);
        
                        matchingValues = matchingValues(:);
        
                        groupedData(yIndex, xIndex) = mean( ...
                            matchingValues, ...
                            'omitnan');
                    end
                end
        
                %% Validate grouped data
                if any(~isfinite(groupedData), 'all')
                    uialert(app.UIFigure, ...
                        ['At least one X/Y label combination contains no ' ...
                         'valid numeric measurements.'], ...
                        'Invalid Grouped Data');
                    return;
                end
        
                %% Y-axis AGNES
                %
                % Observations:
                %     Unique Y labels
                %
                % Features:
                %     Unique X labels
        
                if numberOfUniqueYLabels > 1
        
                    yClusteringData = zscore( ...
                        groupedData, ...
                        0, ...
                        1);
        
                    % Constant features produce NaN after zscore
                    yClusteringData(~isfinite(yClusteringData)) = 0;
        
                    % Condensed pairwise-distance vector
                    yDistances = pdist( ...
                        yClusteringData, ...
                        'euclidean');
        
                    % Full square pairwise-distance matrix
                    yDistanceMatrix = squareform(yDistances);
        
                    % Derived similarity matrix
                    ySimilarityMatrix = ...
                        1 ./ (1 + yDistanceMatrix);
        
                    % AGNES linkage tree
                    yClusterTree = linkage( ...
                        yDistances, ...
                        'ward');
        
                    % Optimal dendrogram leaf order
                    yOrder = optimalleaforder( ...
                        yClusterTree, ...
                        yDistances);
        
                else
                    yDistances = [];
                    yDistanceMatrix = 0;
                    ySimilarityMatrix = 1;
                    yClusterTree = [];
                    yOrder = 1;
                end
        
                %% X-axis AGNES
                %
                % Transpose groupedData so every unique X label becomes an
                % observation.
        
                if numberOfUniqueXLabels > 1
        
                    xClusteringData = groupedData';
        
                    xClusteringData = zscore( ...
                        xClusteringData, ...
                        0, ...
                        1);
        
                    % Constant features produce NaN after zscore
                    xClusteringData(~isfinite(xClusteringData)) = 0;
        
                    % Condensed pairwise-distance vector
                    xDistances = pdist( ...
                        xClusteringData, ...
                        'euclidean');
        
                    % Full square pairwise-distance matrix
                    xDistanceMatrix = squareform(xDistances);
        
                    % Derived similarity matrix
                    xSimilarityMatrix = ...
                        1 ./ (1 + xDistanceMatrix);
        
                    % AGNES linkage tree
                    xClusterTree = linkage( ...
                        xDistances, ...
                        'ward');
        
                    % Optimal dendrogram leaf order
                    xOrder = optimalleaforder( ...
                        xClusterTree, ...
                        xDistances);
        
                else
                    xDistances = [];
                    xDistanceMatrix = 0;
                    xSimilarityMatrix = 1;
                    xClusterTree = [];
                    xOrder = 1;
                end
        
                %% Clear old panel contents
                delete(app.Panel_AGNES_xaxis.Children);
                delete(app.Panel_AGNES_yaxis.Children);
        
                app.Panel_AGNES_xaxis.Title = '';
                app.Panel_AGNES_yaxis.Title = '';
        
                %% Create axes in the X panel
                xAxes = uiaxes(app.Panel_AGNES_xaxis);
        
                xAxes.Units = 'normalized';
                xAxes.Position = [0.08 0.18 0.88 0.75];
        
                %% Draw X clustering tree
                plotAGNESLinkageTree( ...
                    app, ...
                    xAxes, ...
                    xClusterTree, ...
                    uniqueXLabels, ...
                    xOrder, ...
                    'top');
        
                title(xAxes, ...
                    'AGNES — Unique X Labels');
        
                xlabel(xAxes, ...
                    'Unique X Labels');
        
                ylabel(xAxes, ...
                    'Linkage Distance');
        
                xAxes.XTickLabelRotation = 45;
        
                %% Create axes in the Y panel
                yAxes = uiaxes(app.Panel_AGNES_yaxis);
        
                yAxes.Units = 'normalized';
                yAxes.Position = [0.10 0.10 0.84 0.84];
        
                %% Draw Y clustering tree
                plotAGNESLinkageTree( ...
                    app, ...
                    yAxes, ...
                    yClusterTree, ...
                    uniqueYLabels, ...
                    yOrder, ...
                    'left');
        
                title(yAxes, ...
                    'AGNES — Unique Y Labels');
        
                xlabel(yAxes, ...
                    'Linkage Distance');
        
                ylabel(yAxes, ...
                    'Unique Y Labels');
        
                %% Store all results in the app
                app.AGNESUniqueXLabels = uniqueXLabels;
                app.AGNESUniqueYLabels = uniqueYLabels;
                app.AGNESGroupedData = groupedData;
        
                app.XClusterTree = xClusterTree;
                app.YClusterTree = yClusterTree;
        
                app.ClusteredColumnOrder = xOrder;
                app.ClusteredRowOrder = yOrder;
        
                app.AGNESXDistanceMatrix = xDistanceMatrix;
                app.AGNESYDistanceMatrix = yDistanceMatrix;
        
                app.AGNESXSimilarityMatrix = xSimilarityMatrix;
                app.AGNESYSimilarityMatrix = ySimilarityMatrix;
        
                drawnow;
        
            catch ME
                uialert(app.UIFigure, ...
                    ME.message, ...
                    'AGNES Error');
            end
        
        end
        function plotAGNESLinkageTree( ...
        app, axesHandle, clusterTree, labels, leafOrder, orientation)

            %#ok<INUSD>
            % app is present because this is an App Designer private method.
        
            labels = string(labels(:));
            leafOrder = leafOrder(:).';
        
            numberOfLeaves = numel(labels);
        
            %% Reset the axes
            cla(axesHandle);
            hold(axesHandle, 'on');
        
            axesHandle.Box = 'on';
            axesHandle.XGrid = 'off';
            axesHandle.YGrid = 'off';
        
            %% Handle one unique label
            if numberOfLeaves == 1
        
                if strcmpi(orientation, 'top')
        
                    axesHandle.XLim = [0.5 1.5];
                    axesHandle.YLim = [0 1];
        
                    axesHandle.XTick = 1;
                    axesHandle.XTickLabel = cellstr(labels);
        
                    axesHandle.YTick = [];
        
                    text(axesHandle, ...
                        1, ...
                        0.5, ...
                        'One unique label', ...
                        'HorizontalAlignment', 'center', ...
                        'VerticalAlignment', 'middle');
        
                elseif strcmpi(orientation, 'left')
        
                    axesHandle.XLim = [0 1];
                    axesHandle.YLim = [0.5 1.5];
        
                    axesHandle.XTick = [];
                    axesHandle.YTick = 1;
                    axesHandle.YTickLabel = cellstr(labels);
        
                    axesHandle.YDir = 'reverse';
                    axesHandle.YAxisLocation = 'right';
        
                    text(axesHandle, ...
                        0.5, ...
                        1, ...
                        'One unique label', ...
                        'HorizontalAlignment', 'center', ...
                        'VerticalAlignment', 'middle');
        
                else
                    error('Tree orientation must be top or left.');
                end
        
                hold(axesHandle, 'off');
                return;
            end
        
            %% Validate the linkage tree
            expectedTreeRows = numberOfLeaves - 1;
        
            if isempty(clusterTree) || ...
                    size(clusterTree, 1) ~= expectedTreeRows || ...
                    size(clusterTree, 2) < 3
        
                error(['The linkage tree does not match the number of ' ...
                       'unique labels.']);
            end
        
            %% Validate leaf order
            if numel(leafOrder) ~= numberOfLeaves || ...
                    ~isequal(sort(leafOrder), 1:numberOfLeaves)
        
                error('The AGNES leaf order is invalid.');
            end
        
            %% Allocate coordinates for all nodes
            %
            % For N original labels, linkage identifies:
            %
            % 1 to N:
            %     Original leaves
            %
            % N+1 to 2N-1:
            %     Newly merged clusters
        
            numberOfNodes = (2 * numberOfLeaves) - 1;
        
            nodePosition = nan(numberOfNodes, 1);
            nodeHeight = zeros(numberOfNodes, 1);
        
            % Assign leaf positions using the optimized ordering
            nodePosition(leafOrder) = 1:numberOfLeaves;
        
            % Use the default axes line color
            treeColor = axesHandle.ColorOrder(1, :);
        
            %% Draw every merge in the linkage tree
            for mergeIndex = 1:(numberOfLeaves - 1)
        
                firstNode = round(clusterTree(mergeIndex, 1));
                secondNode = round(clusterTree(mergeIndex, 2));
        
                mergeHeight = clusterTree(mergeIndex, 3);
        
                firstPosition = nodePosition(firstNode);
                secondPosition = nodePosition(secondNode);
        
                firstHeight = nodeHeight(firstNode);
                secondHeight = nodeHeight(secondNode);
        
                if any(~isfinite([ ...
                        firstPosition, ...
                        secondPosition, ...
                        firstHeight, ...
                        secondHeight, ...
                        mergeHeight]))
        
                    error('The linkage tree contains invalid values.');
                end
        
                if strcmpi(orientation, 'top')
        
                    % First vertical branch
                    line(axesHandle, ...
                        [firstPosition firstPosition], ...
                        [firstHeight mergeHeight], ...
                        'Color', treeColor, ...
                        'LineWidth', 1.5);
        
                    % Second vertical branch
                    line(axesHandle, ...
                        [secondPosition secondPosition], ...
                        [secondHeight mergeHeight], ...
                        'Color', treeColor, ...
                        'LineWidth', 1.5);
        
                    % Horizontal merge connection
                    line(axesHandle, ...
                        [firstPosition secondPosition], ...
                        [mergeHeight mergeHeight], ...
                        'Color', treeColor, ...
                        'LineWidth', 1.5);
        
                elseif strcmpi(orientation, 'left')
        
                    % First horizontal branch
                    line(axesHandle, ...
                        [firstHeight mergeHeight], ...
                        [firstPosition firstPosition], ...
                        'Color', treeColor, ...
                        'LineWidth', 1.5);
        
                    % Second horizontal branch
                    line(axesHandle, ...
                        [secondHeight mergeHeight], ...
                        [secondPosition secondPosition], ...
                        'Color', treeColor, ...
                        'LineWidth', 1.5);
        
                    % Vertical merge connection
                    line(axesHandle, ...
                        [mergeHeight mergeHeight], ...
                        [firstPosition secondPosition], ...
                        'Color', treeColor, ...
                        'LineWidth', 1.5);
        
                else
                    error('Tree orientation must be top or left.');
                end
        
                %% Store the newly merged node
                newNode = numberOfLeaves + mergeIndex;
        
                nodePosition(newNode) = ...
                    (firstPosition + secondPosition) / 2;
        
                nodeHeight(newNode) = mergeHeight;
            end
        
            %% Determine the distance-axis range
            maximumHeight = max(clusterTree(:, 3));
        
            if ~isfinite(maximumHeight) || maximumHeight <= 0
                maximumHeight = 1;
            end
        
            distanceLimit = maximumHeight * 1.08;
        
            %% Format X-axis tree
            if strcmpi(orientation, 'top')
        
                axesHandle.XLim = ...
                    [0.5 numberOfLeaves + 0.5];
        
                axesHandle.YLim = ...
                    [0 distanceLimit];
        
                axesHandle.XTick = ...
                    1:numberOfLeaves;
        
                axesHandle.XTickLabel = ...
                    cellstr(labels(leafOrder));
        
                axesHandle.YDir = 'normal';
                axesHandle.XDir = 'normal';
        
            %% Format Y-axis tree
            elseif strcmpi(orientation, 'left')
        
                axesHandle.XLim = ...
                    [0 distanceLimit];
        
                axesHandle.YLim = ...
                    [0.5 numberOfLeaves + 0.5];
        
                axesHandle.YTick = ...
                    1:numberOfLeaves;
        
                axesHandle.YTickLabel = ...
                    cellstr(labels(leafOrder));
        
                % First ordered label appears at the top
                axesHandle.YDir = 'reverse';
        
                % Leaves appear on the right, tree extends toward the left
                axesHandle.XDir = 'reverse';
        
                % Display unique Y labels on the right-hand side
                axesHandle.YAxisLocation = 'right';
            end
        
            hold(axesHandle, 'off');
        
        end
        function start_tsne(app)
            progressbar = uiprogressdlg(app.UIFigure,"Title","Progress bar...","Message","Initialising tSNE...","Indeterminate","on");

            %% Conditions
            condition1 = numel(app.marker_list)>0; %make sure there are identified markers
            condition2 = numel(app.dataset_list)>0; %make sure there is data loaded in still
            condition3 = numel(app.marker_select_box_tsne.Value)>1;

            %% initiate
            if condition1&&condition2&&condition3

                %do plot
                selected_dataset = app.dataset_pick_tsne.Value;
                selected_pack = app.datasets_dictionary_processed(selected_dataset);

                selected_cell_lines = string(app.variant_select_box_tsne.Value);

                selected_markers = string(app.marker_select_box_tsne.Value)';

                params = app.tSNE_parameters;
                fields = fieldnames(params);
                params = rmfield(params, fields(structfun(@isempty, params)));
                params = namedargs2cell(params);

                close(progressbar)

                app.InspectSelectionButton_tsne.Enable = "off";
                app.InspectSelectionButton_tsne.Value = 0;
                app.InspectSelectionButton_tsne.Text = "";

                app.StoptSNEButton.Enable = "on";

                %                 [y, Fs] = audioread('this-is-the-way-from-mandalorian.mp3');
                %                 sound(y,Fs);

                [app.tsne_output,~,app.tsne_plot_tiles,~,app.tsne_master_table] = do_tsne(selected_pack,selected_cell_lines,selected_markers, ...
                    "app",app, ...
                    "panel",app.Panel_tsne, ...
                    "stop_button",app.StoptSNEButton, ...
                    "pause_button",app.PauseButton_tsne, ...
                    "inspect_poly",app.InspectSelectionButton_tsne, ...
                    "tsne_lamp", app.tsne_lamp, ...
                    "tsne_lamp_label", app.tsne_lamp_label, ...
                    params{:});

                app.StoptSNEButton.Enable = "off";

            end


        end
        
        function start_biexp(app)
            progressbar = uiprogressdlg(app.UIFigure, ...
                "Title", "Progress bar...", ...
                "Message", "Initialising Biexponential Plot...", ...
                "Indeterminate", "on");
        
            %% Conditions
            condition1 = numel(app.marker_list) > 0;
            condition2 = numel(app.dataset_list) > 0;
            condition3 = numel(app.marker_select_box_biexp.Value) == 1;
            condition4 = numel(app.marker_select_box_biexp2.Value) == 1;
        
            %% Validation
            if ~condition3 || ~condition4
                close(progressbar);
                errordlg("Please select exactly ONE marker for both X and Y axes.", "Marker Selection Error");
                return;
            end
        
            %% Initiate
            if condition1 && condition2 && condition3 && condition4
        
                % Select data
                selected_dataset = app.dataset_pick_biexp.Value;
                selected_pack = app.datasets_dictionary_processed(selected_dataset);
                selected_cell_lines = string(app.variant_select_box_biexp.Value);
                selected_markers_x = string(app.marker_select_box_biexp.Value)';
                selected_markers_y = string(app.marker_select_box_biexp2.Value)';
                selected_markers = [selected_markers_x, selected_markers_y];
        
                close(progressbar);
        
                %% Do Biexp
                [app.biexp_output, app.biexp_plot_tiles, ~, app.biexp_master_table] = do_biexponential( ...
                    selected_pack, selected_cell_lines, selected_markers, ...
                    numel(selected_markers), ...
                    "stop_button", app.StopBiexponentialPlotButton, ...
                    "panel", app.Panel_biexp);
            end
        end


        function start_PCA(app)
            progressbar = uiprogressdlg(app.UIFigure, ...
            "Title", "Progress bar...", ...
            "Message", "Initialising PCA...", ...
            "Indeterminate", "on");
        
            %% Conditions
            condition1 = numel(app.marker_list) > 0;
            condition2 = numel(app.dataset_list) > 0;
            condition3 = numel(app.marker_select_box_PCA.Value) > 1;
            
            %% Initiate
            if condition1 && condition2 && condition3
            
                % Select data
                selected_dataset = app.dataset_pick_PCA.Value;
                selected_pack = app.datasets_dictionary_processed(selected_dataset);
                selected_cell_lines = string(app.variant_select_box_PCA.Value);
                selected_markers = string(app.marker_select_box_PCA.Value)';


                close(progressbar)

                %% Do PCA

                [app.PCA_output,~,app.PCA_plot_tiles,~,app.PCA_master_table] = do_pca(selected_pack,selected_cell_lines,selected_markers, ....
                    app.PCADimensionOptionDropDown.Value,"stop_button",app.StopPCAButton,"panel",app.Panel_PCA);

                app.StopUMAPButton.Enable = 0;
           
            end
        end


        function start_umap(app)
            progressbar = uiprogressdlg(app.UIFigure,"Title","Progress bar...","Message","Initialising UMAP...","Indeterminate","on");

            %% Conditions
            condition1 = numel(app.marker_list)>0; %make sure there are identified markers
            condition2 = numel(app.dataset_list)>0; %make sure there is data loaded in still
            condition3 = numel(app.marker_select_box_umap.Value)>1;

            %% initiate
            if condition1&&condition2&&condition3

                %do plot
                selected_dataset = app.dataset_pick_umap.Value;
                selected_pack = app.datasets_dictionary_processed(selected_dataset);

                selected_cell_lines = string(app.variant_select_box_umap.Value);

                selected_markers = string(app.marker_select_box_umap.Value)';

                %% Setup params

                params = app.umap_parameters;
                fields = fieldnames(params);
                params = rmfield(params, fields(structfun(@isempty, params)));
                params = namedargs2cell(params);

                close(progressbar)

                %% Do umap

                [app.umap_output,~,app.umap_plot_tiles,~,app.umap_master_table] = do_umap(selected_pack,selected_cell_lines,selected_markers, ...
                    "app",app, ...
                    "panel",app.Panel_umap, ...
                    "stop_button",app.StopUMAPButton, ...
                    "pause_button",app.PauseButton_umap, ...
                    "inspect_selection",app.InspectSelection_umap, ...
                    "status_lamp", app.umap_lamp, ...
                    "status_lamp_label", app.umap_lamp_label, ...
                    params{:});

                app.InspectSelection_umap.Enable = 1;
                app.InspectSelection_umap.Value = 0;

                app.StopUMAPButton.Enable = 0;
                app.PauseButton_umap.Enable = 0;
            end
        end

        function random_sound_fx(app,audio_sample_list)
            if app.app_parameters.enable_force

                try
                    n_audios = numel(audio_sample_list);
                    [y, Fs] = audioread(audio_sample_list(ceil(rand*n_audios)));
                    sound(y,Fs);
                end

            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %Be able to use all functions in app directory
%                         addpath(genpath(cd));

            [~,~,~] = mkdir(fullfile(cd,"Input Data"));
            [~,~,~] = mkdir(fullfile(cd,"Temp"));
            [~,~,~] = mkdir(fullfile(cd,"Output Data"));

            progbar = uiprogressdlg(app.UIFigure,"Indeterminate","on","Message",'Loading in input data...');

            app.datasets_dictionary_original = import_data_sets(app.file_types_supported,app.inputdata_dir,app.datasets_dictionary_original,"app",app,"startup",1);

            app.datasets_dictionary_processed = app.datasets_dictionary_original;

            close(progbar)

            datalist_update(app)

            %% Filter plot
            app.Filter_by_channel_plot.Visible = "off";
            app.correlation_tiles = tiledlayout(app.panel_variant_marker_correlation,1,1);

            %% Process and save data
            app.OutputdatadirectoryEditField.Value = app.outputdata_dir;
            app.OutputplotsdirectoryEditField.Value = app.outputplot_dir;
            app.DatafiletypeDropDown.Items = app.file_types_supported;
            app.DatafiletypeDropDown.Value = ".csv";

            if isfile(fullfile(cd,"Temp","app_settings.mat"))
                temp_param = load(app.app_settings_dir,'-mat',"app_parameters");
                app.app_parameters = temp_param.app_parameters;
            end

            % open app sound, silenced for now
            % audio_sample_list = ["hello-adele.mp3","obi-wan-hello-there.mp3","titan-tv-man-cinemaman-red-screen.mp3","and-its-arsenal.mp3"];
            % random_sound_fx(app,audio_sample_list)

            % if app.app_parameters.enable_force
            %     [y, Fs] = audioread('obi-wan-hello-there.mp3');
            %     sound(y,0.8*Fs);
            % end

            %% App settings
            app_parameters_fields = string(fieldnames(app.app_parameters))';

            app.app_input_meta = dictionary(app_parameters_fields, ...
                {...
                ... App Settings
                struct("type","heading","label","App Settings"), ...
                struct("type","dropdown","label","Update plots live?"), ...
                struct("type","checkbox","label","Collate cell lines of the same name"), ...
                struct("type","checkbox","label","May the force be with you")});

            app.app_input_settings = dictionary(app_parameters_fields, ...
                {...
                ...| app_settings | App Settings
                struct(), ..., ...
                ... live_update_plots | Update plots live?
                struct("Items",{'Always','Simple plots only'}), ...
                ... collate_repeat_cell_line_names | Collate cell lines of the same name
                struct("Text",""), ...
                ... enable_force | May the force be with you
                struct("Text","")});


            %% Setup Settings for pop out options
            %useful plot variables
            mkrs = string({'*','o','^','.','+','x','s','p','h'});

            %% More settings variables
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% ND $$$$$$$$$%$$$$$$$$%%%%%%%%
            d1_parameters_fields = string(fieldnames(app.d1_parameters))';

            app.d1_input_meta = dictionary(d1_parameters_fields, ...
                {...
                ... 1D Plot Settings
                struct("type","heading","label","1D Plot Settings"), ...
                struct("type","checkbox","label","Show histogram"), ...
                struct("type","checkbox","label","Show histogram edge line"), ...
                struct("type","checkbox","label","Show legend"), ...
                ...  Histogram Settings
                struct("type","heading","label"," Histogram Settings"), ...
                struct("type","checkbox","label","Use manual number of bins"), ...
                struct("type","spinner","label","-- Number of bins"), ...
                struct("type","checkbox","label","Use manual bin width (overrides number of bins)"), ...
                struct("type","numeric","label","-- Bin widths"), ...
                struct("type","dropdown","label","Bar size representation"), ...
                struct("type","dropdown","label","Bar display style"), ...
                struct("type","numeric","label","Transparency of bars"), ...
                struct("type","dropdown","label","Orientation")});

            app.d1_input_settings = dictionary(d1_parameters_fields, ...
                {...
                ...| plot_settings_1d | 1D Plot Settings
                struct(), ..., ...
                ... do_histogram | Show histogram
                struct("Text",""), ...
                ... do_histogram_edge_line | Show histogram edge line
                struct("Text",""), ...
                ... do_1d_legend | Show legend
                struct("Text",""), ...
                ...| hist_settings |  Histogram Settings
                struct(), ..., ...
                ... specify_bins_1d | Use manual number of bins
                struct("Text",""), ...
                ... num_bins_1d | -- Number of bins
                struct("Limits",[0,Inf],"LowerLimitInclusive","off","RoundFractionalValues","on"), ...
                ... specify_bin_width_1d | Use manual bin width (overrides number of bins)
                struct("Text",""), ...
                ... bin_width_1d | -- Bin widths
                struct("Limits",[0,inf],"LowerLimitInclusive","off","UpperLimitInclusive","on"), ...
                ... Normalization_1d | Bar size representation
                struct("Items",string({'count','probability','countdensity','pdf','cumcount','cdf'})), ...
                ... display_style_1d | Bar display style
                struct("Items",string({'bar','stairs'})), ...
                ... face_alpha_1d | Transparency of bars
                struct("Limits",[0,1],"LowerLimitInclusive","on","UpperLimitInclusive","on"), ...
                ... orientation_1d | Orientation
                struct("Items",string({'vertical','horizontal'}))});

            d2_parameters_fields = string(fieldnames(app.d2_parameters))';

            app.d2_input_meta = dictionary(d2_parameters_fields, ...
                {...
                ... 2D Plot Settings
                struct("type","heading","label","2D Plot Settings"), ...
                struct("type","checkbox","label","Show scatterplot"), ...
                struct("type","checkbox","label","Show legend"), ...
                struct("type","dropdown","label","Plot type"), ...
                ... Scatter Plot settings
                struct("type","heading","label","Scatter Plot settings"), ...
                struct("type","dropdown","label","Marker type"), ...
                ... Bin Scatter Settings
                struct("type","heading","label","Bin Scatter Settings"), ...
                struct("type","checkbox","label","Use manual number of bins"), ...
                struct("type","spinner","label","-- Number of bins"), ...
                struct("type","checkbox","label","Use manual bin width (overrides number of bins)"), ...
                struct("type","spinner","label","-- Bin widths"), ...
                ... 3D Histogram Settings
                struct("type","heading","label","3D Histogram Settings"), ...
                struct("type","checkbox","label","Use manual number of bins"), ...
                struct("type","spinner","label","-- Number of bins"), ...
                struct("type","checkbox","label","Use manual bin width (overrides number of bins)"), ...
                struct("type","spinner","label","-- Bin widths") ...
                ... Bin Bar Plot Settings
                struct("type","heading","label","Bin Bar Plot Settings"), ...
                struct("type","spinner","label","Number of bins"), ...
                struct("type","dropdown","label","Bin method"), ...
                struct("type","button","label","Save mean and median for selected group")});

            app.d2_input_settings = dictionary(d2_parameters_fields, ...
                {...
                ...| plot_settings | 2D Plot Settings
                struct(), ..., ...
                ... do_scatter_2d | Show scatterplot
                struct("Text",""), ...
                ... do_legend_2d | Show legend
                struct("Text",""), ...
                ... type_2d | Plot type
                struct("Items",string({'scatter','bin scatter','3d histogram','density scatter','bin bars'})), ...
                ...| scatter_plot_settings | Scatter Plot settings
                struct(), ..., ...
                ... markertype_2d | Marker type
                struct("Items",mkrs), ...
                ...| bin_scatter_settings | Bin Scatter Settings
                struct(), ..., ...
                ... do_bins_2d | Use manual number of bins
                struct("Text",""), ...
                ... num_bins_2d | -- Number of bins
                struct("Limits",[0,250],"LowerLimitInclusive","off","RoundFractionalValues","on"), ...
                ... do_bin_width_2d | Use manual bin width (overrides number of bins)
                struct("Text",""), ...
                ... binwidth_2d | -- Bin widths
                struct("Limits",[0,250],"LowerLimitInclusive","off","RoundFractionalValues","on"), ...
                ...| histogram_3d_settings | 3D Histogram Settings
                struct(), ..., ...
                ... do_histogram_bins_2d | Use manual number of bins
                struct("Text",""), ...
                ... num_histogram_bins_2d | -- Number of bins
                struct("Limits",[0,inf],"LowerLimitInclusive","off","RoundFractionalValues","on"), ...
                ... do_histogram_bin_width_2d | Use manual bin width (overrides number of bins)
                struct("Text",""), ...
                ... histogram_bin_width_2d | -- Bin widths
                struct("Limits",[0,inf],"LowerLimitInclusive","off","RoundFractionalValues","on") ...
                ...| bin_bar_settings_title | Bin Bar Plot Settings
                struct(), ...
                ... binbar_nbins_2d | Number of bins
                struct("Limits",[0,250],"LowerLimitInclusive","off","RoundFractionalValues","on"), ...
                ... binbar_bin_method_2d | Bin method
                struct("Items",string({'linear','quantile'})), ...
                ... saving selected marker's mean and median
                struct("Text","Yes",'ButtonPushedFcn', @(~,~) generate_mean_median(app))});


            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% V-C plot matrix %%%%%%%%%%%%%%%%%%%%%%%%%%%%

            vc_parameters_fields = string(fieldnames(app.vc_parameters))';

            app.vc_input_meta = dictionary(vc_parameters_fields, ...
                {...
                ... Variant-Channel Plot Settings
                struct("type","heading","label","Variant-Channel Plot Settings"), ...
                struct("type","checkbox","label","Show scatter"), ...
                struct("type","checkbox","label","Show linear fit"), ...
                struct("type","checkbox","label","Show detailed annotations"), ...
                struct("type","checkbox","label","Update plot matrix live"), ...
                ... Plot Settings
                struct("type","heading","label","Plot Settings"), ...
                struct("type","dropdown","label","Scatter plot type"), ...
                struct("type","dropdown","label","-- Marker type (for 'scatter')"), ...
                struct("type","checkbox","label","Use manual number of bins (for diagonal histogram)"), ...
                struct("type","numeric","label","-- Number of bins (for diagonal histogram)"), ...
                struct("type","checkbox","label","Use manual number of bins (for 'bin scatter')"), ...
                struct("type","numeric","label","-- Number of bins (for 'bin scatter')"), ...
                ... Correlation Settings
                struct("type","heading","label","Correlation Settings"), ...
                struct("type","dropdown","label","Correlation method")});

            app.vc_input_settings = dictionary(vc_parameters_fields, ...
                {...
                ...| plot_settings | Variant-Channel Plot Settings
                struct(), ..., ...
                ... do_scatter | Show scatter
                struct("Text",""), ...
                ... do_linear_fit | Show linear fit
                struct("Text",""), ...
                ... do_annotate_plots | Show detailed annotations
                struct("Text",""), ...
                ... do_drawnow | Update plot matrix live
                struct("Text",""), ...
                ...| sub_plot_settings | Plot Settings
                struct(), ..., ...
                ... type | Scatter plot type
                struct("Items",string({'scatter','bin scatter'})), ...
                ... marker_type | -- Marker type (for 'scatter')
                struct("Items",mkrs), ...
                ... do_histbins | manual number of histogram bins
                struct("Text",""), ...
                ... histbins | -- Number of bins (for diagonal histogram)
                struct("Limits",[0,Inf],"LowerLimitInclusive","off","UpperLimitInclusive","on","RoundFractionalValues","on"), ...
                ... do_2dscatterbins | manual number of scatter bins
                struct("Text",""), ...
                ... scatterbins | -- Number of bins (for 'bin scatter')
                struct("Limits",[0,250],"LowerLimitInclusive","off","UpperLimitInclusive","on"), ...
                ...| corr_settings | Correlation Settings
                struct(), ..., ...
                ... correlation_method | Correlation method
                struct("Items",string({'Pearson','Kendall','Spearman'}))});

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% corrtest
            cortests_parameters_fields = string(fieldnames(app.cortests_parameters))';

            app.cortests_input_meta = dictionary(cortests_parameters_fields, ...
                {...
                ... Plot Settings
                struct("type","heading","label","Plot Settings"), ...
                struct("type","dropdown","label","Plot type"), ...
                struct("type","checkbox","label","Do all marker combinations? (If no, then only combinations with the first selected marker will be computed)"), ...
                struct("type","checkbox","label","Show histogram"), ...
                struct("type","checkbox","label","-- Show histogram edge line"), ...
                struct("type","checkbox","label","-- Show histogram confidence interval"), ...
                struct("type","checkbox","label","Show legend"), ...
                ... Histogram Settings
                struct("type","heading","label","Histogram Settings"), ...
                struct("type","checkbox","label","Use manual number of bins"), ...
                struct("type","numeric","label","-- Number of bins"), ...
                struct("type","checkbox","label","Use manual bin width (overrides number of bins)"), ...
                struct("type","numeric","label","-- Bin widths"), ...
                struct("type","dropdown","label","Bar size representation"), ...
                struct("type","dropdown","label","Bar display style"), ...
                struct("type","numeric","label","Transparency of bars"), ...
                struct("type","dropdown","label","Orientation"), ...
                ... Box Chart Settings
                struct("type","heading","label","Box Chart Settings"), ...
                struct("type","checkbox","label","Show outliers"), ...
                struct("type","checkbox","label","Notch bar at median"), ...
                struct("type","checkbox","label","Jitter outliers"), ...
                ... Correlation Settings
                struct("type","heading","label","Correlation Settings"), ...
                struct("type","dropdown","label","Correlation method"), ...
                struct("type","checkbox","label","Use bootstrapping (on by default for 'percentile bootstrap' comparisons)"), ...
                struct("type","spinner","label","-- Number of repeated bootstrap samples"), ...
                struct("type","numeric","label","-- Bootstrap sample size (as a % of total sample data)"), ...
                struct("type","checkbox","label","-- Sample with replacement"), ...
                struct("type","numeric","label","-- Confidence interval % (on histogram plots)"), ...
                struct("type","dropdown","label","Correlation comparison grid method"), ...
                ... Normality Test Settings
                struct("type","heading","label","Normality Test Settings"), ...
                struct("type","numeric","label","Alpha % to reject null hypothesis")});

            app.cortests_input_settings = dictionary(cortests_parameters_fields, ...
                {...
                ...| plot_settings | Plot Settings
                struct(), ..., ...
                ... which_plot_type | Plot type
                struct("Items",{'Histogram','Correlation distribution','Correlation Comparison'}), ...
                ... do_all_marker_combinations | Do all marker combinations? (If no, then only combinations with the first selected marker will be computed)
                struct("Text",""), ...
                ... do_histogram | Show histogram
                struct("Text",""), ...
                ... do_histogram_edge_line | -- Show histogram edge line
                struct("Text",""), ...
                ... do_histogram_ci | -- Show histogram confidence interval
                struct("Text",""), ...
                ... do_legend | Show legend
                struct("Text",""), ...
                ...| hist_settings | Histogram Settings
                struct(), ..., ...
                ... specify_bins_1d | Use manual number of bins
                struct("Text",""), ...
                ... num_bins_1d | -- Number of bins
                struct("Limits",[0,Inf],"LowerLimitInclusive","off","UpperLimitInclusive","on","RoundFractionalValues","on"), ...
                ... specify_bin_width_1d | Use manual bin width (overrides number of bins)
                struct("Text",""), ...
                ... bin_width_1d | -- Bin widths
                struct("Limits",[0,Inf],"LowerLimitInclusive","off","UpperLimitInclusive","on"), ...
                ... Normalization_1d | Bar size representation
                struct("Items",{'count','probability','countdensity','pdf','cumcount','cdf'}), ...
                ... display_style_1d | Bar display style
                struct("Items",{'bar','stairs'}), ...
                ... face_alpha_1d | Transparency of bars
                struct("Limits",[0,1],"LowerLimitInclusive","off","UpperLimitInclusive","on"), ...
                ... orientation_1d | Orientation
                struct("Items",{'vertical','horizontal'}), ...
                ...| boxchart_settings | Box Chart Settings
                struct(), ..., ...
                ... MarkerStyle | Show outliers
                struct("Text",""), ...
                ... Notch | Notch bar at median
                struct("Text",""), ...
                ... JitterOutliers | Jitter outliers
                struct("Text",""), ...
                ...| corr_settings | Correlation Settings
                struct(), ..., ...
                ... correlation_method | Correlation method
                struct("Items",{'Pearson','Kendall','Spearman'}), ...
                ... do_bootstrap | Use bootstrapping (on by default for 'percentile bootstrap' comparisons)
                struct("Text",""), ...
                ... bootstrap_repeat_samples | -- Number of repeated bootstrap samples
                struct("Limits",[0,Inf],"LowerLimitInclusive","off","RoundFractionalValues","on"), ...
                ... bootstrap_sample_size | -- Bootstrap sample size (as a % of total sample data)
                struct("Limits",[0,100],"LowerLimitInclusive","off","UpperLimitInclusive","on"), ...
                ... bootstrap_replace | -- Sample with replacement
                struct("Text",""), ...
                ... histogram_ci_percent | -- Confidence interval % (on histogram plots)
                struct("Limits",[0,100],"LowerLimitInclusive","off","UpperLimitInclusive","off"), ...
                ... comparison_method | Correlation comparison grid method
                struct("Items",{'Percentile Bootstrap','Fisher-z'}), ...
                ...| norm_test_table_settings | Normality Test Settings
                struct(), ..., ...
                ... normality_alpha | Alpha % to reject null hypothesis
                struct("Limits",[0,100],"LowerLimitInclusive","off","UpperLimitInclusive","off")});

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% Expression Marker plot %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            expression_marker_parameters_fields = string(fieldnames(app.expression_marker_parameters))';

            app.expression_marker_input_meta = dictionary(expression_marker_parameters_fields, ...
                {...
                ... Display plots
                struct("type","heading","label","Display plots"), ...
                struct("type","checkbox","label","Show scatter"), ...
                struct("type","checkbox","label","Show smoothed data"), ...
                struct("type","checkbox","label","Show linear segmentation line"), ...
                struct("type","checkbox","label","Show linear segmentation fit"), ...
                struct("type","checkbox","label","Show confidence intervals"), ...
                struct("type","checkbox","label","Show legend"), ...
                ... Plot settings
                struct("type","heading","label","Plot settings"), ...
                struct("type","dropdown","label","Scatter plot type"), ...
                struct("type","dropdown","label","Scatter marker"), ...
                struct("type","numeric","label","Transparency"), ...
                ... Smooth data settings
                struct("type","heading","label","Smooth data settings"), ...
                struct("type","dropdown","label","Smooth method"), ...
                struct("type","checkbox","label","Use manual smooth span"), ...
                struct("type","spinner","label","Enter smooth span"), ...
                struct("type","numeric","label","Auto smooth span factor"), ...
                ... Segmentation settings
                struct("type","heading","label","Segmentation settings"), ...
                struct("type","checkbox","label","Segment data using smoothed fit"), ...
                struct("type","dropdown","label","Select method for segmenting data"), ...
                struct("type","spinner","label","Enter the maximum number of segments"), ...
                struct("type","checkbox","label","Calculate segmentations based off threshold (overrides maximum number of segments)"), ...
                struct("type","numeric","label","-- Enter the segment threshold detection level"), ...
                ... Fit settings
                struct("type","heading","label","Fit settings"), ...
                struct("type","checkbox","label","Fit on smoothed data"), ...
                struct("type","dropdown","label","Select fit method"), ...
                struct("type","numeric","label","Enter the fit prediction interval (between 0-1)"), ...
                struct("type","dropdown","label","-- Prediction bounds type"), ...
                struct("type","checkbox","label","Simultaneous bounds")});

            app.expression_marker_input_settings = dictionary(expression_marker_parameters_fields, ...
                {...
                ... Display plots
                struct(), ..., ...
                ... Show scatter
                struct("Text",""), ...
                ... Show smoothed data
                struct("Text",""), ...
                ... Show linear segmentation line
                struct("Text",""), ...
                ... Show linear segmentation fit
                struct("Text",""), ...
                ... Show confidence intervals
                struct("Text",""), ...
                ... Show legend
                struct("Text",""), ...
                ... Plot settings
                struct(), ..., ...
                ... Scatter plot type
                struct("Items",string({'scatter','bin scatter'})), ...
                ... Scatter marker
                struct("Items",mkrs), ...
                ... Transparency
                struct("Limits",[0,1],"LowerLimitInclusive","on","UpperLimitInclusive","on"), ...
                ... Smooth data settings
                struct(), ..., ...
                ... Smooth method
                struct("Items",string({'movmean','movmedian','gaussian','lowess','loess','rlowess','rloess','sgolay'})), ...
                ... Use manual smooth span
                struct("Text",""), ...
                ... Smooth data span
                struct("Limits",[0,Inf],"LowerLimitInclusive","off"), ...
                ... Auto smooth span factor
                struct("Limits",[0,1],"LowerLimitInclusive","off","UpperLimitInclusive","off"), ...
                ... Segmentation settings
                struct(), ..., ...
                ... Segment data off smoothed fit
                struct("Text",""), ...
                ... Select method for segmenting data
                struct("Items",string({'mean','variance','linear'})), ...
                ... Enter the maximum number of segments
                struct("Limits",[0,Inf],"LowerLimitInclusive","on","RoundFractionalValues","on"), ...
                ... Calculate segmentations automatically based off threshold
                struct("Text",""), ...
                ... Enter the segment threshold detection level
                struct("Limits",[0,Inf],"LowerLimitInclusive","off","UpperLimitInclusive","on"), ...
                ... Fit settings
                struct(), ..., ...
                ... Fit on smoothed data
                struct("Text",""), ...
                ... Select fit method
                struct("Items",string({'poly1','poly2','linearinterp','cubicinterp','smoothingspline','lowess'})), ...
                ... Enter the fit prediction interval (between 0-1)
                struct("Limits",[0,1],"LowerLimitInclusive","on","UpperLimitInclusive","on"), ...
                ...| prediction_bounds_type | -- Prediction bounds type
                struct("Items",string({'observation','functional'})), ...
                ...| prediction_bounds_simultaneous | -- Simultaneous bounds
                struct("Text","")});

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% 3D on 2D fit %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            scatter_3d_on_2d_parameters_fields = string(fieldnames(app.scatter_3d_on_2d_parameters))';

            app.scatter_3d_on_2d_input_meta = dictionary(scatter_3d_on_2d_parameters_fields, ...
                {...
                ... 3D on 2D Plot Settings
                struct("type","heading","label","3D on 2D Plot Settings"), ...
                struct("type","checkbox","label","Show scatter"), ...
                struct("type","checkbox","label","Show error bars"), ...
                struct("type","checkbox","label","Show legend"), ...
                struct("type","checkbox","label","Show line along scatter plot"), ...
                struct("type","checkbox","label","Show MANOVA analysis (needs consistent bins across cell lines enabled)"), ...
                ... Scatter Plot Settings
                struct("type","heading","label","Scatter Plot Settings"), ...
                struct("type","dropdown","label","Scatter plot marker type"), ...
                struct("type","dropdown","label","Error bar type"), ...
                struct("type","dropdown","label","How to calculate scatter point size"), ...
                struct("type","dropdown","label","Binning method along z marker"), ...
                struct("type","spinner","label","-- Number of bins along z marker"), ...
                ... % bin_across_cell_lines
                struct("type","checkbox","label","Bin consistently across all selected cell lines"), ...
                struct("type","checkbox","label","Use smoothed x/y data along z"), ...
                ... Data Smoothing Settings
                struct("type","heading","label","Data Smoothing Settings"), ...
                struct("type","dropdown","label","Smooth method"), ...
                struct("type","numeric","label","Auto smooth span factor"), ...
                struct("type","checkbox","label","Use manual smooth span (overrides auto smooth span factor)"), ...
                struct("type","spinner","label","Manual smooth span")});

            app.scatter_3d_on_2d_input_settings = dictionary(scatter_3d_on_2d_parameters_fields, ...
                {...
                ...| plot_settings | 3D on 2D Plot Settings
                struct(), ..., ...
                ... do_scatter | Show scatter
                struct("Text",""), ...
                ... do_error_bars | Show error bars
                struct("Text",""), ...
                ... do_legend | Show legend
                struct("Text",""), ...
                ... do_scatter_line | Show line along scatter plot
                struct("Text",""), ...
                ... do_manova | Show MANOVA analysis
                struct("Text",""), ...
                ...| scatter_settings | Scatter Plot Settings
                struct(), ..., ...
                ... point | Scatter plot marker type
                struct("Items",mkrs), ...
                ... error_bar_type | Error bar type
                struct("Items",{'standard error','95% range','std'}), ...
                ... scatter_size_type | How to calculate scatter point size
                struct("Items",{'exponential','linear','log10'}), ...
                ... binning_type | Binning method along z marker
                struct("Items",{'percentile','linear'}), ...
                ... zvar_bins | Number of bins along z marker
                struct("Limits",[0,Inf],"LowerLimitInclusive","off","RoundFractionalValues","on"), ...
                ... bin_across_cell_lines | Bin across all selected cell lines
                struct("Text",""), ...
                ... use_smoothed_data | Use smoothed x/y data along z
                struct("Text",""), ...
                ...| smooth_settings | Data Smoothing Settings
                struct(), ..., ...
                ... smooth_method | Smooth method
                struct("Items",{'movmean','movmedian','gaussian','lowess','loess','rlowess','rloess','sgolay'}), ...
                ... auto_smooth_span_factor | Auto smooth span factor
                struct("Limits",[0,1],"LowerLimitInclusive","off","UpperLimitInclusive","on"), ...
                ... specify_smooth_span | Use manual smooth span (overrides auto smooth span factor)
                struct("Text",""), ...
                ... smooth_span | Manual smooth span
                struct("Limits",[0,Inf],"LowerLimitInclusive","off","RoundFractionalValues","on")});

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% tSNE %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            tSNE_parameters_fields = string(fieldnames(app.tSNE_parameters))';

            app.tSNE_input_meta = dictionary(tSNE_parameters_fields, ...
                {...
                ... Display Plots
                struct("type","heading","label","Display Plots"), ...
                struct("type","checkbox","label","Update tSNE plot live"), ...
                struct("type","checkbox","label","Show training divergence"), ...
                struct("type","checkbox","label","Show legend"), ...
                ... tSNE Training Options
                struct("type","heading","label","tSNE Training Options"), ...
                struct("type","numeric","label","Maximum number of training iterations"), ...
                struct("type","checkbox","label","Parallelise training (uses more system resources)"), ...
                struct("type","dropdown","label","Specify number of dimensions tSNE should reduce data down to"), ...
                struct("type","numeric","label","Specify number of tSNE training iterations between live plot updates"), ...
                struct("type","checkbox","label","Specify whether to normalise channels by their standard deviation"), ...
                struct("type","dropdown","label","Specify which algorithm to use for tSNE"), ...
                struct("type","dropdown","label","Specify which method to use for calculating distance between points"), ...
                struct("type","numeric","label","(Exaggeration) size of natural clusters in data"), ...
                struct("type","numeric","label","Specify the number of dimensions to reduce to via PCA before doing tSNE (0 means no PCA reduction)"), ...
                struct("type","numeric","label","(Perplexity) effective number of local neighbours for each cell"), ...
                struct("type","numeric","label","Specify learn rate for optimization process"), ...
                struct("type","numeric","label","Specify Barnes-Hut tradeoff (higher values give a faster but less accurate optimization)")});

            app.tSNE_input_settings = dictionary(tSNE_parameters_fields, ...
                {...
                ... Display Plots
                struct(), ..., ...
                ... Update tSNE plot live
                struct("Text",""), ...
                ... Show training divergence
                struct("Text",""), ...
                ... Show legend
                struct("Text",""), ...
                ... tSNE Training Options
                struct(), ..., ...
                ... Maximum number of training iterations
                struct("Limits",[0,10000],"LowerLimitInclusive","off","UpperLimitInclusive","on"), ...
                ... Parallelise training (uses more system resources)
                struct("Text",""), ...
                ... Specify number of dimensions tSNE should reduce data down to
                struct("Items",string([2,3]),'ItemsData',[2,3]), ...
                ... Specify number of tSNE training iterations between live plot updates
                struct("Limits",[1,Inf],"LowerLimitInclusive","on","RoundFractionalValues","on"), ...
                ... Specify whether to normalise channels by their standard deviation
                struct("Text",""), ...
                ... Specify which algorithm to use for tSNE
                struct("Items",string({'barneshut','exact'})), ...
                ... Specify which method to use for calculating distance between points
                struct("Items",string({'euclidean','seuclidean','cityblock','chebychev','minkowski','mahalanobis','cosine','correlation','spearman','hamming','jaccard'})), ...
                ... Specify the size of natural clusters in data
                struct("Limits",[0,Inf],"LowerLimitInclusive","off","RoundFractionalValues","on"), ...
                ... Specify the number of dimensions to reduce to via PCA before doing tSNE (0 means no PCA reduction)
                struct("Limits",[0,Inf],"LowerLimitInclusive","on","RoundFractionalValues","on"), ...
                ... Specify effective number of local neighbours for each cell
                struct("Limits",[0,Inf],"LowerLimitInclusive","off","RoundFractionalValues","on"), ...
                ... Specify learn rate for optimization process
                struct("Limits",[0,Inf],"LowerLimitInclusive","off","RoundFractionalValues","on"), ...
                ... Specify Barnes-Hut tradeoff (higher values give a faster but less accurate optimization)
                struct("Limits",[0,1],"LowerLimitInclusive","off","UpperLimitInclusive","on")});

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%% UMAP %%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            umap_parameters_fields = string(fieldnames(app.umap_parameters))';

            app.umap_input_meta = dictionary(umap_parameters_fields, ...
                {...
                ... Display Plots
                struct("type","heading","label","Display Plots"), ...
                struct("type","checkbox","label","Update UMAP plot live"), ...
                struct("type","checkbox","label","Show legend"), ...
                ... UMAP Settings
                struct("type","heading","label","UMAP Settings"), ...
                struct("type","checkbox","label","Supervised UMAP"), ...
                struct("type","dropdown","label","Specify number of dimensions UMAP should reduce data down to"), ...
                struct("type","numeric","label","(min-dist) How tightly UMAP is allowed to pack points together. Must be less than effective distance scale"), ...
                struct("type","numeric","label","(spread) Effective distance scale"), ...
                struct("type","spinner","label","(n-neighbours) Balance from local to global structures"), ...
                struct("type","dropdown","label","Specify which method UMAP should use to calculate distance"), ...
                struct("type","numeric","label","-- Specify Minkowski exponent (if 'Minkowski' selected)"), ...
                struct("type","dropdown","label","Nearest neighbors search method"), ...
                struct("type","checkbox","label","-- Knnsearch will include all the neighbors whose distance values are equal to the Kth smallest distance"), ...
                struct("type","numeric","label","-- Maximum number of data points in the leaf node (if 'kdtree' is selected)"), ...
                ... UMAP pop-out app settings
                struct("type","heading","label","UMAP pop-out app settings"), ...
                struct("type","dropdown","label","(2D) Method to calculate clusters"), ...
                struct("type","dropdown","label","Method to calculate distance (if 'dbscan' selected)"), ...
                struct("type","numeric","label","-- Search radius 'epsilon' around each point (if 'dbscan' selected)"), ...
                struct("type","spinner","label","-- Minimum number of neighbors required for a core point (if 'dbscan' selected)"), ...
                struct("type","spinner","label","(2D) Contour display probability")});

            app.umap_input_settings = dictionary(umap_parameters_fields, ...
                {...
                ...| h_display_plots | Display Plots
                struct(), ..., ...
                ... show_live_update | Update UMAP plot live
                struct("Text",""), ...
                ... do_legend | Show legend
                struct("Text",""), ...
                ...| h_umap_settings | UMAP Settings
                struct(), ..., ...
                ...| do_supervised_umap | Supervised UMAP
                struct("Text",""), ...
                ... n_components | Specify number of dimensions UMAP should reduce data down to
                struct("Items",string([2,3]),"ItemsData",[2,3]), ...
                ... min_dist | How tightly UMAP is allowed to pack points together
                struct("Limits",[0,Inf],"LowerLimitInclusive","off","UpperLimitInclusive","on"), ...
                ... spread | Effective distance scale
                struct("Limits",[0,Inf],"LowerLimitInclusive","off","UpperLimitInclusive","on"), ...
                ... n_neighbors | Balance from local to global structures
                struct("Limits",[2,200],"LowerLimitInclusive","off","UpperLimitInclusive","off","RoundFractionalValues","on"), ...
                ... metric | Specify which method UMAP should use to calculate distance
                struct("Items",string({'euclidean','seuclidean','cityblock','chebychev','minkowski','mahalanobis','cosine','correlation','spearman','hamming','jaccard'})), ...
                ... P | Specify Minkowski exponent (if 'Minkowski' selected)
                struct("Limits",[0,Inf],"LowerLimitInclusive","off","UpperLimitInclusive","on","RoundFractionalValues","on"), ...
                ... NSMethod | Nearest neighbors search method
                struct("Items",string({'kdtree','exhaustive','nn_descent'})), ...
                ... IncludeTies | Knnsearch will include all the neighbors whose distance values are equal to the Kth smallest distance
                struct("Text",""), ...
                ... BucketSize | Maximum number of data points in the leaf node (if 'kdtree' is selected)
                struct("Limits",[0,Inf],"LowerLimitInclusive","off","UpperLimitInclusive","on","RoundFractionalValues","on"), ...
                ...| h_umap_app_settings | UMAP pop-out app settings
                struct(), ..., ...
                ... cluster_method_2D | (2D) Method to calculate clusters
                struct("Items",string({'dbscan','dbm'})), ...
                ... dbscan_distance | Method to calculate distance (if 'dbscan' selected)
                struct("Items",string({'precomputed','euclidean','squaredeuclidean','seuclidean','cityblock','chebychev','minkowski','mahalanobis','cosine','correlation','spearman','hamming','jaccard'})), ...
                ... epsilon | Search radius 'epsilon' around each point (if 'dbscan' selected)
                struct("Limits",[0,Inf],"LowerLimitInclusive","off","UpperLimitInclusive","on"), ...
                ... minpts | Minimum number of neighbors required for a core point (if 'dbscan' selected)
                struct("Limits",[0,Inf],"LowerLimitInclusive","off","RoundFractionalValues","on"), ...
                ... contour_percent | (2D) Contour display probability
                struct("Limits",[0,25],"LowerLimitInclusive","on","UpperLimitInclusive","on")});
        end

        % Button pushed function: UpdateTableViewer_Original
        function UpdateTableViewer_OriginalPushed(app, event)
            if numel(app.cell_line_names)>0
                variant_pack = app.datasets_dictionary_original(app.selected_data_set);
                cell_line_data = variant_pack(app.FileNameDropDown_Load.Value);

                %Visualise Data
                app.Table_OriginalFiles.Data = cell_line_data{1};
                %Show Column Headers
                app.Table_OriginalFiles.ColumnName = cell_line_data{1}.Properties.VariableNames;
            end
        end

        % Button pushed function: AddAnotherFile_Original
        function AddAnotherFile_OriginalPushed(app, event)
            progbar = uiprogressdlg(app.UIFigure,"Indeterminate","on","Message",'Select file/s to add...');
            %Add new data to input folder for app
            save_location = fullfile(app.inputdata_dir,app.selected_data_set);
            [~,status] = add_input_data(save_location);

            if ~status
                return
            end

            close(progbar)
            figure(app.UIFigure)

            %Update list of input data files
            progbar = uiprogressdlg(app.UIFigure,"Indeterminate","on","Message",'Rescanning input data folder...');

            app.datasets_dictionary_original = import_data_sets(app.file_types_supported,app.inputdata_dir,app.datasets_dictionary_original,"app",app,"startup",1);

            app.datasets_dictionary_processed = app.datasets_dictionary_original;

            close(progbar)

            %Reload data
            datalist_update(app)
        end

        % Button pushed function: Load_In_Folder_Original
        function Load_In_Folder_OriginalPushed(app, event)
            progbar = uiprogressdlg(app.UIFigure,"Indeterminate","on","Message",'Loading in data folder...');

            app.datasets_dictionary_original = import_data_sets(app.file_types_supported,app.inputdata_dir,app.datasets_dictionary_original,"app",app);

            app.datasets_dictionary_processed = app.datasets_dictionary_original;

            close(progbar);

            datalist_update(app);

            figure(app.UIFigure);

            % ui waitbar
            % do load in folder prompt and name prompt
            % ui end waitbar

        end

        % Button pushed function: DeleteFile_Original
        function DeleteFile_OriginalPushed(app, event)
            progbar = uiprogressdlg(app.UIFigure,"Indeterminate","on","Message",'Deleting data...');

            del_dir = fullfile(app.inputdata_dir,app.selected_data_set);
            remove_selected_data(app.OriginalFilesListBox.Value,del_dir)

            close(progbar)

            %Update list of input data files
            progbar = uiprogressdlg(app.UIFigure,"Indeterminate","on","Message",'Rescanning input data folder...');

            app.datasets_dictionary_original = import_data_sets(app.file_types_supported,app.inputdata_dir,app.datasets_dictionary_original,"app",app,"startup",1);

            app.datasets_dictionary_processed = app.datasets_dictionary_original;

            close(progbar)

            %Reload data
            datalist_update(app)
        end

        % Button pushed function: AddchanneltorenameButton
        function AddchanneltorenameButtonPushed(app, event)
            [app.OriginalcolumnnamesListBox.Items, app.NewcolumnnamesListBox.Items] = change_add_column_names_2(app.OriginalcolumnnamesListBox.Items,app.NewcolumnnamesListBox.Items,app.available_markers);

            %Update Marker List
            [app.marker_list] = change_marker_list_from_rename(app.marker_list,app.OriginalcolumnnamesListBox.Items,app.NewcolumnnamesListBox.Items);
            update_marker_list(app)
        end

        % Button pushed function: RemoveselectednamesButton
        function RemoveselectednamesButtonPushed(app, event)
            %Update marker list
            app.marker_list = remove_marker_list_selected_items(app.marker_list,app.NewcolumnnamesListBox.Value);
            app.marker_list = remove_marker_list_selected_items(app.marker_list,app.OriginalcolumnnamesListBox.Value);
            update_marker_list(app)

            %Update rename column
            [app.OriginalcolumnnamesListBox.Items,app.NewcolumnnamesListBox.Items] = remove_channel_renames( ...
                app.OriginalcolumnnamesListBox.Items,app.NewcolumnnamesListBox.Items, ...
                app.OriginalcolumnnamesListBox.Value,app.NewcolumnnamesListBox.Value);
        end

        % Value changed function: OriginalcolumnnamesListBox
        function OriginalcolumnnamesListBoxValueChanged(app, event)
            app.NewcolumnnamesListBox.Value = app.old2new_column_names(app.OriginalcolumnnamesListBox.Value);
        end

        % Value changed function: NewcolumnnamesListBox
        function NewcolumnnamesListBoxValueChanged(app, event)
            app.OriginalcolumnnamesListBox.Value = app.new2old_column_names(app.NewcolumnnamesListBox.Value);
        end

        % Button pushed function: 
        % AddmarkerchannelfromoriginalcolumnnamesButton
        function AddmarkerchannelfromoriginalcolumnnamesButtonPushed(app, event)
            if numel(app.dataset_list)>0

                [app.OriginalcolumnnamesListBox.Items,app.NewcolumnnamesListBox.Items] = add_marker_from_original_data_column_2( ...
                    app.OriginalcolumnnamesListBox.Items,app.NewcolumnnamesListBox.Items,app.available_markers,app.marker_list, ...
                    "select_all",0);

                %Update Marker List
                [app.marker_list] = change_marker_list_from_rename(app.marker_list,app.OriginalcolumnnamesListBox.Items,app.NewcolumnnamesListBox.Items);
                update_marker_list(app)
            end
        end

        % Button pushed function: RemoveMarkerButton
        function RemoveMarkerButtonPushed(app, event)
            %Update Marker list
            app.marker_list = remove_marker_list_selected_items(app.marker_list,app.MarkerchannelsListBox.Value);
            update_marker_list(app)
        end

        % Callback function
        function UIFigureSizeChanged(app, event)

        end

        % Value changed function: PickwhichmarkertoplotDropDown
        function PickwhichmarkertoplotDropDownValueChanged(app, event)
            update_absolute_filter_plot(app)
        end

        % Button pushed function: UpdateabsolutechannelfilterButton
        function UpdateabsolutechannelfilterButtonPushed(app, event)
            condition1 = numel(app.Variants_ListBox_channel_filtering.Value)>0;
            condition2 = numel(app.marker_list)>0;

            if condition1&&condition2
                selected_cell_lines = app.Variants_ListBox_channel_filtering.Value;
                selected_marker = app.PickwhichmarkertoplotDropDown.Value;

                lowerval = app.LowerboundEditField.Value;
                upperval = app.UpperboundEditField.Value;

                if ~app.Apply_on_all_absolute_filter.Checked
                    selected_datasets = app.Dataset_pick_channel_filter.Value;
                else
                    %% Apply filter to all
                    selected_datasets = app.dataset_list;
                end
                %% Apply filter to selected dataset and selected cell lines
                [app.dataset_dictionary_marker_absolute_filter_bounds] = register_filter_change_2( ...
                    selected_datasets, ...
                    selected_cell_lines, ...
                    selected_marker, ...
                    app.dataset_dictionary_marker_absolute_filter_bounds, ...
                    lowerval,upperval);
            end
            update_absolute_filter_plot(app)
        end

        % Value changed function: Variants_ListBox_channel_filtering
        function Variants_ListBox_channel_filteringValueChanged(app, event)
            update_absolute_filter_plot(app);
            SelectmarkerchannelstofilterusingquantilesListBoxValueChanged(app, event);
        end

        % Value changed function: UpperboundEditField
        function UpperboundEditFieldValueChanged(app, event)
            upperval = max(app.UpperboundEditField.Value,app.LowerboundEditField.Value);
            lowerval = min(app.UpperboundEditField.Value,app.LowerboundEditField.Value);

            app.UpperboundEditField.Value = upperval;
            app.LowerboundEditField.Value = lowerval;

            app.l.Position(2) = app.UpperboundEditField.Value;
            app.l.Position(1) = app.LowerboundEditField.Value;
            lMoving(app,app.qc_hist_plot_data)
        end

        % Value changed function: 
        % SelectmarkerchannelstofilterusingquantilesListBox
        function SelectmarkerchannelstofilterusingquantilesListBoxValueChanged(app, event)
            %% Set what the upper quartile and lower quartile values are based off of selected markers and already recorded values
            condition1 = numel(app.SelectmarkerchannelstofilterusingquantilesListBox.Value)==1;
            condition2 = numel(app.Variants_ListBox_channel_filtering.Value)==1;

            if condition1&&condition2
                %Show specific quantile bound values if only one cell
                %line and one marker is selected
                variant_pack_quantile_filter = app.dataset_dictionary_marker_quantile_filter_bounds(app.Dataset_pick_channel_filter.Value);
                cell_line_quantile_range = variant_pack_quantile_filter(app.Variants_ListBox_channel_filtering.Value);
                marker_quantile_range = cell_line_quantile_range(app.SelectmarkerchannelstofilterusingquantilesListBox.Value);

                app.UpperquantileEditField.Value = marker_quantile_range{1}(2);
                app.LowerquantileEditField.Value = marker_quantile_range{1}(1);
            else
                app.UpperquantileEditField.Value = 100;
                app.LowerquantileEditField.Value = 0;
            end
        end

        % Button pushed function: UpdatequantilechannelfilterButton
        function UpdatequantilechannelfilterButtonPushed(app, event)
            condition1 = numel(app.SelectmarkerchannelstofilterusingquantilesListBox.Value)>0;
            condition2 = numel(app.cell_line_names)>0;

            if condition1&&condition2
                if numel(app.Variants_ListBox_channel_filtering.Value)==0
                    selected_cell_lines = app.cell_line_names;
                else
                    selected_cell_lines = app.Variants_ListBox_channel_filtering.Value;
                end

                if app.Apply_on_all_quantile_filter.Checked
                    selected_datasets = app.dataset_list;
                else
                    selected_datasets = app.Dataset_pick_channel_filter.Value;
                end

                selected_markers = app.SelectmarkerchannelstofilterusingquantilesListBox.Value;

                lowerval = app.LowerquantileEditField.Value;
                upperval = app.UpperquantileEditField.Value;

                %% Apply filter to selected dataset and selected cell lines
                [app.dataset_dictionary_marker_quantile_filter_bounds] = register_filter_change_2( ...
                    selected_datasets, ...
                    selected_cell_lines, ...
                    selected_markers, ...
                    app.dataset_dictionary_marker_quantile_filter_bounds, ...
                    lowerval,upperval);
            end

            update_absolute_filter_plot(app)
        end

        % Button pushed function: ResetselectedButton
        function ResetselectedButtonPushed(app, event)
            % reset quantile filter values
            app.UpperquantileEditField.Value = 100;
            app.LowerquantileEditField.Value = 0;

            %apply resetted values to selected channels
            UpdatequantilechannelfilterButtonPushed(app, event)
        end

        % Button pushed function: FilterandprocessdataButton
        function FilterandprocessdataButtonPushed(app, event)
            filter_process_data(app)
        end

        % Value changed function: LogDataSwitch
        function LogDataSwitchValueChanged(app, event)
            if app.LogDataSwitch.Value


                marker_list = string(app.marker_list);  % Make sure it's a string array
                num_markers = numel(marker_list);
        
                fig = uifigure('Name', 'Select Markers to Log', 'Position', [100, 100, 400, 500]);
                uilabel(fig, 'Text', 'Select markers to log:', ...
                    'Position', [20 460 300 20], 'FontWeight', 'bold');
        
                listbox = uilistbox(fig, ...
                    'Items', marker_list, ...
                    'Multiselect', 'on', ...
                    'Position', [20, 100, 360, 350]);
        
                uibutton(fig, 'Text', 'OK', ...
                    'Position', [150, 40, 100, 30], ...
                    'ButtonPushedFcn', @(btn,event) uiresume(fig));
        
                uiwait(fig);
        
                selected_markers = listbox.Value;
                log_flags = ismember(marker_list, selected_markers);
        
                app.LogDataListListBox.Items = selected_markers;

                app.LogDataListListBox.ItemsData = log_flags;  % store the selection
        
                close(fig);

                app.Zero_adjust_switch.Value = 1;
            end
        end

        % Value changed function: WhichfiltergoesfirstSwitch
        function WhichfiltergoesfirstSwitchValueChanged(app, event)
            update_absolute_filter_plot(app)
        end

        % Callback function
        function Xaxismarker_2d_scatterValueChanged(app, event)
            update_2d_scatter_plot(app)
        end

        % Callback function
        function Yaxismarker_2d_scatterValueChanged(app, event)
            update_2d_scatter_plot(app)
        end

        % Callback function
        function Scatter_plot_2d_variant_select_boxValueChanged(app, event)
            update_2d_scatter_plot(app)
        end

        % Callback function
        function UpdateTableViewer_ComparisonButtonPushed(app, event)
            if numel(app.comparison_cell_line_names)>0
                %Visualise Data
                app.Table_ComparisonFiles.Data = app.processed_comparison_data_sets{app.comparison_cell_line_names==app.FileNameDropDown_Comparison.Value};
                %Show Column Headers
                app.Table_ComparisonFiles.ColumnName = app.processed_comparison_data_sets{app.comparison_cell_line_names==app.FileNameDropDown_Comparison.Value}.Properties.VariableNames;
            end
        end

        % Callback function
        function Load_In_Folder_ComparisonButtonPushed(app, event)
            message = 'ALL the contents of the chosen folder will be copied into the app data input folder';
            title = 'Open Folder...';

            selection = uiconfirm(app.UIFigure,message,title,...
                'Options',{'Continue','Cancel'},...
                'Icon','warning');

            switch selection
                case 'Continue'
                    dummy = figure('Position', [-100 -100 0 0]); %create a dummy figure so that uigetfile doesn't minimize our GUI
                    index_new_folder_data(app.input_comparison_data_dir)
                    delete(dummy); %delete dummy

                    app.comparison_cell_line_names = input_data_folder_check(app.input_comparison_data_dir);
                    datalist_update(app)
                case 'Cancel'
                    %do nothing
            end
        end

        % Callback function
        function AddAnotherFile_ComparisonButtonPushed(app, event)
            %Add new data to input folder for app
            add_input_data(app.input_comparison_data_dir);
            figure(app.UIFigure)
            %Update list of input data files
            app.comparison_cell_line_names = input_data_folder_check(app.input_comparison_data_dir);
            %Reload data
            datalist_update(app);
        end

        % Callback function
        function DeleteFile_ComparisonButtonPushed(app, event)
            remove_selected_data(app.ComparisonFilesListBox.Value,app.input_comparison_data_dir)

            app.comparison_cell_line_names = input_data_folder_check(app.input_comparison_data_dir);
            datalist_update(app)
        end

        % Value changed function: Dataset_pick_channel_filter
        function Dataset_pick_channel_filterValueChanged(app, event)
            app.Variants_ListBox_channel_filtering = data_set_change(app,app.Dataset_pick_channel_filter.Value,app.Variants_ListBox_channel_filtering);

            update_absolute_filter_plot(app)
            SelectmarkerchannelstofilterusingquantilesListBoxValueChanged(app, event)
        end

        % Callback function
        function OverlaybothdatasetsperchannelCheckBoxValueChanged(app, event)
            update_absolute_filter_plot(app)
            SelectmarkerchannelstofilterusingquantilesListBoxValueChanged(app, event)
        end

        % Callback function: Toolbar_filter_data
        function Toolbar_filter_dataClicked(app, event)
            filter_process_data(app)
        end

        % Value changed function: Xaxismarker_scatter_filter
        function Xaxismarker_scatter_filterValueChanged(app, event)
            update_scatter_filter_plot(app)
        end

        % Value changed function: Yaxismarker_scatter_filter
        function Yaxismarker_scatter_filterValueChanged(app, event)
            update_scatter_filter_plot(app)
        end

        % Value changed function: Variants_ListBox_scatter_filtering
        function Variants_ListBox_scatter_filteringValueChanged(app, event)
            update_scatter_filter_plot(app)
        end

        % Value changed function: Filter_scatter_plot_type
        function Filter_scatter_plot_typeValueChanged(app, event)
            update_scatter_filter_plot(app)
        end

        % Value changed function: Filter_scatter_do_bin_width
        function Filter_scatter_do_bin_widthValueChanged(app, event)
            update_scatter_filter_plot(app)
        end

        % Value changed function: Binwidth_scatter_filter
        function Binwidth_scatter_filterValueChanged(app, event)
            if string(app.Filter_scatter_do_bin_width.Value)=="Manual"
                update_scatter_filter_plot(app)
            end
        end

        % Value changed function: DrawPolygonButton
        function DrawPolygonButtonValueChanged(app, event)
            if app.DrawPolygonButton.Value
                %update plot with no polygons
                update_scatter_filter_plot(app)
                %draw polygon
                app.current_polygon = drawpolygon(app.axes_scatter_filter_plot);
                app.allow_save_polygon = 1;
                %reset button after shape drawn
                app.DrawPolygonButton.Value = 0;
            elseif ~app.allow_save_polygon
                delete(app.current_polygon)
            end
            %reset button after shape drawn
            app.DrawPolygonButton.Value = 0;
        end

        % Button pushed function: SavepolygonfilterButton
        function SavepolygonfilterButtonPushed(app, event)
            progbar = uiprogressdlg(app.UIFigure,"Indeterminate","on","Message",'Updating app...');

            if app.allow_save_polygon
                polyshape = app.current_polygon.Position;
                xvar = string(app.Xaxismarker_scatter_filter.Value);
                yvar = string(app.Yaxismarker_scatter_filter.Value);

                selected_datasets = app.Dataset_pick_scatter_filter.Value;

                if app.apply_scatter_filter_to_all_variants.Checked
                    selected_cell_lines = app.Variants_ListBox_scatter_filtering.Items;
                else
                    selected_cell_lines = app.Variants_ListBox_scatter_filtering.Value;
                end

                app.dataset_dictionary_marker_polygon_filter = save_polygon_filter_2( ...
                    xvar,yvar, ...
                    selected_datasets, ...
                    selected_cell_lines, ...
                    app.dataset_dictionary_marker_polygon_filter, ...
                    polyshape);
            end

            delete(progbar)
        end

        % Callback function
        function PlottypeDropDownValueChanged(app, event)
            update_2d_scatter_plot(app)
        end

        % Callback function
        function BinnumberEditFieldValueChanged(app, event)
            update_2d_scatter_plot(app)
        end

        % Callback function
        function Binwidth_2dscatterValueChanged(app, event)
            update_2d_scatter_plot(app)
        end

        % Callback function
        function Switch_Scatter_2d_bin_number_switchValueChanged(app, event)
            update_2d_scatter_plot(app)
        end

        % Callback function
        function Switch_Scatter_2d_bin_width_switchValueChanged(app, event)
            update_2d_scatter_plot(app)
        end

        % Callback function
        function Dataset_pick_2D_scatterValueChanged(app, event)
            app.Scatter_plot_2d_variant_select_box = data_set_change(app,app.Dataset_pick_2D_scatter.Value,app.Scatter_plot_2d_variant_select_box);

        end

        % Callback function
        function Dataset_pick_3D_scatterValueChanged(app, event)
            data_set_change(app,app.Dataset_pick_3D_scatter.Value,app.Scatter_plot_3d_variant_select_box);

        end

        % Callback function
        function Scatter_plot_3d_variant_select_boxValueChanged(app, event)
            update_3D_scatter_plot(app)
        end

        % Callback function
        function Xaxismarker_3d_scatterValueChanged(app, event)
            update_3D_scatter_plot(app)
        end

        % Callback function
        function Yaxismarker_3d_scatterValueChanged(app, event)
            update_3D_scatter_plot(app)
        end

        % Callback function
        function Zaxismarker_3d_scatterValueChanged(app, event)
            update_3D_scatter_plot(app)
        end

        % Value changed function: Correlation_plot_variant_select_box
        function Correlation_plot_variant_select_boxValueChanged(app, event)
            if isequal(app.app_parameters.live_update_plots,"Always")
                update_variant_marker_correlation_plot(app)
            end
        end

        % Callback function
        function KeepexpressionchannelinplotCheckBoxValueChanged(app, event)
            update_variant_marker_correlation_plot(app)
        end

        % Callback function
        function KeepnormalisationchannelinplotCheckBoxValueChanged(app, event)
            update_variant_marker_correlation_plot(app)
        end

        % Value changed function: Dataset_pick_variant_correlation
        function Dataset_pick_variant_correlationValueChanged(app, event)
            data_set_change(app,app.Dataset_pick_variant_correlation.Value,app.Correlation_plot_variant_select_box);

        end

        % Button pushed function: Popout_vc_grid
        function Popout_vc_gridPushed(app, event)
            progbar = uiprogressdlg(app.UIFigure,"Indeterminate","on","Message",'Updating app...');

            if app.exist_correlation_plot
                app_pos = app.UIFigure.Position;
                size = app.panel_variant_marker_correlation.Position;
                pop_out_button([app_pos(1:2),size(3:4)],string(app.Correlation_plot_variant_select_box.Value), ...
                    app.correlation_tiles,"dataset_name",app.Dataset_pick_variant_correlation.Value)
            end

            close(progbar)
        end

        % Value changed function: Expression_marker_plot_variant_select_box
        function Expression_marker_plot_variant_select_boxValueChanged(app, event)
            if isequal(app.app_parameters.live_update_plots,"Always")
                update_expression_marker_plot(app)
            end
        end

        % Value changed function: Yaxismarker_expression_marker_plot
        function Yaxismarker_expression_marker_plotValueChanged(app, event)
            if isequal(app.app_parameters.live_update_plots,"Always")
                update_expression_marker_plot(app)
            end
        end

        % Button pushed function: NormaliseallButton
        function NormaliseallButtonPushed(app, event)
            [app.marker_do_normalise_buttons] = normalise_all_channels_button(app.marker_do_normalise_buttons);
        end

        % Value changed function: MoreSettings_expression_marker
        function MoreSettings_expression_markerValueChanged(app, event)
            more_settings_button_push(app.MoreSettings_expression_marker,app,[],"Expression marker plot settings","expression_marker_parameters",app.expression_marker_parameters,app.expression_marker_input_meta,app.expression_marker_input_settings)
        end

        % Button pushed function: LinearsegmentdetailsButton
        function LinearsegmentdetailsButtonPushed(app, event)
            if isequal(class(app.segmentdata),'struct')
                sub_app_marker_segment_analysis_converted(app.segmentdata)
            end
        end

        % Callback function: Toolbar_analysis_tab
        function Toolbar_analysis_tabClicked(app, event)
            app.TabGroup.SelectedTab = app.D13Tab;
        end

        % Value changed function: Scatter_3d_fit_on_2d_variant_select_box
        function Scatter_3d_fit_on_2d_variant_select_boxValueChanged(app, event)
            if isequal(app.app_parameters.live_update_plots,"Always")
                update_3D_scatter_on_2D_plot(app)
            end
        end

        % Value changed function: Xaxismarker_3d_scatter_on_2d
        function Xaxismarker_3d_scatter_on_2dValueChanged(app, event)
            if isequal(app.app_parameters.live_update_plots,"Always")
                update_3D_scatter_on_2D_plot(app)
            end
        end

        % Value changed function: Yaxismarker_3d_scatter_on_2d
        function Yaxismarker_3d_scatter_on_2dValueChanged(app, event)
            if isequal(app.app_parameters.live_update_plots,"Always")
                update_3D_scatter_on_2D_plot(app)
            end
        end

        % Value changed function: Zaxismarker_3d_scatter_on_2d
        function Zaxismarker_3d_scatter_on_2dValueChanged(app, event)
            if isequal(app.app_parameters.live_update_plots,"Always")
                update_3D_scatter_on_2D_plot(app)
            end
        end

        % Value changed function: MoreSettingsButton_3D_fit_on_2D
        function MoreSettingsButton_3D_fit_on_2DValueChanged(app, event)
            more_settings_button_push(app.MoreSettingsButton_3D_fit_on_2D,app,[], ...
                "3D to 2D fit settings","scatter_3d_on_2d_parameters",app.scatter_3d_on_2d_parameters,app.scatter_3d_on_2d_input_meta,app.scatter_3d_on_2d_input_settings)
        end

        % Button pushed function: PopoutButton_3D_fit_on_2D
        function PopoutButton_3D_fit_on_2DPushed(app, event)
            progbar = uiprogressdlg(app.UIFigure,"Indeterminate","on","Message",'Popping out axis...');

            if app.exist_3d_on_2d_fit
                name = strcat(string(app.Xaxismarker_3d_scatter_on_2d.Value)," vs ",string(app.Yaxismarker_3d_scatter_on_2d.Value),", against ",string(app.Zaxismarker_3d_scatter_on_2d.Value));
                pop_out_button([],name,app.tiles_3D_scatter_on_2D_plot,"figfather",app.UIFigure,"dataset_name",app.Dataset_pick_3D_scatter_on_2d.Value)
            end

            close(progbar)
        end

        % Callback function: Toolbar_dimensionality_reduction_tab
        function Toolbar_dimensionality_reduction_tabClicked(app, event)
            app.TabGroup.SelectedTab = app.tSNETab;
        end

        % Button pushed function: StarttSNEButton
        function StarttSNEButtonPushed(app, event)
            start_tsne(app);
        end

        % Value changed function: Marker_pick_correlation_plot_list_box
        function Marker_pick_correlation_plot_list_boxValueChanged(app, event)

        end

        % Value changed function: PauseButton_tsne
        function PauseButton_tsneValueChanged(app, event)
            if ~app.PauseButton_tsne.Value

                app.PauseButton_tsne.Text = "Pause";
                app.PauseButton_tsne.Enable = "on";
                app.PauseButton_tsne.Value = 0;

                app.tsne_lamp.Color = 'r';
                app.tsne_lamp_label.Text = 'Running tSNE...';

                uiresume(app.UIFigure);
            end
        end

        % Value changed function: InspectSelectionButton_tsne
        function InspectSelectionButton_tsneValueChanged(app, event)
            %             if ~isempty(app.tsne_output)

            if isvalid(app.tsne_plot_tiles)&&~app.PauseButton_tsne.Enable
                app.InspectSelectionButton_tsne.Enable = 0;

                selection_inspection(nexttile(app.tsne_plot_tiles,1),app.tsne_output.('Position'),app.tsne_output.('Category'),app.tsne_master_table);

                app.InspectSelectionButton_tsne.Enable = 1;
                app.InspectSelectionButton_tsne.Value = 0;
            end
        end

        % Value changed function: MoreSettings_tsne
        function MoreSettings_tsneValueChanged(app, event)
            more_settings_button_push(app.MoreSettings_tsne,app,[app.UIFigure.Position],"tSNE settings","tSNE_parameters",app.tSNE_parameters,app.tSNE_input_meta,app.tSNE_input_settings)
        end

        % Callback function: Toolbar_define_channels
        function Toolbar_define_channelsClicked(app, event)
            app.TabGroup.SelectedTab = app.DefineChannelsTab;
        end

        % Value changed function: LowerboundEditField
        function LowerboundEditFieldValueChanged(app, event)
            upperval = max(app.UpperboundEditField.Value,app.LowerboundEditField.Value);
            lowerval = min(app.UpperboundEditField.Value,app.LowerboundEditField.Value);

            app.UpperboundEditField.Value = upperval;
            app.LowerboundEditField.Value = lowerval;

            app.l.Position(2) = app.UpperboundEditField.Value;
            app.l.Position(1) = app.LowerboundEditField.Value;
            lMoving(app,app.qc_hist_plot_data);
        end

        % Menu selected function: Apply_on_all_absolute_filter
        function Apply_on_all_absolute_filterSelected(app, event)
            app.Apply_on_all_absolute_filter.Checked = ~app.Apply_on_all_absolute_filter.Checked;
        end

        % Menu selected function: Apply_on_all_quantile_filter
        function Apply_on_all_quantile_filterMenuSelected(app, event)
            app.Apply_on_all_quantile_filter.Checked = ~app.Apply_on_all_quantile_filter.Checked;
        end

        % Value changed function: DatasetDropDown_load
        function DatasetDropDown_loadValueChanged(app, event)
            app.OriginalFilesListBox = data_set_change(app,app.DatasetDropDown_load.Value,app.OriginalFilesListBox);
        end

        % Value changed function: FileNameDropDown_Load
        function FileNameDropDown_LoadValueChanged(app, event)
            if numel(app.cell_line_names)>0
                variant_pack = app.datasets_dictionary_original(app.selected_data_set);
                cell_line_data = variant_pack(app.FileNameDropDown_Load.Value);

                %Visualise Data
                app.Table_OriginalFiles.Data = cell_line_data{1};
                %Show Column Headers
                app.Table_OriginalFiles.ColumnName = cell_line_data{1}.Properties.VariableNames;
            end
        end

        % Value changed function: Dataset_pick_scatter_filter
        function Dataset_pick_scatter_filterValueChanged(app, event)
            app.Variants_ListBox_scatter_filtering = data_set_change(app,app.Dataset_pick_scatter_filter.Value,app.Variants_ListBox_scatter_filtering);

            update_scatter_filter_plot(app)
        end

        % Value changed function: Dataset_pick_3D_scatter_on_2d
        function Dataset_pick_3D_scatter_on_2dValueChanged(app, event)
            data_set_change(app,app.Dataset_pick_3D_scatter_on_2d.Value,app.Scatter_3d_fit_on_2d_variant_select_box);

        end

        % Value changed function: variant_select_box_tsne
        function variant_select_box_tsneValueChanged(app, event)

        end

        % Value changed function: dataset_pick_tsne
        function dataset_pick_tsneValueChanged(app, event)
            app.variant_select_box_tsne = data_set_change(app,app.dataset_pick_tsne.Value,app.variant_select_box_tsne);

        end

        % Value changed function: Dataset_pick_expression_marker
        function Dataset_pick_expression_markerValueChanged(app, event)
            app.Expression_marker_plot_variant_select_box = data_set_change(app,app.Dataset_pick_expression_marker.Value,app.Expression_marker_plot_variant_select_box);

        end

        % Value changed function: dataset_pick_umap
        function dataset_pick_umapValueChanged(app, event)
            app.variant_select_box_umap = data_set_change(app,app.dataset_pick_umap.Value,app.variant_select_box_umap);
            %praise
        end

        % Button pushed function: StartUMAPButton
        function StartUMAPButtonPushed(app, event)
            start_umap(app)
        end

        % Button pushed function: ClearpolygonButton
        function ClearpolygonButtonPushed(app, event)
            progbar = uiprogressdlg(app.UIFigure,"Indeterminate","on","Message",'Updating app...');

            xvar = string(app.Xaxismarker_scatter_filter.Value);
            yvar = string(app.Yaxismarker_scatter_filter.Value);

            selected_datasets = app.Dataset_pick_scatter_filter.Value;
            selected_cell_lines = app.Variants_ListBox_scatter_filtering.Value;

            app.dataset_dictionary_marker_polygon_filter = delete_polygon_filter_2( ...
                xvar,yvar, ...
                selected_datasets, ...
                selected_cell_lines, ...
                app.dataset_dictionary_marker_polygon_filter);

            delete(progbar)

            update_scatter_filter_plot(app)

        end

        % Button pushed function: Popoutfigure_expression_segmentation
        function Popoutfigure_expression_segmentationPushed(app, event)
            progbar = uiprogressdlg(app.UIFigure,"Indeterminate","on","Message",'Popping out axis...');

            if app.exist_expression_marker_plot
                name = strjoin(string(app.Expression_marker_plot_variant_select_box.Value)," | ");
                pop_out_button([],name,app.tiles_expression_marker,"figfather",app.UIFigure,"dataset_name",app.Dataset_pick_expression_marker.Value)
            end

            close(progbar)
        end

        % Callback function
        function Scatter_plot_2d_variant_select_boxDoubleClicked(app, event)
            update_2d_scatter_plot(app)
        end

        % Callback function
        function Scatter_plot_3d_variant_select_boxDoubleClicked(app, event)
            update_3D_scatter_plot(app)
        end

        % Double-clicked callback: Scatter_3d_fit_on_2d_variant_select_box
        function Scatter_3d_fit_on_2d_variant_select_boxDoubleClicked(app, event)
            update_3D_scatter_on_2D_plot(app)
        end

        % Double-clicked callback: Correlation_plot_variant_select_box
        function Correlation_plot_variant_select_boxDoubleClicked(app, event)
            update_variant_marker_correlation_plot(app)
        end

        % Double-clicked callback: 
        % Expression_marker_plot_variant_select_box
        function Expression_marker_plot_variant_select_boxDoubleClicked(app, event)
            update_expression_marker_plot(app)
        end

        % Value changed function: 
        % GenerateplotsforallYaxismarkercombinationsButton
        function GenerateplotsforallYaxismarkercombinationsButtonValueChanged(app, event)
            if app.GenerateplotsforallYaxismarkercombinationsButton.Value
                app.GenerateplotsforallYaxismarkercombinationsButton.Enable = 0;

                %% Produce plots
                condition1 = numel(app.Expression_marker_plot_variant_select_box.Value)>0;
                condition2 = numel(app.marker_list)>1;
                if condition1&&condition2
                    progbar = uiprogressdlg(app.UIFigure,"Message",'Generating plots...','Value',0,'Cancelable','on');

                    %% Initialise
                    selected_dataset = app.Dataset_pick_expression_marker.Value;
                    selected_pack = app.datasets_dictionary_processed(selected_dataset);
                    selected_cell_lines = string(app.Expression_marker_plot_variant_select_box.Value);

                    params = app.expression_marker_parameters;
                    fields = fieldnames(params);
                    params = rmfield(params, fields(structfun(@isempty, params)));
                    params = namedargs2cell(params);

                    expression_channel = app.Xaxismarker_expression_marker_plot.Value;
                    selected_markers = setdiff(app.marker_list,expression_channel);

                    % Get segmentation section index
                    min_segment = [];
                    max_segment = [];

                    %% Plot function inputs
                    for j = 1:numel(selected_cell_lines)
                        for i = 1:numel(selected_markers)
                            progbar.Title = selected_cell_lines(j);
                            progbar.Message = strcat("Plotting ",selected_markers(i)," against ",expression_channel);
                            progbar.Value = i/numel(selected_markers);
                            if progbar.CancelRequested
                                break
                            end
                            [~,output_data] = expression_marker_plot(selected_pack, ...
                                app.cell_line_names, ...
                                selected_cell_lines(j), ...
                                selected_markers(i), ...
                                expression_channel, ...
                                params{:}, ...
                                "fig_name",selected_dataset);

                            temp_x_samples = output_data.tfitsample;
                            temp_x_samples = temp_x_samples{1};

                            if numel(temp_x_samples)>1
                                min_segment = [min_segment,min(temp_x_samples{2}(:,1))];
                            else
                                min_segment = [min_segment,min(temp_x_samples{1}(:,1))];
                            end
                            max_segment = [max_segment,min(temp_x_samples{end}(:,1))];

                        end

                        if progbar.CancelRequested
                            break
                        end
                    end

                    min_max_pop_out_box(min(min_segment),max(max_segment),expression_channel)

                end

                close(progbar)
                %% Finish
                app.GenerateplotsforallYaxismarkercombinationsButton.Enable = 1;
                app.GenerateplotsforallYaxismarkercombinationsButton.Value = 0;
            end
        end

        % Value changed function: InspectSelection_umap
        function InspectSelection_umapValueChanged(app, event)
            if ~isempty(app.umap_output)
                app.InspectSelection_umap.Enable = 0;

                selection_inspection(nexttile(app.umap_plot_tiles,1),app.umap_output.('Position'),app.umap_output.('Category'),app.umap_master_table);

                app.InspectSelection_umap.Enable = 1;
                app.InspectSelection_umap.Value = 0;
            end
        end

        % Value changed function: PauseButton_umap
        function PauseButton_umapValueChanged(app, event)
            if ~app.PauseButton_umap.Value

                app.PauseButton_umap.Text = "Pause";
                app.PauseButton_umap.Enable = "on";
                app.PauseButton_umap.Value = 0;

                app.umap_lamp.Color = 'r';
                app.umap_lamp_label.Text = 'Running UMAP...';

                uiresume(app.UIFigure);
            end
        end

        % Value changed function: MoreSettings_umap
        function MoreSettings_umapValueChanged(app, event)
            more_settings_button_push(app.MoreSettings_umap,app,[app.UIFigure.Position],"UMAP settings","umap_parameters",app.umap_parameters,app.umap_input_meta,app.umap_input_settings);
        end

        % Button pushed function: PopoutButton_tsne
        function PopoutButton_tsnePushed(app, event)
            if ~isempty(app.tsne_plot_tiles)
                name = "";
                pop_out_button([],name,app.tsne_plot_tiles,"figfather",app.UIFigure,"dataset_name",app.Dataset_pick_expression_marker.Value)

                %                 selection_inspection(nexttile(app.tsne_plot_tiles,1),app.tsne_output.('Position'),app.tsne_output.('Category'),app.tsne_master_table);
            end
        end

        % Button pushed function: PopoutButton_umap
        function PopoutButton_umapPushed(app, event)
            if ~isempty(app.umap_plot_tiles)
                name = "";
                pop_out_button([],name,app.umap_plot_tiles,"figfather",app.UIFigure,"dataset_name",app.Dataset_pick_expression_marker.Value)
            end
        end

        % Value changed function: ND_variant_select_box
        function ND_variant_select_boxValueChanged(app, event)
            update_nd_plot(app)
        end

        % Value changed function: Xaxismarker_ND_scatter
        function Xaxismarker_ND_scatterValueChanged(app, event)
            update_nd_plot(app)
        end

        % Value changed function: Yaxismarker_ND_scatter
        function Yaxismarker_ND_scatterValueChanged(app, event)
            update_nd_plot(app)
        end

        % Value changed function: Zaxismarker_ND_scatter
        function Zaxismarker_ND_scatterValueChanged(app, event)
            update_nd_plot(app)
        end

        % Double-clicked callback: ND_variant_select_box
        function ND_variant_select_boxDoubleClicked(app, event)
            update_nd_plot(app)
        end

        % Button pushed function: PopoutButton_nd_plot
        function PopoutButton_nd_plotPushed(app, event)
            progbar = uiprogressdlg(app.UIFigure,"Indeterminate","on","Message",'Popping out axis...');

            if app.exist_nd_plot
                name = strjoin(string(app.ND_variant_select_box.Value));
                pop_out_button([],name,app.tiles_nd_plot,"figfather",app.UIFigure,"dataset_name",app.Dataset_pick_ND_scatter.Value)
            end

            close(progbar)
        end

        % Value changed function: MoreSettingsButton_1ND
        function MoreSettingsButton_1NDValueChanged(app, event)
            more_settings_button_push(app.MoreSettingsButton_1ND,app,[app.UIFigure.Position],"1D Plot settings","d1_parameters",app.d1_parameters,app.d1_input_meta,app.d1_input_settings);
        end

        % Value changed function: MoreSettingsButton_2ND
        function MoreSettingsButton_2NDValueChanged(app, event)
            more_settings_button_push(app.MoreSettingsButton_2ND,app,[app.UIFigure.Position],"2D Plot settings","d2_parameters",app.d2_parameters,app.d2_input_meta,app.d2_input_settings);
        end

        % Value changed function: MoreSettingsButton_channel_filter
        function MoreSettingsButton_channel_filterValueChanged(app, event)
            more_settings_button_push(app.MoreSettingsButton_channel_filter,app,[app.UIFigure.Position],"Absolute filter plot settings","d1_parameters",app.d1_parameters,app.d1_input_meta,app.d1_input_settings);
        end

        % Value changed function: Zero_adjust_switch
        function Zero_adjust_switchValueChanged(app, event)
            if app.LogDataSwitch.Value
                app.Zero_adjust_switch.Value = 1;
            end
        end

        % Button pushed function: UpdateplotButton
        function UpdateplotButtonPushed(app, event)
            update_correlation_tests_plot(app);
        end

        % Button pushed function: PopoutplotButton_correlation_tests
        function PopoutplotButton_correlation_testsPushed(app, event)
            progbar = uiprogressdlg(app.UIFigure,"Indeterminate","on","Message",'Popping out axis...');

            if app.axes_correlation_test~=0
                name = strjoin(string(app.correlation_tests_variant_select_box.Value));
                pop_out_button([],name,app.tiles_correlation_test,"figfather",app.UIFigure,"dataset_name",app.Dataset_pick_correlation_tests.Value)
            end

            close(progbar)
        end

        % Button pushed function: Update_normality_test_table_button
        function Update_normality_test_table_buttonPushed(app, event)
            update_correlation_normality_table(app);
        end

        % Button pushed function: PopoutnormalitytesttableButton
        function PopoutnormalitytesttableButtonPushed(app, event)
            progbar = uiprogressdlg(app.UIFigure,"Indeterminate","on","Message",'Popping out axis...');

            name = strjoin(string(app.correlation_tests_variant_select_box.Value));
            pop_out_button([],name,app.correlation_test_table_panel,"figfather",app.UIFigure,"dataset_name",app.Dataset_pick_correlation_tests.Value)

            close(progbar)
        end

        % Value changed function: Dataset_pick_ND_scatter
        function Dataset_pick_ND_scatterValueChanged(app, event)
            update_nd_plot(app);
        end

        % Button pushed function: correlation_table_vc_grid
        function correlation_table_vc_gridPushed(app, event)
            update_correlation_tables(app);
        end

        % Button pushed function: correlation_comparison_table_button
        function correlation_comparison_table_buttonPushed(app, event)
            update_correlation_comparison_tables(app);
        end

        % Value changed function: MoreSettings_variant_channel_grid
        function MoreSettings_variant_channel_gridValueChanged(app, event)
            more_settings_button_push(app.MoreSettings_variant_channel_grid,app,[],"V-C Grid settings","vc_parameters",app.vc_parameters,app.vc_input_meta,app.vc_input_settings)
        end

        % Value changed function: MoreSettings_corrtests
        function MoreSettings_corrtestsValueChanged(app, event)
            more_settings_button_push(app.MoreSettings_corrtests,app,[],"Correlation tests settings","cortests_parameters", ...
                app.cortests_parameters,app.cortests_input_meta,app.cortests_input_settings);
        end

        % Button pushed function: UpdatePlot_vc_grid
        function UpdatePlot_vc_gridButtonPushed(app, event)
            update_variant_marker_correlation_plot(app);
        end

        % Button pushed function: UpdateplotButton_3d_on_2d
        function UpdateplotButton_3d_on_2dPushed(app, event)
            update_3D_scatter_on_2D_plot(app);
        end

        % Button pushed function: UpdateplotButton_expression_segmentation
        function UpdateplotButton_expression_segmentationPushed(app, event)
            update_expression_marker_plot(app)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            try
                app_parameters = app.app_parameters;
                save(app.app_settings_dir,"app_parameters");
            end

            try
                % close app sound
                audio_sample_list = ["jingle-bells-rocks.mp3","chewy_roar.mp3","desseffes.mp3"];
                random_sound_fx(app,audio_sample_list)
            end

            delete(app)
        end

        % Value changed function: AppSettingsButton
        function AppSettingsButtonValueChanged(app, event)
            more_settings_button_push(app.AppSettingsButton,app,[], ...
                "App Settings","app_parameters",app.app_parameters,app.app_input_meta,app.app_input_settings);
        end

        % Value changed function: Dataset_pick_correlation_tests
        function Dataset_pick_correlation_testsValueChanged(app, event)

        end

        % Value changed function: correlation_tests_variant_select_box
        function correlation_tests_variant_select_boxValueChanged(app, event)

        end

        % Value changed function: Xaxismarker_expression_marker_plot
        function Xaxismarker_expression_marker_plotValueChanged(app, event)
            if isequal(app.app_parameters.live_update_plots,"Always")
                update_expression_marker_plot(app)
            end
        end

        % Menu selected function: apply_scatter_filter_to_all_variants
        function apply_scatter_filter_to_all_variantsMenuSelected(app, event)
            app.apply_scatter_filter_to_all_variants.Checked = ~app.apply_scatter_filter_to_all_variants.Checked;
        end

        % Button pushed function: AddallavailablemarkersButton
        function AddallavailablemarkersButtonPushed(app, event)
            if numel(app.dataset_list)>0

                [app.OriginalcolumnnamesListBox.Items,app.NewcolumnnamesListBox.Items] = add_marker_from_original_data_column_2( ...
                    app.OriginalcolumnnamesListBox.Items,app.NewcolumnnamesListBox.Items,app.available_markers,app.marker_list, ...
                    "select_all",1);

                %Update Marker List
                [app.marker_list] = change_marker_list_from_rename(app.marker_list,app.OriginalcolumnnamesListBox.Items,app.NewcolumnnamesListBox.Items);
                update_marker_list(app)
            end
        end

        % Button pushed function: PopoutButton_umap_2
        function PopoutButton_PCAPushed(app, event)
            if ~isempty(app.PCA_plot_tiles)
                name = "";
                pop_out_button([],name,app.PCA_plot_tiles,"figfather",app.UIFigure,"dataset_name",app.Dataset_pick_expression_marker.Value)
            end
        end

        % Button pushed function: StartPCAButton
        function StartPCAButtonPushed(app, event)
            start_PCA(app);
        end

        % Callback function
        function dataset_pick_PCAValueChanged(app, event)
            app.variant_select_box_PCA = data_set_change(app,app.dataset_pick_PCA.Value,app.variant_select_box_PCA);
            
        end

        % Button pushed function: StartBiexponentialPlotButton
        function StartBiexpButtonPushed(app, event)
            start_biexp(app);
        end

        % Button pushed function: PopoutButton_biexp
        function PopoutButton_biexppushed(app, event)
            if ~isempty(app.biexp_plot_tiles)
                name = "";
                pop_out_button([],name,app.biexp_plot_tiles,"figfather",app.UIFigure,"dataset_name",app.Dataset_pick_expression_marker.Value)
            end
        end

        % Callback function: not associated with a component
        function PopoutButton_biexp_2Pushed(app, event)
            if ~isempty(app.biexp_plot_tiles)
                name = "";
                pop_out_button([],name,app.biexp_plot_tiles,"figfather",app.UIFigure,"dataset_name",app.Dataset_pick_expression_marker.Value)
            end
        end

        % Button down function: Panel_AGNES_xaxis
        function Panel_AGNES_xaxisButtonDown(app, event)
            
        end

        % Callback function: not associated with a component
        function NumberofclustersonxaxisEditFieldValueChanged(app, event)
            value = app.NumberofclustersonxaxisEditField.Value;
            app.NumberofclustersonxaxisEditField.Value = value;
        end

        % Clicked callback: excel_select_box_agnes
        function excel_select_box_agnesClicked(app, event)

            % Open a dialog for selecting an Excel file
            [fileName, folderPath] = uigetfile( ...
                {'*.xlsx;*.xls', 'Excel Files (*.xlsx, *.xls)'}, ...
                'Select an Excel file');
        
            % Restore the App Designer window
            app.UIFigure.Visible = 'on';
            app.UIFigure.WindowState = 'normal';
            drawnow;
        
            % Give focus back to the select box
            focus(app.excel_select_box_agnes);
        
            % Stop if the user cancels
            if isequal(fileName, 0)
                return;
            end
        
            fullFilePath = fullfile(folderPath, fileName);
        
            try
                %% Read the Excel file as a cell array
                %
                % readcell preserves duplicate labels in the first row.
                app.agnes_master_table = readcell(fullFilePath);
        
                % Validate that some data was imported
                if isempty(app.agnes_master_table)
                    error('The selected Excel worksheet is empty.');
                end
        
                % Store the selected file path
                app.agnes_excel_path = string(fullFilePath);
        
                % Display the selected path in the select box
                app.excel_select_box_agnes.Items = {fullFilePath};
                app.excel_select_box_agnes.Value = fullFilePath;
        
                % Confirmation message
                uialert(app.UIFigure, ...
                    sprintf('Excel file imported successfully:\n%s', fileName), ...
                    'AGNES Import Complete', ...
                    'Icon', 'success');
        
                % Restore focus after the alert is closed
                app.UIFigure.WindowState = 'normal';
                drawnow;
                focus(app.excel_select_box_agnes);
        
            catch ME
                % Display an error if MATLAB cannot read the file
                uialert(app.UIFigure, ...
                    sprintf('Could not read the Excel file:\n%s', ME.message), ...
                    'Import Error', ...
                    'Icon', 'error');
        
                app.UIFigure.WindowState = 'normal';
                drawnow;
                focus(app.excel_select_box_agnes);
            end

            
        end

        % Button pushed function: RunAGNESButton
        function RunAGNESButtonPushed(app, event)
            runAGNES(app)
        end

        % Button pushed function: SaveAGNESPlotButton
        function SaveAGNESPlotButtonPushed(app, event)
            %% Confirm that AGNES has been run
            if isempty(app.AGNESGroupedData) || ...
                    isempty(app.AGNESUniqueXLabels) || ...
                    isempty(app.AGNESUniqueYLabels)
        
                uialert(app.UIFigure, ...
                    'Run AGNES before saving the results.', ...
                    'No AGNES Results', ...
                    'Icon', 'warning');
                return;
            end
        
            %% Ask the user to choose an output folder
            saveFolder = uigetdir( ...
                pwd, ...
                'Select folder to save AGNES results');
        
            app.UIFigure.Visible = 'on';
            app.UIFigure.WindowState = 'normal';
            drawnow;
        
            if isequal(saveFolder, 0)
                return;
            end
        
            try
                %% Create timestamp
                timestamp = char(datetime( ...
                    'now', ...
                    'Format', 'yyyyMMdd_HHmmss'));
        
                %% Define output paths
                xPlotPath = fullfile( ...
                    saveFolder, ...
                    ['AGNES_X_Axis_' timestamp '.png']);
        
                yPlotPath = fullfile( ...
                    saveFolder, ...
                    ['AGNES_Y_Axis_' timestamp '.png']);
        
                matFilePath = fullfile( ...
                    saveFolder, ...
                    ['AGNES_Results_' timestamp '.mat']);
        
                excelFilePath = fullfile( ...
                    saveFolder, ...
                    ['AGNES_Results_' timestamp '.xlsx']);
        
                savedFiles = strings(0, 1);
        
                %% Find axes inside both panels
                xAxes = findall( ...
                    app.Panel_AGNES_xaxis, ...
                    'Type', 'axes');
        
                yAxes = findall( ...
                    app.Panel_AGNES_yaxis, ...
                    'Type', 'axes');
        
                %% Export X plot
                if ~isempty(xAxes)
        
                    exportgraphics( ...
                        xAxes(1), ...
                        xPlotPath, ...
                        'Resolution', 300, ...
                        'BackgroundColor', 'white');
        
                    savedFiles(end + 1) = string(xPlotPath);
                end
        
                %% Export Y plot
                if ~isempty(yAxes)
        
                    exportgraphics( ...
                        yAxes(1), ...
                        yPlotPath, ...
                        'Resolution', 300, ...
                        'BackgroundColor', 'white');
        
                    savedFiles(end + 1) = string(yPlotPath);
                end
        
                %% Retrieve stored results
                uniqueXLabels = string( ...
                    app.AGNESUniqueXLabels);
        
                uniqueYLabels = string( ...
                    app.AGNESUniqueYLabels);
        
                uniqueXLabels = uniqueXLabels(:).';
                uniqueYLabels = uniqueYLabels(:);
        
                groupedData = app.AGNESGroupedData;
        
                xDistanceMatrix = ...
                    app.AGNESXDistanceMatrix;
        
                yDistanceMatrix = ...
                    app.AGNESYDistanceMatrix;
        
                xSimilarityMatrix = ...
                    app.AGNESXSimilarityMatrix;
        
                ySimilarityMatrix = ...
                    app.AGNESYSimilarityMatrix;
        
                xOrder = app.ClusteredColumnOrder(:).';
                yOrder = app.ClusteredRowOrder(:).';
        
                %% Validate dimensions
                if size(groupedData, 2) ~= numel(uniqueXLabels)
                    error(['The grouped-data column count does not match ' ...
                           'the number of unique X labels.']);
                end
        
                if size(groupedData, 1) ~= numel(uniqueYLabels)
                    error(['The grouped-data row count does not match ' ...
                           'the number of unique Y labels.']);
                end
        
                if numel(xOrder) ~= numel(uniqueXLabels)
                    error('The stored X-axis leaf order is invalid.');
                end
        
                if numel(yOrder) ~= numel(uniqueYLabels)
                    error('The stored Y-axis leaf order is invalid.');
                end
        
                %% Reorder data according to dendrogram order
                reorderedData = groupedData( ...
                    yOrder, ...
                    xOrder);
        
                reorderedXLabels = ...
                    uniqueXLabels(xOrder);
        
                reorderedYLabels = ...
                    uniqueYLabels(yOrder);
        
                %% Build complete MATLAB structure
                AGNESResults = struct();
        
                AGNESResults.OriginalImportedData = ...
                    app.agnes_master_table;
        
                AGNESResults.SourceExcelPath = ...
                    app.agnes_excel_path;
        
                AGNESResults.UniqueXLabels = ...
                    uniqueXLabels;
        
                AGNESResults.UniqueYLabels = ...
                    uniqueYLabels;
        
                AGNESResults.GroupedData = ...
                    groupedData;
        
                AGNESResults.ReorderedXLabels = ...
                    reorderedXLabels;
        
                AGNESResults.ReorderedYLabels = ...
                    reorderedYLabels;
        
                AGNESResults.ReorderedData = ...
                    reorderedData;
        
                AGNESResults.XDistanceMatrix = ...
                    xDistanceMatrix;
        
                AGNESResults.YDistanceMatrix = ...
                    yDistanceMatrix;
        
                AGNESResults.XSimilarityMatrix = ...
                    xSimilarityMatrix;
        
                AGNESResults.YSimilarityMatrix = ...
                    ySimilarityMatrix;
        
                AGNESResults.XClusterTree = ...
                    app.XClusterTree;
        
                AGNESResults.YClusterTree = ...
                    app.YClusterTree;
        
                AGNESResults.XLeafOrder = ...
                    xOrder;
        
                AGNESResults.YLeafOrder = ...
                    yOrder;
        
                AGNESResults.LinkageMethod = ...
                    "ward";
        
                AGNESResults.DistanceMetric = ...
                    "euclidean";
        
                AGNESResults.SimilarityFormula = ...
                    "1 / (1 + distance)";
        
                AGNESResults.DuplicateAggregation = ...
                    "mean with missing values omitted";
        
                AGNESResults.SavedAt = ...
                    datetime('now');
        
                %% Save MATLAB result file
                save( ...
                    matFilePath, ...
                    'AGNESResults');
        
                savedFiles(end + 1) = string(matFilePath);
        
                %% Write summary sheet
                summaryData = {
                    'Property',                'Value'
                    'Saved at',                char(AGNESResults.SavedAt)
                    'Source Excel file',       char(string(app.agnes_excel_path))
                    'Unique X-label count',    numel(uniqueXLabels)
                    'Unique Y-label count',    numel(uniqueYLabels)
                    'Linkage method',          'ward'
                    'Distance metric',         'euclidean'
                    'Similarity formula',      '1 / (1 + distance)'
                    'Duplicate aggregation',   'mean, omitting missing values'
                };
        
                writecell( ...
                    summaryData, ...
                    excelFilePath, ...
                    'Sheet', 'Summary');
        
                %% Write original worksheet
                if iscell(app.agnes_master_table)
        
                    originalDataForExcel = ...
                        app.agnes_master_table;
        
                    % Replace missing values with Excel-compatible blanks
                    for cellIndex = 1:numel(originalDataForExcel)
        
                        currentValue = ...
                            originalDataForExcel{cellIndex};
        
                        if isempty(currentValue)
                            originalDataForExcel{cellIndex} = '';
                            continue;
                        end
        
                        try
                            valueIsMissing = ismissing(currentValue);
                        catch
                            valueIsMissing = false;
                        end
        
                        if ~isempty(valueIsMissing) && ...
                                all(valueIsMissing(:))
        
                            originalDataForExcel{cellIndex} = '';
                            continue;
                        end
        
                        if isstring(currentValue)
        
                            currentValue( ...
                                ismissing(currentValue)) = "";
        
                            originalDataForExcel{cellIndex} = ...
                                currentValue;
                        end
                    end
        
                    originalDataForExcel{1, 1} = ...
                        'Y Label / X Label';
        
                    writecell( ...
                        originalDataForExcel, ...
                        excelFilePath, ...
                        'Sheet', 'Original Data');
                end
        
                %% Write averaged unique-label data
                groupedOutput = cell( ...
                    numel(uniqueYLabels) + 1, ...
                    numel(uniqueXLabels) + 1);
        
                groupedOutput{1, 1} = ...
                    'Y Label / X Label';
        
                groupedOutput(1, 2:end) = reshape( ...
                    cellstr(uniqueXLabels), ...
                    1, []);
        
                groupedOutput(2:end, 1) = reshape( ...
                    cellstr(uniqueYLabels), ...
                    [], 1);
        
                groupedOutput(2:end, 2:end) = ...
                    num2cell(groupedData);
        
                writecell( ...
                    groupedOutput, ...
                    excelFilePath, ...
                    'Sheet', 'Averaged Data');
        
                %% Write reordered clustered data
                reorderedOutput = cell( ...
                    numel(reorderedYLabels) + 1, ...
                    numel(reorderedXLabels) + 1);
        
                reorderedOutput{1, 1} = ...
                    'Y Label / X Label';
        
                reorderedOutput(1, 2:end) = reshape( ...
                    cellstr(reorderedXLabels), ...
                    1, []);
        
                reorderedOutput(2:end, 1) = reshape( ...
                    cellstr(reorderedYLabels), ...
                    [], 1);
        
                reorderedOutput(2:end, 2:end) = ...
                    num2cell(reorderedData);
        
                writecell( ...
                    reorderedOutput, ...
                    excelFilePath, ...
                    'Sheet', 'Clustered Data');
        
                %% Write X pairwise-distance matrix
                xDistanceOutput = cell( ...
                    numel(uniqueXLabels) + 1, ...
                    numel(uniqueXLabels) + 1);
        
                xDistanceOutput{1, 1} = ...
                    'X Label / X Label';
        
                xDistanceOutput(1, 2:end) = reshape( ...
                    cellstr(uniqueXLabels), ...
                    1, []);
        
                xDistanceOutput(2:end, 1) = reshape( ...
                    cellstr(uniqueXLabels), ...
                    [], 1);
        
                xDistanceOutput(2:end, 2:end) = ...
                    num2cell(xDistanceMatrix);
        
                writecell( ...
                    xDistanceOutput, ...
                    excelFilePath, ...
                    'Sheet', 'X Pairwise Distances');
        
                %% Write Y pairwise-distance matrix
                yDistanceOutput = cell( ...
                    numel(uniqueYLabels) + 1, ...
                    numel(uniqueYLabels) + 1);
        
                yDistanceOutput{1, 1} = ...
                    'Y Label / Y Label';
        
                yDistanceOutput(1, 2:end) = reshape( ...
                    cellstr(uniqueYLabels), ...
                    1, []);
        
                yDistanceOutput(2:end, 1) = reshape( ...
                    cellstr(uniqueYLabels), ...
                    [], 1);
        
                yDistanceOutput(2:end, 2:end) = ...
                    num2cell(yDistanceMatrix);
        
                writecell( ...
                    yDistanceOutput, ...
                    excelFilePath, ...
                    'Sheet', 'Y Pairwise Distances');
        
                %% Write X similarity matrix
                xSimilarityOutput = cell( ...
                    numel(uniqueXLabels) + 1, ...
                    numel(uniqueXLabels) + 1);
        
                xSimilarityOutput{1, 1} = ...
                    'X Label / X Label';
        
                xSimilarityOutput(1, 2:end) = reshape( ...
                    cellstr(uniqueXLabels), ...
                    1, []);
        
                xSimilarityOutput(2:end, 1) = reshape( ...
                    cellstr(uniqueXLabels), ...
                    [], 1);
        
                xSimilarityOutput(2:end, 2:end) = ...
                    num2cell(xSimilarityMatrix);
        
                writecell( ...
                    xSimilarityOutput, ...
                    excelFilePath, ...
                    'Sheet', 'X Similarities');
        
                %% Write Y similarity matrix
                ySimilarityOutput = cell( ...
                    numel(uniqueYLabels) + 1, ...
                    numel(uniqueYLabels) + 1);
        
                ySimilarityOutput{1, 1} = ...
                    'Y Label / Y Label';
        
                ySimilarityOutput(1, 2:end) = reshape( ...
                    cellstr(uniqueYLabels), ...
                    1, []);
        
                ySimilarityOutput(2:end, 1) = reshape( ...
                    cellstr(uniqueYLabels), ...
                    [], 1);
        
                ySimilarityOutput(2:end, 2:end) = ...
                    num2cell(ySimilarityMatrix);
        
                writecell( ...
                    ySimilarityOutput, ...
                    excelFilePath, ...
                    'Sheet', 'Y Similarities');
        
                %% Write X label order
                xOrderTable = table( ...
                    (1:numel(xOrder))', ...
                    xOrder(:), ...
                    reorderedXLabels(:), ...
                    'VariableNames', { ...
                        'ClusteredPosition', ...
                        'OriginalIndex', ...
                        'XLabel'});
        
                writetable( ...
                    xOrderTable, ...
                    excelFilePath, ...
                    'Sheet', 'X Label Order');
        
                %% Write Y label order
                yOrderTable = table( ...
                    (1:numel(yOrder))', ...
                    yOrder(:), ...
                    reorderedYLabels(:), ...
                    'VariableNames', { ...
                        'ClusteredPosition', ...
                        'OriginalIndex', ...
                        'YLabel'});
        
                writetable( ...
                    yOrderTable, ...
                    excelFilePath, ...
                    'Sheet', 'Y Label Order');
        
                %% Write X linkage tree
                if ~isempty(app.XClusterTree)
        
                    xTree = app.XClusterTree;
        
                    xTreeTable = table( ...
                        (1:size(xTree, 1))', ...
                        xTree(:, 1), ...
                        xTree(:, 2), ...
                        xTree(:, 3), ...
                        'VariableNames', { ...
                            'MergeNumber', ...
                            'Child1', ...
                            'Child2', ...
                            'Distance'});
        
                    writetable( ...
                        xTreeTable, ...
                        excelFilePath, ...
                        'Sheet', 'X Linkage Tree');
                end
        
                %% Write Y linkage tree
                if ~isempty(app.YClusterTree)
        
                    yTree = app.YClusterTree;
        
                    yTreeTable = table( ...
                        (1:size(yTree, 1))', ...
                        yTree(:, 1), ...
                        yTree(:, 2), ...
                        yTree(:, 3), ...
                        'VariableNames', { ...
                            'MergeNumber', ...
                            'Child1', ...
                            'Child2', ...
                            'Distance'});
        
                    writetable( ...
                        yTreeTable, ...
                        excelFilePath, ...
                        'Sheet', 'Y Linkage Tree');
                end
        
                savedFiles(end + 1) = string(excelFilePath);
        
                %% Show success message
                savedFileText = strjoin( ...
                    savedFiles, ...
                    newline);
        
                uialert(app.UIFigure, ...
                    sprintf(['AGNES plots and data were saved successfully:\n\n' ...
                             '%s'], ...
                            savedFileText), ...
                    'AGNES Results Saved', ...
                    'Icon', 'success');
        
            catch ME
                uialert(app.UIFigure, ...
                    sprintf('Could not save the AGNES results:\n%s', ...
                    ME.message), ...
                    'Save Error', ...
                    'Icon', 'error');
            end
        
            %% Restore application window
            app.UIFigure.WindowState = 'normal';
            drawnow;

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'SCADR';
            app.UIFigure.Icon = fullfile(pathToMLAPP, 'Application Resources', 'Toolbar Images', 'App Logo Corbin.jpg');
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Toolbar = uitoolbar(app.UIFigure);
            app.Toolbar_define_channels = uipushtool(app.Toolbar);
            app.Toolbar_define_channels.Tooltip = {'Go to marker edit settings page'};
            app.Toolbar_define_channels.ClickedCallback = createCallbackFcn(app, @Toolbar_define_channelsClicked, true);
            app.Toolbar_define_channels.Icon = fullfile(pathToMLAPP, 'Application Resources', 'Toolbar Images', 'Tune Settings.png');
            app.Toolbar_filter_data = uipushtool(app.Toolbar);
            app.Toolbar_filter_data.Tooltip = {'Process all data with current settings'};
            app.Toolbar_filter_data.ClickedCallback = createCallbackFcn(app, @Toolbar_filter_dataClicked, true);
            app.Toolbar_filter_data.Icon = fullfile(pathToMLAPP, 'Application Resources', 'Toolbar Images', 'filter_data_symbol.png');
            app.Toolbar_analysis_tab = uipushtool(app.Toolbar);
            app.Toolbar_analysis_tab.Tooltip = {'Go to results pages'};
            app.Toolbar_analysis_tab.ClickedCallback = createCallbackFcn(app, @Toolbar_analysis_tabClicked, true);
            app.Toolbar_analysis_tab.Icon = fullfile(pathToMLAPP, 'Application Resources', 'Toolbar Images', 'Plots.png');
            app.Toolbar_dimensionality_reduction_tab = uipushtool(app.Toolbar);
            app.Toolbar_dimensionality_reduction_tab.Tooltip = {'Go to dimensionality reduction pages'};
            app.Toolbar_dimensionality_reduction_tab.ClickedCallback = createCallbackFcn(app, @Toolbar_dimensionality_reduction_tabClicked, true);
            app.Toolbar_dimensionality_reduction_tab.Icon = fullfile(pathToMLAPP, 'Application Resources', 'Toolbar Images', 'tSNE shortcut.png');
            app.TabGroup = uitabgroup(app.UIFigure);
            app.LoadinDataTab = uitab(app.TabGroup);
            app.LoadinDataTab.Title = 'Load in Data';
            app.LoadindataLabel = uilabel(app.LoadinDataTab);
            app.LoadindataLabel.Text = 'Load in data';
            app.VisualisedataLabel = uilabel(app.LoadinDataTab);
            app.VisualisedataLabel.Text = 'Visualise data';
            app.AppSettingsButton = uibutton(app.LoadinDataTab, 'state');
            app.AppSettingsButton.ValueChangedFcn = createCallbackFcn(app, @AppSettingsButtonValueChanged, true);
            app.AppSettingsButton.Icon = fullfile(pathToMLAPP, 'Application Resources', 'Toolbar Images', 'More settings.png');
            app.AppSettingsButton.Text = 'App Settings';
            app.AppSettingsButton.Value = true;
            app.Load_In_Folder_Original = uibutton(app.LoadinDataTab, 'push');
            app.Load_In_Folder_Original.ButtonPushedFcn = createCallbackFcn(app, @Load_In_Folder_OriginalPushed, true);
            app.Load_In_Folder_Original.Text = {'Load in a dataset / '; 'library of datasets'};
            app.FileNameDropDown_LoadLabel = uilabel(app.LoadinDataTab);
            app.FileNameDropDown_LoadLabel.Text = 'File Name';
            app.FileNameDropDown_Load = uidropdown(app.LoadinDataTab);
            app.FileNameDropDown_Load.Items = {};
            app.FileNameDropDown_Load.ValueChangedFcn = createCallbackFcn(app, @FileNameDropDown_LoadValueChanged, true);
            app.FileNameDropDown_Load.Value = {};
            app.DatasetDropDown_load_label = uilabel(app.LoadinDataTab);
            app.DatasetDropDown_load_label.Text = 'Dataset:';
            app.DatasetDropDown_load = uidropdown(app.LoadinDataTab);
            app.DatasetDropDown_load.Items = {};
            app.DatasetDropDown_load.ValueChangedFcn = createCallbackFcn(app, @DatasetDropDown_loadValueChanged, true);
            app.DatasetDropDown_load.Value = {};
            app.UpdateTableViewer_Original = uibutton(app.LoadinDataTab, 'push');
            app.UpdateTableViewer_Original.ButtonPushedFcn = createCallbackFcn(app, @UpdateTableViewer_OriginalPushed, true);
            app.UpdateTableViewer_Original.Text = 'Update Table Viewer';
            app.FilesFoundListBoxLabel = uilabel(app.LoadinDataTab);
            app.FilesFoundListBoxLabel.Text = {'Files '; 'Found'};
            app.OriginalFilesListBox = uilistbox(app.LoadinDataTab);
            app.OriginalFilesListBox.Items = {};
            app.OriginalFilesListBox.Multiselect = 'on';
            app.OriginalFilesListBox.Value = {};
            app.Table_OriginalFiles = uitable(app.LoadinDataTab);
            app.Table_OriginalFiles.ColumnName = '';
            app.Table_OriginalFiles.ColumnRearrangeable = 'on';
            app.Table_OriginalFiles.RowName = {};
            app.Table_OriginalFiles.ColumnSortable = true;
            app.AddAnotherFile_Original = uibutton(app.LoadinDataTab, 'push');
            app.AddAnotherFile_Original.ButtonPushedFcn = createCallbackFcn(app, @AddAnotherFile_OriginalPushed, true);
            app.AddAnotherFile_Original.Text = {'Add Another'; 'File'};
            app.DeleteFile_Original = uibutton(app.LoadinDataTab, 'push');
            app.DeleteFile_Original.ButtonPushedFcn = createCallbackFcn(app, @DeleteFile_OriginalPushed, true);
            app.DeleteFile_Original.Text = 'Delete File';
            app.DefineChannelsTab = uitab(app.TabGroup);
            app.DefineChannelsTab.Title = 'Define Channels';
            app.RenameChannelsLabel = uilabel(app.DefineChannelsTab);
            app.RenameChannelsLabel.Text = 'Rename Channels';
            app.UpdatingthislistwillupdatethemarkerchannelslistLabel = uilabel(app.DefineChannelsTab);
            app.UpdatingthislistwillupdatethemarkerchannelslistLabel.Text = 'Updating this list will update the marker channels list';
            app.NormaliseanddataadjustmentoptionsLabel = uilabel(app.DefineChannelsTab);
            app.NormaliseanddataadjustmentoptionsLabel.Text = 'Normalise and data adjustment options';
            app.RenameachannelButton = uibutton(app.DefineChannelsTab, 'state');
            app.RenameachannelButton.Enable = 'off';
            app.RenameachannelButton.Text = 'Rename a channel';
            app.normalise_channel_buttons_panel = uipanel(app.DefineChannelsTab);
            app.normalise_channel_buttons_panel.Title = 'Select which channels to normalise';
            app.AddchanneltorenameButton = uibutton(app.DefineChannelsTab, 'push');
            app.AddchanneltorenameButton.ButtonPushedFcn = createCallbackFcn(app, @AddchanneltorenameButtonPushed, true);
            app.AddchanneltorenameButton.Enable = 'off';
            app.AddchanneltorenameButton.Text = 'Add channel to rename';
            app.RemoveselectednamesButton = uibutton(app.DefineChannelsTab, 'push');
            app.RemoveselectednamesButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveselectednamesButtonPushed, true);
            app.RemoveselectednamesButton.Enable = 'off';
            app.RemoveselectednamesButton.Text = 'Remove selected names';
            app.OriginalcolumnnamesLabel = uilabel(app.DefineChannelsTab);
            app.OriginalcolumnnamesLabel.Enable = 'off';
            app.OriginalcolumnnamesLabel.Text = {'Original '; 'column'; 'names'};
            app.OriginalcolumnnamesListBox = uilistbox(app.DefineChannelsTab);
            app.OriginalcolumnnamesListBox.Items = {};
            app.OriginalcolumnnamesListBox.Multiselect = 'on';
            app.OriginalcolumnnamesListBox.ValueChangedFcn = createCallbackFcn(app, @OriginalcolumnnamesListBoxValueChanged, true);
            app.OriginalcolumnnamesListBox.Enable = 'off';
            app.OriginalcolumnnamesListBox.Value = {};
            app.NewcolumnnamesLabel = uilabel(app.DefineChannelsTab);
            app.NewcolumnnamesLabel.Enable = 'off';
            app.NewcolumnnamesLabel.Text = {'New column'; 'names'};
            app.NewcolumnnamesListBox = uilistbox(app.DefineChannelsTab);
            app.NewcolumnnamesListBox.Items = {};
            app.NewcolumnnamesListBox.Multiselect = 'on';
            app.NewcolumnnamesListBox.ValueChangedFcn = createCallbackFcn(app, @NewcolumnnamesListBoxValueChanged, true);
            app.NewcolumnnamesListBox.Enable = 'off';
            app.NewcolumnnamesListBox.Value = {};
            app.MarkerchannelsListBoxLabel = uilabel(app.DefineChannelsTab);
            app.MarkerchannelsListBoxLabel.Text = {'Marker'; 'channels'};
            app.MarkerchannelsListBox = uilistbox(app.DefineChannelsTab);
            app.MarkerchannelsListBox.Items = {};
            app.MarkerchannelsListBox.Multiselect = 'on';
            app.MarkerchannelsListBox.Value = {};
            app.AddallavailablemarkersButton = uibutton(app.DefineChannelsTab, 'push');
            app.AddallavailablemarkersButton.ButtonPushedFcn = createCallbackFcn(app, @AddallavailablemarkersButtonPushed, true);
            app.AddallavailablemarkersButton.Text = 'Add all available markers';
            app.AddmarkerchannelfromoriginalcolumnnamesButton = uibutton(app.DefineChannelsTab, 'push');
            app.AddmarkerchannelfromoriginalcolumnnamesButton.ButtonPushedFcn = createCallbackFcn(app, @AddmarkerchannelfromoriginalcolumnnamesButtonPushed, true);
            app.AddmarkerchannelfromoriginalcolumnnamesButton.Text = {'Add marker channel from'; 'original column names'};
            app.RemoveMarkerButton = uibutton(app.DefineChannelsTab, 'push');
            app.RemoveMarkerButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveMarkerButtonPushed, true);
            app.RemoveMarkerButton.Text = {'Remove'; 'Marker'};
            app.LogDataListListBoxLabel = uilabel(app.DefineChannelsTab);
            app.LogDataListListBoxLabel.Text = 'Log Data List';
            app.LogDataListListBox = uilistbox(app.DefineChannelsTab);
            app.LogDataListListBox.Items = {};
            app.LogDataListListBox.Value = {};
            app.NormalisingchannelifrelevantLabel = uilabel(app.DefineChannelsTab);
            app.NormalisingchannelifrelevantLabel.Text = {'Normalising channel'; '(if relevant)'};
            app.NormalisingchannelifrelevantDropDown = uidropdown(app.DefineChannelsTab);
            app.NormalisingchannelifrelevantDropDown.Items = {};
            app.NormalisingchannelifrelevantDropDown.Value = {};
            app.Zero_adjust_switch = uiswitch(app.DefineChannelsTab, 'toggle');
            app.Zero_adjust_switch.ItemsData = [0 1];
            app.Zero_adjust_switch.ValueChangedFcn = createCallbackFcn(app, @Zero_adjust_switchValueChanged, true);
            app.Zero_adjust_switch.Value = 0;
            app.LogDataSwitch = uiswitch(app.DefineChannelsTab, 'toggle');
            app.LogDataSwitch.ItemsData = [0 1];
            app.LogDataSwitch.ValueChangedFcn = createCallbackFcn(app, @LogDataSwitchValueChanged, true);
            app.LogDataSwitch.Value = 0;
            app.NormaliseallButton = uibutton(app.DefineChannelsTab, 'push');
            app.NormaliseallButton.ButtonPushedFcn = createCallbackFcn(app, @NormaliseallButtonPushed, true);
            app.NormaliseallButton.Text = 'Normalise all';
            app.ZeroAdjustmandatoryifdataisbeingloggedSwitchLabel = uilabel(app.DefineChannelsTab);
            app.ZeroAdjustmandatoryifdataisbeingloggedSwitchLabel.Text = 'Zero Adjust? (mandatory if data is being logged)';
            app.LogDataSwitchLabel = uilabel(app.DefineChannelsTab);
            app.LogDataSwitchLabel.Text = 'Log Data?';
            app.QCChannelFilteringTab = uitab(app.TabGroup);
            app.QCChannelFilteringTab.Title = 'QC: Channel Filtering';
            app.Filter_by_channel_plot = uiaxes(app.QCChannelFilteringTab);
            xlabel(app.Filter_by_channel_plot, 'X')
            ylabel(app.Filter_by_channel_plot, 'Y')
            zlabel(app.Filter_by_channel_plot, 'Z')
            app.Filter_by_channel_plot.Visible = 'off';
            app.FilterchanneldataLabel = uilabel(app.QCChannelFilteringTab);
            app.FilterchanneldataLabel.Text = 'Filter channel data';
            app.DatasetDropDownLabel = uilabel(app.QCChannelFilteringTab);
            app.DatasetDropDownLabel.Text = 'Dataset:';
            app.Dataset_pick_channel_filter = uidropdown(app.QCChannelFilteringTab);
            app.Dataset_pick_channel_filter.Items = {};
            app.Dataset_pick_channel_filter.ValueChangedFcn = createCallbackFcn(app, @Dataset_pick_channel_filterValueChanged, true);
            app.Dataset_pick_channel_filter.Value = {};
            app.FilterbyquantileLabel = uilabel(app.QCChannelFilteringTab);
            app.FilterbyquantileLabel.Text = 'Filter by quantile';
            app.WhichfiltergoesfirstSwitchLabel = uilabel(app.QCChannelFilteringTab);
            app.WhichfiltergoesfirstSwitchLabel.Text = 'Which filter goes first?';
            app.SelectmarkerchannelstofilterusingquantilesListBoxLabel = uilabel(app.QCChannelFilteringTab);
            app.SelectmarkerchannelstofilterusingquantilesListBoxLabel.Text = 'Select marker channels to filter using quantiles';
            app.WhichfiltergoesfirstSwitch = uiswitch(app.QCChannelFilteringTab, 'toggle');
            app.WhichfiltergoesfirstSwitch.Items = {'Absolute', 'Quantile'};
            app.WhichfiltergoesfirstSwitch.ValueChangedFcn = createCallbackFcn(app, @WhichfiltergoesfirstSwitchValueChanged, true);
            app.WhichfiltergoesfirstSwitch.Value = 'Absolute';
            app.SelectmarkerchannelstofilterusingquantilesListBox = uilistbox(app.QCChannelFilteringTab);
            app.SelectmarkerchannelstofilterusingquantilesListBox.Items = {};
            app.SelectmarkerchannelstofilterusingquantilesListBox.Multiselect = 'on';
            app.SelectmarkerchannelstofilterusingquantilesListBox.ValueChangedFcn = createCallbackFcn(app, @SelectmarkerchannelstofilterusingquantilesListBoxValueChanged, true);
            app.SelectmarkerchannelstofilterusingquantilesListBox.Value = {};
            app.PickwhichmarkertoplotLabel = uilabel(app.QCChannelFilteringTab);
            app.PickwhichmarkertoplotLabel.Text = 'Pick which marker to plot';
            app.PickwhichmarkertoplotDropDown = uidropdown(app.QCChannelFilteringTab);
            app.PickwhichmarkertoplotDropDown.Items = {};
            app.PickwhichmarkertoplotDropDown.ValueChangedFcn = createCallbackFcn(app, @PickwhichmarkertoplotDropDownValueChanged, true);
            app.PickwhichmarkertoplotDropDown.Value = {};
            app.SelectwhichvariantstoviewListBoxLabel = uilabel(app.QCChannelFilteringTab);
            app.SelectwhichvariantstoviewListBoxLabel.Text = 'Select which variants to view';
            app.Variants_ListBox_channel_filtering = uilistbox(app.QCChannelFilteringTab);
            app.Variants_ListBox_channel_filtering.Items = {};
            app.Variants_ListBox_channel_filtering.Multiselect = 'on';
            app.Variants_ListBox_channel_filtering.ValueChangedFcn = createCallbackFcn(app, @Variants_ListBox_channel_filteringValueChanged, true);
            app.Variants_ListBox_channel_filtering.Value = {};
            app.MoreSettingsButton_channel_filter = uibutton(app.QCChannelFilteringTab, 'state');
            app.MoreSettingsButton_channel_filter.ValueChangedFcn = createCallbackFcn(app, @MoreSettingsButton_channel_filterValueChanged, true);
            app.MoreSettingsButton_channel_filter.Icon = fullfile(pathToMLAPP, 'Application Resources', 'Toolbar Images', 'More settings.png');
            app.MoreSettingsButton_channel_filter.Text = 'More Settings';
            app.UpperquantileEditFieldLabel = uilabel(app.QCChannelFilteringTab);
            app.UpperquantileEditFieldLabel.Text = 'Upper quantile';
            app.UpperquantileEditField = uieditfield(app.QCChannelFilteringTab, 'numeric');
            app.UpperquantileEditField.Limits = [0 100];
            app.UpperquantileEditField.Value = 100;
            app.FilterSliderPanel = uipanel(app.QCChannelFilteringTab);
            app.FilterSliderPanel.Title = 'Filter Slider';
            app.UpperboundEditFieldLabel = uilabel(app.QCChannelFilteringTab);
            app.UpperboundEditFieldLabel.Text = 'Upper bound';
            app.UpperboundEditField = uieditfield(app.QCChannelFilteringTab, 'numeric');
            app.UpperboundEditField.ValueChangedFcn = createCallbackFcn(app, @UpperboundEditFieldValueChanged, true);
            app.ResetselectedButton = uibutton(app.QCChannelFilteringTab, 'push');
            app.ResetselectedButton.ButtonPushedFcn = createCallbackFcn(app, @ResetselectedButtonPushed, true);
            app.ResetselectedButton.Text = 'Reset selected';
            app.LowerboundEditFieldLabel = uilabel(app.QCChannelFilteringTab);
            app.LowerboundEditFieldLabel.Text = 'Lower bound';
            app.LowerboundEditField = uieditfield(app.QCChannelFilteringTab, 'numeric');
            app.LowerboundEditField.ValueChangedFcn = createCallbackFcn(app, @LowerboundEditFieldValueChanged, true);
            app.LowerquantileEditFieldLabel = uilabel(app.QCChannelFilteringTab);
            app.LowerquantileEditFieldLabel.Text = 'Lower quantile';
            app.LowerquantileEditField = uieditfield(app.QCChannelFilteringTab, 'numeric');
            app.LowerquantileEditField.Limits = [0 100];
            app.UpdateabsolutechannelfilterButton = uibutton(app.QCChannelFilteringTab, 'push');
            app.UpdateabsolutechannelfilterButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateabsolutechannelfilterButtonPushed, true);
            app.UpdateabsolutechannelfilterButton.Text = 'Update absolute channel filter';
            app.UpdatequantilechannelfilterButton = uibutton(app.QCChannelFilteringTab, 'push');
            app.UpdatequantilechannelfilterButton.ButtonPushedFcn = createCallbackFcn(app, @UpdatequantilechannelfilterButtonPushed, true);
            app.UpdatequantilechannelfilterButton.Text = 'Update quantile channel filter';
            app.QCScatterPlotFilteringTab = uitab(app.TabGroup);
            app.QCScatterPlotFilteringTab.Title = 'QC: Scatter Plot Filtering';
            app.axes_scatter_filter_plot = uiaxes(app.QCScatterPlotFilteringTab);
            zlabel(app.axes_scatter_filter_plot, 'Z')
            app.FilterscatterplotLabel = uilabel(app.QCScatterPlotFilteringTab);
            app.FilterscatterplotLabel.Text = 'Filter scatter plot';
            app.DatasetDropDown_2Label_2 = uilabel(app.QCScatterPlotFilteringTab);
            app.DatasetDropDown_2Label_2.Text = 'Dataset:';
            app.Dataset_pick_scatter_filter = uidropdown(app.QCScatterPlotFilteringTab);
            app.Dataset_pick_scatter_filter.Items = {};
            app.Dataset_pick_scatter_filter.ValueChangedFcn = createCallbackFcn(app, @Dataset_pick_scatter_filterValueChanged, true);
            app.Dataset_pick_scatter_filter.Value = {};
            app.SelectwhichvariantstoviewListBox_2Label = uilabel(app.QCScatterPlotFilteringTab);
            app.SelectwhichvariantstoviewListBox_2Label.Text = 'Select which variants to view';
            app.Variants_ListBox_scatter_filtering = uilistbox(app.QCScatterPlotFilteringTab);
            app.Variants_ListBox_scatter_filtering.Items = {};
            app.Variants_ListBox_scatter_filtering.Multiselect = 'on';
            app.Variants_ListBox_scatter_filtering.ValueChangedFcn = createCallbackFcn(app, @Variants_ListBox_scatter_filteringValueChanged, true);
            app.Variants_ListBox_scatter_filtering.Value = {};
            app.XaxischannelLabel = uilabel(app.QCScatterPlotFilteringTab);
            app.XaxischannelLabel.Text = 'X axis marker';
            app.Xaxismarker_scatter_filter = uidropdown(app.QCScatterPlotFilteringTab);
            app.Xaxismarker_scatter_filter.Items = {};
            app.Xaxismarker_scatter_filter.ValueChangedFcn = createCallbackFcn(app, @Xaxismarker_scatter_filterValueChanged, true);
            app.Xaxismarker_scatter_filter.Value = {};
            app.YaxismarkerDropDownLabel_2 = uilabel(app.QCScatterPlotFilteringTab);
            app.YaxismarkerDropDownLabel_2.Text = 'Y axis marker';
            app.Yaxismarker_scatter_filter = uidropdown(app.QCScatterPlotFilteringTab);
            app.Yaxismarker_scatter_filter.Items = {};
            app.Yaxismarker_scatter_filter.ValueChangedFcn = createCallbackFcn(app, @Yaxismarker_scatter_filterValueChanged, true);
            app.Yaxismarker_scatter_filter.Value = {};
            app.PlottypeDropDown_2Label = uilabel(app.QCScatterPlotFilteringTab);
            app.PlottypeDropDown_2Label.Text = 'Plot type';
            app.Filter_scatter_plot_type = uidropdown(app.QCScatterPlotFilteringTab);
            app.Filter_scatter_plot_type.Items = {'scatter', 'bin scatter'};
            app.Filter_scatter_plot_type.ValueChangedFcn = createCallbackFcn(app, @Filter_scatter_plot_typeValueChanged, true);
            app.Filter_scatter_plot_type.Value = 'scatter';
            app.BinwidthEditField_3Label = uilabel(app.QCScatterPlotFilteringTab);
            app.BinwidthEditField_3Label.Text = 'Bin width';
            app.Binwidth_scatter_filter = uieditfield(app.QCScatterPlotFilteringTab, 'numeric');
            app.Binwidth_scatter_filter.Limits = [0 250];
            app.Binwidth_scatter_filter.ValueChangedFcn = createCallbackFcn(app, @Binwidth_scatter_filterValueChanged, true);
            app.Binwidth_scatter_filter.Value = 250;
            app.Filter_scatter_do_bin_width = uiswitch(app.QCScatterPlotFilteringTab, 'slider');
            app.Filter_scatter_do_bin_width.Items = {'Manual', 'Auto'};
            app.Filter_scatter_do_bin_width.ValueChangedFcn = createCallbackFcn(app, @Filter_scatter_do_bin_widthValueChanged, true);
            app.Filter_scatter_do_bin_width.Value = 'Auto';
            app.DrawPolygonButton = uibutton(app.QCScatterPlotFilteringTab, 'state');
            app.DrawPolygonButton.ValueChangedFcn = createCallbackFcn(app, @DrawPolygonButtonValueChanged, true);
            app.DrawPolygonButton.Text = 'Draw Polygon';
            app.SavepolygonfilterButton = uibutton(app.QCScatterPlotFilteringTab, 'push');
            app.SavepolygonfilterButton.ButtonPushedFcn = createCallbackFcn(app, @SavepolygonfilterButtonPushed, true);
            app.SavepolygonfilterButton.Text = 'Save polygon filter';
            app.ClearpolygonButton = uibutton(app.QCScatterPlotFilteringTab, 'push');
            app.ClearpolygonButton.ButtonPushedFcn = createCallbackFcn(app, @ClearpolygonButtonPushed, true);
            app.ClearpolygonButton.Text = 'Clear polygon';
            app.ProcessalldataTab = uitab(app.TabGroup);
            app.ProcessalldataTab.Title = 'Process all data';
            app.OutputdatadirectoryEditFieldLabel = uilabel(app.ProcessalldataTab);
            app.OutputdatadirectoryEditFieldLabel.Text = 'Output data directory';
            app.OutputdatadirectoryEditField = uieditfield(app.ProcessalldataTab, 'text');
            app.ChangeoutputdatadirectoryButton = uibutton(app.ProcessalldataTab, 'push');
            app.ChangeoutputdatadirectoryButton.Icon = fullfile(pathToMLAPP, 'Application Resources', 'Toolbar Images', 'Folder Explorer.png');
            app.ChangeoutputdatadirectoryButton.Text = 'Change output data directory';
            app.DatafiletypeDropDownLabel = uilabel(app.ProcessalldataTab);
            app.DatafiletypeDropDownLabel.Text = 'Data file type';
            app.DatafiletypeDropDown = uidropdown(app.ProcessalldataTab);
            app.DatafiletypeDropDown.Items = {};
            app.DatafiletypeDropDown.Value = {};
            app.OutputplotsdirectoryLabel = uilabel(app.ProcessalldataTab);
            app.OutputplotsdirectoryLabel.Enable = 'off';
            app.OutputplotsdirectoryLabel.Text = 'Output plots directory';
            app.OutputplotsdirectoryEditField = uieditfield(app.ProcessalldataTab, 'text');
            app.OutputplotsdirectoryEditField.Enable = 'off';
            app.ChangeoutputplotsdirectoryButton = uibutton(app.ProcessalldataTab, 'push');
            app.ChangeoutputplotsdirectoryButton.Icon = fullfile(pathToMLAPP, 'Application Resources', 'Toolbar Images', 'Folder Explorer.png');
            app.ChangeoutputplotsdirectoryButton.Enable = 'off';
            app.ChangeoutputplotsdirectoryButton.Text = 'Change output plots directory';
            app.FilterorderPanel = uipanel(app.ProcessalldataTab);
            app.FilterorderPanel.Title = 'Filter order';
            app.FirstFilterDropDownLabel = uilabel(app.FilterorderPanel);
            app.FirstFilterDropDownLabel.Text = 'First Filter';
            app.filter1 = uidropdown(app.FilterorderPanel);
            app.filter1.Items = {'Absolute Channel Filter', 'Quantile Channel Filter', 'Scatter 2D Filter'};
            app.filter1.ItemsData = {'Absolute', 'Quantile', 'Scatter'};
            app.filter1.Value = 'Scatter';
            app.SecondFilterDropDownLabel = uilabel(app.FilterorderPanel);
            app.SecondFilterDropDownLabel.Text = 'Second Filter';
            app.filter2 = uidropdown(app.FilterorderPanel);
            app.filter2.Items = {'Absolute Channel Filter', 'Quantile Channel Filter', 'Scatter 2D Filter'};
            app.filter2.ItemsData = {'Absolute', 'Quantile', 'Scatter'};
            app.filter2.Value = 'Quantile';
            app.ThirdFilterDropDownLabel = uilabel(app.FilterorderPanel);
            app.ThirdFilterDropDownLabel.Text = 'Third Filter';
            app.filter3 = uidropdown(app.FilterorderPanel);
            app.filter3.Items = {'Absolute Channel Filter', 'Quantile Channel Filter', 'Scatter 2D Filter'};
            app.filter3.ItemsData = {'Absolute', 'Quantile', 'Scatter'};
            app.filter3.Value = 'Absolute';
            app.FilterandprocessdataButton = uibutton(app.ProcessalldataTab, 'push');
            app.FilterandprocessdataButton.ButtonPushedFcn = createCallbackFcn(app, @FilterandprocessdataButtonPushed, true);
            app.FilterandprocessdataButton.Text = 'Filter and process data';
            app.D13Tab = uitab(app.TabGroup);
            app.D13Tab.Title = '1-3D Plot ';
            app.D2D3Don2DPlotLabel = uilabel(app.D13Tab);
            app.D2D3Don2DPlotLabel.Text = '1D, 2D, 3D (on 2D) Plot';
            app.DatasetLabel_4 = uilabel(app.D13Tab);
            app.DatasetLabel_4.Text = 'Dataset:';
            app.Dataset_pick_ND_scatter = uidropdown(app.D13Tab);
            app.Dataset_pick_ND_scatter.Items = {};
            app.Dataset_pick_ND_scatter.ValueChangedFcn = createCallbackFcn(app, @Dataset_pick_ND_scatterValueChanged, true);
            app.Dataset_pick_ND_scatter.Value = {};
            app.Panel_ND_scatter = uipanel(app.D13Tab);
            app.Panel_ND_scatter.Title = 'Number of cells:';
            app.SelectwhichvariantstoplotLabel_6 = uilabel(app.D13Tab);
            app.SelectwhichvariantstoplotLabel_6.Text = 'Select which variants to plot';
            app.ND_variant_select_box = uilistbox(app.D13Tab);
            app.ND_variant_select_box.Items = {};
            app.ND_variant_select_box.Multiselect = 'on';
            app.ND_variant_select_box.ValueChangedFcn = createCallbackFcn(app, @ND_variant_select_boxValueChanged, true);
            app.ND_variant_select_box.DoubleClickedFcn = createCallbackFcn(app, @ND_variant_select_boxDoubleClicked, true);
            app.ND_variant_select_box.Value = {};
            app.XaxismarkerDropDownLabel_4 = uilabel(app.D13Tab);
            app.XaxismarkerDropDownLabel_4.Text = 'X axis marker';
            app.Xaxismarker_ND_scatter = uidropdown(app.D13Tab);
            app.Xaxismarker_ND_scatter.Items = {};
            app.Xaxismarker_ND_scatter.ValueChangedFcn = createCallbackFcn(app, @Xaxismarker_ND_scatterValueChanged, true);
            app.Xaxismarker_ND_scatter.Value = {};
            app.YaxismarkerDropDownLabel_6 = uilabel(app.D13Tab);
            app.YaxismarkerDropDownLabel_6.Text = 'Y axis marker';
            app.Yaxismarker_ND_scatter = uidropdown(app.D13Tab);
            app.Yaxismarker_ND_scatter.Items = {};
            app.Yaxismarker_ND_scatter.ValueChangedFcn = createCallbackFcn(app, @Yaxismarker_ND_scatterValueChanged, true);
            app.Yaxismarker_ND_scatter.Value = {};
            app.ZaxismarkerDropDownLabel_3 = uilabel(app.D13Tab);
            app.ZaxismarkerDropDownLabel_3.Text = 'Z axis marker';
            app.Zaxismarker_ND_scatter = uidropdown(app.D13Tab);
            app.Zaxismarker_ND_scatter.Items = {''};
            app.Zaxismarker_ND_scatter.ValueChangedFcn = createCallbackFcn(app, @Zaxismarker_ND_scatterValueChanged, true);
            app.Zaxismarker_ND_scatter.Value = '';
            app.MoreSettingsButton_1ND = uibutton(app.D13Tab, 'state');
            app.MoreSettingsButton_1ND.ValueChangedFcn = createCallbackFcn(app, @MoreSettingsButton_1NDValueChanged, true);
            app.MoreSettingsButton_1ND.Icon = fullfile(pathToMLAPP, 'Application Resources', 'Toolbar Images', 'More settings.png');
            app.MoreSettingsButton_1ND.Text = '1D Plot Settings';
            app.MoreSettingsButton_2ND = uibutton(app.D13Tab, 'state');
            app.MoreSettingsButton_2ND.ValueChangedFcn = createCallbackFcn(app, @MoreSettingsButton_2NDValueChanged, true);
            app.MoreSettingsButton_2ND.Icon = fullfile(pathToMLAPP, 'Application Resources', 'Toolbar Images', 'More settings.png');
            app.MoreSettingsButton_2ND.Text = '2D Plot Settings';
            app.PopoutButton_nd_plot = uibutton(app.D13Tab, 'push');
            app.PopoutButton_nd_plot.ButtonPushedFcn = createCallbackFcn(app, @PopoutButton_nd_plotPushed, true);
            app.PopoutButton_nd_plot.Text = 'Pop out';
            app.Dploton2DTab = uitab(app.TabGroup);
            app.Dploton2DTab.Title = '3D plot on 2D';
            app.Dfitson2DsurfaceLabel = uilabel(app.Dploton2DTab);
            app.Dfitson2DsurfaceLabel.Text = '3D fits on 2D surface';
            app.DatasetLabel_Dataset_pick_3D_scatter_on_2d = uilabel(app.Dploton2DTab);
            app.DatasetLabel_Dataset_pick_3D_scatter_on_2d.Text = 'Dataset:';
            app.Dataset_pick_3D_scatter_on_2d = uidropdown(app.Dploton2DTab);
            app.Dataset_pick_3D_scatter_on_2d.Items = {};
            app.Dataset_pick_3D_scatter_on_2d.ValueChangedFcn = createCallbackFcn(app, @Dataset_pick_3D_scatter_on_2dValueChanged, true);
            app.Dataset_pick_3D_scatter_on_2d.Value = {};
            app.Panel_3D_scatter_on_2D_plot = uipanel(app.Dploton2DTab);
            app.SelectwhichvariantstoplotLabel_5 = uilabel(app.Dploton2DTab);
            app.SelectwhichvariantstoplotLabel_5.Text = 'Select which variants to plot';
            app.Scatter_3d_fit_on_2d_variant_select_box = uilistbox(app.Dploton2DTab);
            app.Scatter_3d_fit_on_2d_variant_select_box.Items = {};
            app.Scatter_3d_fit_on_2d_variant_select_box.Multiselect = 'on';
            app.Scatter_3d_fit_on_2d_variant_select_box.ValueChangedFcn = createCallbackFcn(app, @Scatter_3d_fit_on_2d_variant_select_boxValueChanged, true);
            app.Scatter_3d_fit_on_2d_variant_select_box.DoubleClickedFcn = createCallbackFcn(app, @Scatter_3d_fit_on_2d_variant_select_boxDoubleClicked, true);
            app.Scatter_3d_fit_on_2d_variant_select_box.Value = {};
            app.XaxismarkerDropDownLabel_3 = uilabel(app.Dploton2DTab);
            app.XaxismarkerDropDownLabel_3.Text = 'X axis marker';
            app.Xaxismarker_3d_scatter_on_2d = uidropdown(app.Dploton2DTab);
            app.Xaxismarker_3d_scatter_on_2d.Items = {};
            app.Xaxismarker_3d_scatter_on_2d.ValueChangedFcn = createCallbackFcn(app, @Xaxismarker_3d_scatter_on_2dValueChanged, true);
            app.Xaxismarker_3d_scatter_on_2d.Value = {};
            app.YaxismarkerDropDownLabel_5 = uilabel(app.Dploton2DTab);
            app.YaxismarkerDropDownLabel_5.Text = 'Y axis marker';
            app.Yaxismarker_3d_scatter_on_2d = uidropdown(app.Dploton2DTab);
            app.Yaxismarker_3d_scatter_on_2d.Items = {};
            app.Yaxismarker_3d_scatter_on_2d.ValueChangedFcn = createCallbackFcn(app, @Yaxismarker_3d_scatter_on_2dValueChanged, true);
            app.Yaxismarker_3d_scatter_on_2d.Value = {};
            app.ZaxismarkerDropDownLabel_2 = uilabel(app.Dploton2DTab);
            app.ZaxismarkerDropDownLabel_2.Text = 'Z axis marker';
            app.Zaxismarker_3d_scatter_on_2d = uidropdown(app.Dploton2DTab);
            app.Zaxismarker_3d_scatter_on_2d.Items = {};
            app.Zaxismarker_3d_scatter_on_2d.ValueChangedFcn = createCallbackFcn(app, @Zaxismarker_3d_scatter_on_2dValueChanged, true);
            app.Zaxismarker_3d_scatter_on_2d.Value = {};
            app.UpdateplotButton_3d_on_2d = uibutton(app.Dploton2DTab, 'push');
            app.UpdateplotButton_3d_on_2d.ButtonPushedFcn = createCallbackFcn(app, @UpdateplotButton_3d_on_2dPushed, true);
            app.UpdateplotButton_3d_on_2d.Text = 'Update plot';
            app.MoreSettingsButton_3D_fit_on_2D = uibutton(app.Dploton2DTab, 'state');
            app.MoreSettingsButton_3D_fit_on_2D.ValueChangedFcn = createCallbackFcn(app, @MoreSettingsButton_3D_fit_on_2DValueChanged, true);
            app.MoreSettingsButton_3D_fit_on_2D.Icon = fullfile(pathToMLAPP, 'Application Resources', 'Toolbar Images', 'More settings.png');
            app.MoreSettingsButton_3D_fit_on_2D.Text = 'More Settings';
            app.PopoutButton_3D_fit_on_2D = uibutton(app.Dploton2DTab, 'push');
            app.PopoutButton_3D_fit_on_2D.ButtonPushedFcn = createCallbackFcn(app, @PopoutButton_3D_fit_on_2DPushed, true);
            app.PopoutButton_3D_fit_on_2D.Text = 'Pop out';
            app.CorrelationGridTab = uitab(app.TabGroup);
            app.CorrelationGridTab.Title = 'Correlation Grid';
            app.VariantChannelCorrelationLabel = uilabel(app.CorrelationGridTab);
            app.VariantChannelCorrelationLabel.Text = 'Variant-Channel Correlation';
            app.DatasetLabel_2 = uilabel(app.CorrelationGridTab);
            app.DatasetLabel_2.Text = 'Dataset: ';
            app.Dataset_pick_variant_correlation = uidropdown(app.CorrelationGridTab);
            app.Dataset_pick_variant_correlation.Items = {};
            app.Dataset_pick_variant_correlation.ValueChangedFcn = createCallbackFcn(app, @Dataset_pick_variant_correlationValueChanged, true);
            app.Dataset_pick_variant_correlation.Value = {};
            app.panel_variant_marker_correlation = uipanel(app.CorrelationGridTab);
            app.SelectwhichvariantstoplotLabel_3 = uilabel(app.CorrelationGridTab);
            app.SelectwhichvariantstoplotLabel_3.Text = 'Select which variants to plot';
            app.Correlation_plot_variant_select_box = uilistbox(app.CorrelationGridTab);
            app.Correlation_plot_variant_select_box.Items = {};
            app.Correlation_plot_variant_select_box.ValueChangedFcn = createCallbackFcn(app, @Correlation_plot_variant_select_boxValueChanged, true);
            app.Correlation_plot_variant_select_box.DoubleClickedFcn = createCallbackFcn(app, @Correlation_plot_variant_select_boxDoubleClicked, true);
            app.Correlation_plot_variant_select_box.Value = {};
            app.SelectwhichmarkerstoincludeListBoxLabel = uilabel(app.CorrelationGridTab);
            app.SelectwhichmarkerstoincludeListBoxLabel.Text = 'Select which markers to include';
            app.Marker_pick_correlation_plot_list_box = uilistbox(app.CorrelationGridTab);
            app.Marker_pick_correlation_plot_list_box.Items = {};
            app.Marker_pick_correlation_plot_list_box.Multiselect = 'on';
            app.Marker_pick_correlation_plot_list_box.ValueChangedFcn = createCallbackFcn(app, @Marker_pick_correlation_plot_list_boxValueChanged, true);
            app.Marker_pick_correlation_plot_list_box.Value = {};
            app.UpdatePlot_vc_grid = uibutton(app.CorrelationGridTab, 'push');
            app.UpdatePlot_vc_grid.ButtonPushedFcn = createCallbackFcn(app, @UpdatePlot_vc_gridButtonPushed, true);
            app.UpdatePlot_vc_grid.Text = 'Update Plot';
            app.correlation_table_vc_grid = uibutton(app.CorrelationGridTab, 'push');
            app.correlation_table_vc_grid.ButtonPushedFcn = createCallbackFcn(app, @correlation_table_vc_gridPushed, true);
            app.correlation_table_vc_grid.Text = {'Generate correlation'; 'tables'};
            app.MoreSettings_variant_channel_grid = uibutton(app.CorrelationGridTab, 'state');
            app.MoreSettings_variant_channel_grid.ValueChangedFcn = createCallbackFcn(app, @MoreSettings_variant_channel_gridValueChanged, true);
            app.MoreSettings_variant_channel_grid.Icon = fullfile(pathToMLAPP, 'Application Resources', 'Toolbar Images', 'More settings.png');
            app.MoreSettings_variant_channel_grid.Text = 'More Settings';
            app.Popout_vc_grid = uibutton(app.CorrelationGridTab, 'push');
            app.Popout_vc_grid.ButtonPushedFcn = createCallbackFcn(app, @Popout_vc_gridPushed, true);
            app.Popout_vc_grid.Text = 'Pop out figure';
            app.CorrelationTestsTab = uitab(app.TabGroup);
            app.CorrelationTestsTab.Title = 'Correlation Tests';
            app.CorrelationtestsLabel = uilabel(app.CorrelationTestsTab);
            app.CorrelationtestsLabel.Text = 'Correlation tests';
            app.Dataset_pick_correlation_tests_label = uilabel(app.CorrelationTestsTab);
            app.Dataset_pick_correlation_tests_label.Text = 'Dataset: ';
            app.Dataset_pick_correlation_tests = uidropdown(app.CorrelationTestsTab);
            app.Dataset_pick_correlation_tests.Items = {};
            app.Dataset_pick_correlation_tests.ValueChangedFcn = createCallbackFcn(app, @Dataset_pick_correlation_testsValueChanged, true);
            app.Dataset_pick_correlation_tests.Value = {};
            app.correlation_test_plot_panel = uipanel(app.CorrelationTestsTab);
            app.correlation_tests_variant_select_Label = uilabel(app.CorrelationTestsTab);
            app.correlation_tests_variant_select_Label.Text = 'Select which variants to plot';
            app.correlation_tests_variant_select_box = uilistbox(app.CorrelationTestsTab);
            app.correlation_tests_variant_select_box.Items = {};
            app.correlation_tests_variant_select_box.Multiselect = 'on';
            app.correlation_tests_variant_select_box.ValueChangedFcn = createCallbackFcn(app, @correlation_tests_variant_select_boxValueChanged, true);
            app.correlation_tests_variant_select_box.Value = {};
            app.marker_pick_correlation_tests_label = uilabel(app.CorrelationTestsTab);
            app.marker_pick_correlation_tests_label.Text = 'Select which markers to include';
            app.marker_pick_correlation_tests = uilistbox(app.CorrelationTestsTab);
            app.marker_pick_correlation_tests.Items = {};
            app.marker_pick_correlation_tests.Multiselect = 'on';
            app.marker_pick_correlation_tests.Value = {};
            app.UpdateplotButton = uibutton(app.CorrelationTestsTab, 'push');
            app.UpdateplotButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateplotButtonPushed, true);
            app.UpdateplotButton.Text = 'Update plot';
            app.correlation_comparison_table_button = uibutton(app.CorrelationTestsTab, 'push');
            app.correlation_comparison_table_button.ButtonPushedFcn = createCallbackFcn(app, @correlation_comparison_table_buttonPushed, true);
            app.correlation_comparison_table_button.Text = {'Generate correlation'; 'comparison table'};
            app.correlation_test_table_panel = uipanel(app.CorrelationTestsTab);
            app.correlation_test_table_panel.Title = 'Normality test';
            app.correlation_test_table = uitable(app.correlation_test_table_panel);
            app.correlation_test_table.ColumnName = {'Cell line'; 'Marker'; 'Kolmogorov-Smirnov'; 'Lilliefors'; 'Chi-square'; 'Anderson-Darling'; 'Jarque-Bera'; 'Shapiro-Wilk'; 'D''Agostino-Pearson'};
            app.correlation_test_table.RowName = {};
            app.Update_normality_test_table_button = uibutton(app.CorrelationTestsTab, 'push');
            app.Update_normality_test_table_button.ButtonPushedFcn = createCallbackFcn(app, @Update_normality_test_table_buttonPushed, true);
            app.Update_normality_test_table_button.Text = {'Update normality'; 'test table'};
            app.PopoutnormalitytesttableButton = uibutton(app.CorrelationTestsTab, 'push');
            app.PopoutnormalitytesttableButton.ButtonPushedFcn = createCallbackFcn(app, @PopoutnormalitytesttableButtonPushed, true);
            app.PopoutnormalitytesttableButton.Text = {'Pop out '; 'normality test table'};
            app.MoreSettings_corrtests = uibutton(app.CorrelationTestsTab, 'state');
            app.MoreSettings_corrtests.ValueChangedFcn = createCallbackFcn(app, @MoreSettings_corrtestsValueChanged, true);
            app.MoreSettings_corrtests.Icon = fullfile(pathToMLAPP, 'Application Resources', 'Toolbar Images', 'More settings.png');
            app.MoreSettings_corrtests.Text = 'More Settings';
            app.PopoutplotButton_correlation_tests = uibutton(app.CorrelationTestsTab, 'push');
            app.PopoutplotButton_correlation_tests.ButtonPushedFcn = createCallbackFcn(app, @PopoutplotButton_correlation_testsPushed, true);
            app.PopoutplotButton_correlation_tests.Text = 'Pop out plot';
            app.Hyperlink3 = uihyperlink(app.CorrelationTestsTab);
            app.Hyperlink3.URL = 'https://garstats.wordpress.com/2019/06/17/compindcorr/';
            app.Hyperlink3.Text = 'What can bootstrapping correlations do for flow/cytof data?';
            app.ExpressionSegmentationTab = uitab(app.TabGroup);
            app.ExpressionSegmentationTab.Title = 'Expression Segmentation';
            app.ExpressionvsMarkerCorrelationLabel = uilabel(app.ExpressionSegmentationTab);
            app.ExpressionvsMarkerCorrelationLabel.Text = 'Expression vs Marker Correlation';
            app.DatasetLabel_3 = uilabel(app.ExpressionSegmentationTab);
            app.DatasetLabel_3.Text = 'Dataset: ';
            app.Dataset_pick_expression_marker = uidropdown(app.ExpressionSegmentationTab);
            app.Dataset_pick_expression_marker.Items = {};
            app.Dataset_pick_expression_marker.ValueChangedFcn = createCallbackFcn(app, @Dataset_pick_expression_markerValueChanged, true);
            app.Dataset_pick_expression_marker.Value = {};
            app.expression_marker_panel = uipanel(app.ExpressionSegmentationTab);
            app.expression_marker_panel.Title = 'Largest smoothing window size:';
            app.SelectwhichvariantstoplotLabel_4 = uilabel(app.ExpressionSegmentationTab);
            app.SelectwhichvariantstoplotLabel_4.Text = 'Select which variants to plot';
            app.Expression_marker_plot_variant_select_box = uilistbox(app.ExpressionSegmentationTab);
            app.Expression_marker_plot_variant_select_box.Items = {};
            app.Expression_marker_plot_variant_select_box.Multiselect = 'on';
            app.Expression_marker_plot_variant_select_box.ValueChangedFcn = createCallbackFcn(app, @Expression_marker_plot_variant_select_boxValueChanged, true);
            app.Expression_marker_plot_variant_select_box.DoubleClickedFcn = createCallbackFcn(app, @Expression_marker_plot_variant_select_boxDoubleClicked, true);
            app.Expression_marker_plot_variant_select_box.Value = {};
            app.XaxismarkerLabel = uilabel(app.ExpressionSegmentationTab);
            app.XaxismarkerLabel.Text = 'X axis marker';
            app.Xaxismarker_expression_marker_plot = uidropdown(app.ExpressionSegmentationTab);
            app.Xaxismarker_expression_marker_plot.Items = {};
            app.Xaxismarker_expression_marker_plot.ValueChangedFcn = createCallbackFcn(app, @Xaxismarker_expression_marker_plotValueChanged, true);
            app.Xaxismarker_expression_marker_plot.Value = {};
            app.YaxismarkerDropDownLabel_4 = uilabel(app.ExpressionSegmentationTab);
            app.YaxismarkerDropDownLabel_4.Text = 'Y axis marker';
            app.Yaxismarker_expression_marker_plot = uidropdown(app.ExpressionSegmentationTab);
            app.Yaxismarker_expression_marker_plot.Items = {};
            app.Yaxismarker_expression_marker_plot.ValueChangedFcn = createCallbackFcn(app, @Yaxismarker_expression_marker_plotValueChanged, true);
            app.Yaxismarker_expression_marker_plot.Value = {};
            app.GenerateplotsforallYaxismarkercombinationsButton = uibutton(app.ExpressionSegmentationTab, 'state');
            app.GenerateplotsforallYaxismarkercombinationsButton.ValueChangedFcn = createCallbackFcn(app, @GenerateplotsforallYaxismarkercombinationsButtonValueChanged, true);
            app.GenerateplotsforallYaxismarkercombinationsButton.Text = 'Generate plots for all Y axis marker combinations';
            app.UpdateplotButton_expression_segmentation = uibutton(app.ExpressionSegmentationTab, 'push');
            app.UpdateplotButton_expression_segmentation.ButtonPushedFcn = createCallbackFcn(app, @UpdateplotButton_expression_segmentationPushed, true);
            app.UpdateplotButton_expression_segmentation.Text = 'Update plot';
            app.LinearsegmentdetailsButton = uibutton(app.ExpressionSegmentationTab, 'push');
            app.LinearsegmentdetailsButton.ButtonPushedFcn = createCallbackFcn(app, @LinearsegmentdetailsButtonPushed, true);
            app.LinearsegmentdetailsButton.Text = {'Linear segment'; 'details'};
            app.MoreSettings_expression_marker = uibutton(app.ExpressionSegmentationTab, 'state');
            app.MoreSettings_expression_marker.ValueChangedFcn = createCallbackFcn(app, @MoreSettings_expression_markerValueChanged, true);
            app.MoreSettings_expression_marker.Icon = fullfile(pathToMLAPP, 'Application Resources', 'Toolbar Images', 'More settings.png');
            app.MoreSettings_expression_marker.Text = 'More Settings';
            app.Popoutfigure_expression_segmentation = uibutton(app.ExpressionSegmentationTab, 'push');
            app.Popoutfigure_expression_segmentation.ButtonPushedFcn = createCallbackFcn(app, @Popoutfigure_expression_segmentationPushed, true);
            app.Popoutfigure_expression_segmentation.Text = 'Pop out figure';
            app.tSNETab = uitab(app.TabGroup);
            app.tSNETab.Title = 'tSNE';
            app.tSNEclusterLabel = uilabel(app.tSNETab);
            app.tSNEclusterLabel.Text = 'tSNE cluster ';
            app.tsne_lamp_label = uilabel(app.tSNETab);
            app.tsne_lamp_label.Enable = 'off';
            app.tsne_lamp_label.Text = 'Ready';
            app.tsne_lamp = uilamp(app.tSNETab);
            app.tsne_lamp.Enable = 'off';
            app.dataset_pick_tsne_label = uilabel(app.tSNETab);
            app.dataset_pick_tsne_label.Text = 'Dataset: ';
            app.dataset_pick_tsne = uidropdown(app.tSNETab);
            app.dataset_pick_tsne.Items = {};
            app.dataset_pick_tsne.ValueChangedFcn = createCallbackFcn(app, @dataset_pick_tsneValueChanged, true);
            app.dataset_pick_tsne.Value = {};
            app.Panel_tsne = uipanel(app.tSNETab);
            app.variant_select_box_tsne_label = uilabel(app.tSNETab);
            app.variant_select_box_tsne_label.Text = 'Select which variants to plot';
            app.variant_select_box_tsne = uilistbox(app.tSNETab);
            app.variant_select_box_tsne.Items = {};
            app.variant_select_box_tsne.Multiselect = 'on';
            app.variant_select_box_tsne.ValueChangedFcn = createCallbackFcn(app, @variant_select_box_tsneValueChanged, true);
            app.variant_select_box_tsne.Value = {};
            app.marker_select_box_tsne_label = uilabel(app.tSNETab);
            app.marker_select_box_tsne_label.Text = 'Select which markers to include';
            app.marker_select_box_tsne = uilistbox(app.tSNETab);
            app.marker_select_box_tsne.Items = {};
            app.marker_select_box_tsne.Multiselect = 'on';
            app.marker_select_box_tsne.Value = {};
            app.StarttSNEButton = uibutton(app.tSNETab, 'push');
            app.StarttSNEButton.ButtonPushedFcn = createCallbackFcn(app, @StarttSNEButtonPushed, true);
            app.StarttSNEButton.Text = 'Start tSNE';
            app.StoptSNEButton = uibutton(app.tSNETab, 'state');
            app.StoptSNEButton.Text = 'Stop tSNE';
            app.InspectSelectionButton_tsne = uibutton(app.tSNETab, 'state');
            app.InspectSelectionButton_tsne.ValueChangedFcn = createCallbackFcn(app, @InspectSelectionButton_tsneValueChanged, true);
            app.InspectSelectionButton_tsne.Enable = 'off';
            app.InspectSelectionButton_tsne.Text = 'Inspect Selection';
            app.PauseButton_tsne = uibutton(app.tSNETab, 'state');
            app.PauseButton_tsne.ValueChangedFcn = createCallbackFcn(app, @PauseButton_tsneValueChanged, true);
            app.PauseButton_tsne.Enable = 'off';
            app.PauseButton_tsne.Text = 'Pause';
            app.MoreSettings_tsne = uibutton(app.tSNETab, 'state');
            app.MoreSettings_tsne.ValueChangedFcn = createCallbackFcn(app, @MoreSettings_tsneValueChanged, true);
            app.MoreSettings_tsne.Icon = fullfile(pathToMLAPP, 'Application Resources', 'Toolbar Images', 'More settings.png');
            app.MoreSettings_tsne.Text = 'More Settings';
            app.PopoutButton_tsne = uibutton(app.tSNETab, 'push');
            app.PopoutButton_tsne.ButtonPushedFcn = createCallbackFcn(app, @PopoutButton_tsnePushed, true);
            app.PopoutButton_tsne.Text = 'Pop out';
            app.Hyperlink4 = uihyperlink(app.tSNETab);
            app.Hyperlink4.URL = 'https://distill.pub/2016/misread-tsne/';
            app.Hyperlink4.Text = 'How to read/adjust tSNE perplexity';
            app.Hyperlink5 = uihyperlink(app.tSNETab);
            app.Hyperlink5.URL = 'https://www.mathworks.com/help/stats/tsne-settings.html';
            app.Hyperlink5.Text = 'Adjusting Matlab''s tSNE settings';
            app.Hyperlink = uihyperlink(app.tSNETab);
            app.Hyperlink.URL = 'https://www.mathworks.com/help/stats/tsne.html';
            app.Hyperlink.Text = 'Matlab tSNE function documentation';
            app.UMAPTab = uitab(app.TabGroup);
            app.UMAPTab.Title = 'UMAP';
            app.UMAPclusterLabel = uilabel(app.UMAPTab);
            app.UMAPclusterLabel.Text = 'UMAP cluster ';
            app.umap_lamp_label = uilabel(app.UMAPTab);
            app.umap_lamp_label.Enable = 'off';
            app.umap_lamp_label.Text = 'Ready';
            app.umap_lamp = uilamp(app.UMAPTab);
            app.umap_lamp.Enable = 'off';
            app.dataset_pick_umap_label = uilabel(app.UMAPTab);
            app.dataset_pick_umap_label.Text = 'Dataset: ';
            app.dataset_pick_umap = uidropdown(app.UMAPTab);
            app.dataset_pick_umap.Items = {};
            app.dataset_pick_umap.ValueChangedFcn = createCallbackFcn(app, @dataset_pick_umapValueChanged, true);
            app.dataset_pick_umap.Value = {};
            app.Panel_umap = uipanel(app.UMAPTab);
            app.variant_select_box_tsne_label_2 = uilabel(app.UMAPTab);
            app.variant_select_box_tsne_label_2.Text = 'Select which variants to plot';
            app.variant_select_box_umap = uilistbox(app.UMAPTab);
            app.variant_select_box_umap.Items = {};
            app.variant_select_box_umap.Multiselect = 'on';
            app.variant_select_box_umap.Value = {};
            app.marker_select_box_tsne_label_2 = uilabel(app.UMAPTab);
            app.marker_select_box_tsne_label_2.Text = 'Select which markers to include';
            app.marker_select_box_umap = uilistbox(app.UMAPTab);
            app.marker_select_box_umap.Items = {};
            app.marker_select_box_umap.Multiselect = 'on';
            app.marker_select_box_umap.Value = {};
            app.StartUMAPButton = uibutton(app.UMAPTab, 'push');
            app.StartUMAPButton.ButtonPushedFcn = createCallbackFcn(app, @StartUMAPButtonPushed, true);
            app.StartUMAPButton.Text = 'Start UMAP';
            app.StopUMAPButton = uibutton(app.UMAPTab, 'state');
            app.StopUMAPButton.Text = 'Stop UMAP';
            app.InspectSelection_umap = uibutton(app.UMAPTab, 'state');
            app.InspectSelection_umap.ValueChangedFcn = createCallbackFcn(app, @InspectSelection_umapValueChanged, true);
            app.InspectSelection_umap.Enable = 'off';
            app.InspectSelection_umap.Text = 'Inspect Selection';
            app.PauseButton_umap = uibutton(app.UMAPTab, 'state');
            app.PauseButton_umap.ValueChangedFcn = createCallbackFcn(app, @PauseButton_umapValueChanged, true);
            app.PauseButton_umap.Enable = 'off';
            app.PauseButton_umap.Text = 'Pause';
            app.MoreSettings_umap = uibutton(app.UMAPTab, 'state');
            app.MoreSettings_umap.ValueChangedFcn = createCallbackFcn(app, @MoreSettings_umapValueChanged, true);
            app.MoreSettings_umap.Icon = fullfile(pathToMLAPP, 'Application Resources', 'Toolbar Images', 'More settings.png');
            app.MoreSettings_umap.Text = 'More Settings';
            app.PopoutButton_umap = uibutton(app.UMAPTab, 'push');
            app.PopoutButton_umap.ButtonPushedFcn = createCallbackFcn(app, @PopoutButton_umapPushed, true);
            app.PopoutButton_umap.Text = 'Pop out';
            app.Hyperlink6 = uihyperlink(app.UMAPTab);
            app.Hyperlink6.URL = 'https://pair-code.github.io/understanding-umap/';
            app.Hyperlink6.Text = 'Understanding UMAP';
            app.Hyperlink2 = uihyperlink(app.UMAPTab);
            app.Hyperlink2.URL = 'https://www.mathworks.com/matlabcentral/fileexchange/71902-uniform-manifold-approximation-and-projection-umap';
            app.Hyperlink2.Text = 'UMAP Package';
            app.PCATab = uitab(app.TabGroup);
            app.PCATab.Title = 'PCA';
            app.PrincipleComponentAnalysisLabel = uilabel(app.PCATab);
            app.PrincipleComponentAnalysisLabel.Text = 'Principle Component Analysis';
            app.Panel_PCA = uipanel(app.PCATab);
            app.variant_select_box_tsne_label_3 = uilabel(app.PCATab);
            app.variant_select_box_tsne_label_3.Text = 'Select which variants to plot';
            app.variant_select_box_PCA = uilistbox(app.PCATab);
            app.variant_select_box_PCA.Items = {};
            app.variant_select_box_PCA.Multiselect = 'on';
            app.variant_select_box_PCA.Value = {};
            app.marker_select_box_tsne_label_3 = uilabel(app.PCATab);
            app.marker_select_box_tsne_label_3.Text = 'Select which markers to include';
            app.marker_select_box_PCA = uilistbox(app.PCATab);
            app.marker_select_box_PCA.Items = {};
            app.marker_select_box_PCA.Multiselect = 'on';
            app.marker_select_box_PCA.Value = {};
            app.StartPCAButton = uibutton(app.PCATab, 'push');
            app.StartPCAButton.ButtonPushedFcn = createCallbackFcn(app, @StartPCAButtonPushed, true);
            app.StartPCAButton.Text = 'Start PCA';
            app.StopPCAButton = uibutton(app.PCATab, 'state');
            app.StopPCAButton.Text = 'Stop PCA';
            app.PopoutButton_umap_2 = uibutton(app.PCATab, 'push');
            app.PopoutButton_umap_2.ButtonPushedFcn = createCallbackFcn(app, @PopoutButton_PCAPushed, true);
            app.PopoutButton_umap_2.Text = 'Pop out';
            app.Hyperlink6_2 = uihyperlink(app.PCATab);
            app.Hyperlink6_2.URL = 'https://en.wikipedia.org/wiki/Principal_component_analysis';
            app.Hyperlink6_2.Text = 'What is PCA?';
            app.Hyperlink2_2 = uihyperlink(app.PCATab);
            app.Hyperlink2_2.URL = 'https://www.mathworks.com/help/stats/pca.html';
            app.Hyperlink2_2.Text = 'PCA Package';
            app.dataset_pick_umap_label_2 = uilabel(app.PCATab);
            app.dataset_pick_umap_label_2.Text = 'Dataset: ';
            app.dataset_pick_PCA = uidropdown(app.PCATab);
            app.dataset_pick_PCA.Items = {};
            app.dataset_pick_PCA.Value = {};
            app.PCADimensionOptionDropDownLabel = uilabel(app.PCATab);
            app.PCADimensionOptionDropDownLabel.Text = 'PCA Dimension Option:';
            app.PCADimensionOptionDropDown = uidropdown(app.PCATab);
            app.PCADimensionOptionDropDown.Items = {'2D', '3D'};
            app.PCADimensionOptionDropDown.ItemsData = {'2', '3'};
            app.PCADimensionOptionDropDown.Value = '2';
            app.BiexponentialPlotTab = uitab(app.TabGroup);
            app.BiexponentialPlotTab.Title = 'Biexponential Plot';
            app.BiexponentialAnalysisLabel = uilabel(app.BiexponentialPlotTab);
            app.BiexponentialAnalysisLabel.Text = 'Biexponential Analysis';
            app.Panel_biexp = uipanel(app.BiexponentialPlotTab);
            app.variant_select_box_tsne_label_4 = uilabel(app.BiexponentialPlotTab);
            app.variant_select_box_tsne_label_4.Text = 'Select which variants to plot';
            app.variant_select_box_biexp = uilistbox(app.BiexponentialPlotTab);
            app.variant_select_box_biexp.Items = {};
            app.variant_select_box_biexp.Multiselect = 'on';
            app.variant_select_box_biexp.Value = {};
            app.marker_select_box_tsne_label_4 = uilabel(app.BiexponentialPlotTab);
            app.marker_select_box_tsne_label_4.Text = 'Select marker 1 for plot''s x dimension';
            app.marker_select_box_biexp = uilistbox(app.BiexponentialPlotTab);
            app.marker_select_box_biexp.Items = {};
            app.marker_select_box_biexp.Multiselect = 'on';
            app.marker_select_box_biexp.Value = {};
            app.StartBiexponentialPlotButton = uibutton(app.BiexponentialPlotTab, 'push');
            app.StartBiexponentialPlotButton.ButtonPushedFcn = createCallbackFcn(app, @StartBiexpButtonPushed, true);
            app.StartBiexponentialPlotButton.Text = 'Start Biexponential Plot';
            app.StopBiexponentialPlotButton = uibutton(app.BiexponentialPlotTab, 'state');
            app.StopBiexponentialPlotButton.Text = 'Stop Biexponential Plot';
            app.PopoutButton_biexp = uibutton(app.BiexponentialPlotTab, 'push');
            app.PopoutButton_biexp.ButtonPushedFcn = createCallbackFcn(app, @PopoutButton_biexppushed, true);
            app.PopoutButton_biexp.Text = 'Pop out';
            app.Hyperlink6_3 = uihyperlink(app.BiexponentialPlotTab);
            app.Hyperlink6_3.URL = 'https://docs.flowjo.com/flowjo/graphs-and-gating/gw-transform-overview/gw-transform-benefits/';
            app.Hyperlink6_3.Text = 'What is biexponential plot?';
            app.dataset_pick_umap_label_3 = uilabel(app.BiexponentialPlotTab);
            app.dataset_pick_umap_label_3.Text = 'Dataset: ';
            app.dataset_pick_biexp = uidropdown(app.BiexponentialPlotTab);
            app.dataset_pick_biexp.Items = {};
            app.dataset_pick_biexp.Value = {};
            app.marker_select_box_tsne_label_5 = uilabel(app.BiexponentialPlotTab);
            app.marker_select_box_tsne_label_5.Text = 'Select marker 2 for plot''s y dimension';
            app.marker_select_box_biexp2 = uilistbox(app.BiexponentialPlotTab);
            app.marker_select_box_biexp2.Items = {};
            app.marker_select_box_biexp2.Multiselect = 'on';
            app.marker_select_box_biexp2.Value = {};
            app.AGNESTab = uitab(app.TabGroup);
            app.AGNESTab.Title = 'AGNES';
            app.AgglomerativeNestingHierachicalClusteringLabel = uilabel(app.AGNESTab);
            app.AgglomerativeNestingHierachicalClusteringLabel.Text = 'Agglomerative Nesting Hierachical Clustering';
            app.Panel_AGNES_xaxis = uipanel(app.AGNESTab);
            app.Panel_AGNES_xaxis.ButtonDownFcn = createCallbackFcn(app, @Panel_AGNES_xaxisButtonDown, true);
            app.variant_select_box_AGNES_label = uilabel(app.AGNESTab);
            app.variant_select_box_AGNES_label.Text = 'Select Excel sheet to run AGNES on (Click the box below!) ';
            app.excel_select_box_agnes = uilistbox(app.AGNESTab);
            app.excel_select_box_agnes.Items = {};
            app.excel_select_box_agnes.Multiselect = 'on';
            app.excel_select_box_agnes.ClickedFcn = createCallbackFcn(app, @excel_select_box_agnesClicked, true);
            app.excel_select_box_agnes.Value = {};
            app.RunAGNESButton = uibutton(app.AGNESTab, 'push');
            app.RunAGNESButton.ButtonPushedFcn = createCallbackFcn(app, @RunAGNESButtonPushed, true);
            app.RunAGNESButton.Text = 'Run AGNES';
            app.Hyperlink6_4 = uihyperlink(app.AGNESTab);
            app.Hyperlink6_4.URL = 'https://medium.com/@bittusinghtech/agnes-agglomerative-clustering-cb2cef928a96';
            app.Hyperlink6_4.Text = 'What is AGNES?';
            app.SaveAGNESPlotButton = uibutton(app.AGNESTab, 'push');
            app.SaveAGNESPlotButton.ButtonPushedFcn = createCallbackFcn(app, @SaveAGNESPlotButtonPushed, true);
            app.SaveAGNESPlotButton.Text = 'Save AGNES Plot';
            app.xaxisLabel = uilabel(app.AGNESTab);
            app.xaxisLabel.Text = 'x axis';
            app.yaxisLabel = uilabel(app.AGNESTab);
            app.yaxisLabel.Text = 'y axis';
            app.Panel_AGNES_yaxis = uipanel(app.AGNESTab);
            app.SliderLabel = uilabel(app.UIFigure);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.UIFigure);
            app.ContextMenu_filter_channel_absolute = uicontextmenu(app.UIFigure);
            app.Apply_on_all_absolute_filter = uimenu(app.ContextMenu_filter_channel_absolute);
            app.Apply_on_all_absolute_filter.MenuSelectedFcn = createCallbackFcn(app, @Apply_on_all_absolute_filterSelected, true);
            app.Apply_on_all_absolute_filter.Separator = 'on';
            app.Apply_on_all_absolute_filter.Text = 'Apply across every dataset with same variant name ';
            % Assign app.ContextMenu_filter_channel_absolute
            app.UpdateabsolutechannelfilterButton.ContextMenu = app.ContextMenu_filter_channel_absolute;
            app.ContextMenu_filter_channel_quantile = uicontextmenu(app.UIFigure);
            app.Apply_on_all_quantile_filter = uimenu(app.ContextMenu_filter_channel_quantile);
            app.Apply_on_all_quantile_filter.MenuSelectedFcn = createCallbackFcn(app, @Apply_on_all_quantile_filterMenuSelected, true);
            app.Apply_on_all_quantile_filter.Separator = 'on';
            app.Apply_on_all_quantile_filter.Text = 'Apply across every data set experiment';
            % Assign app.ContextMenu_filter_channel_quantile
            app.UpdatequantilechannelfilterButton.ContextMenu = app.ContextMenu_filter_channel_quantile;
            app.ContextMenu_scatter_filter_apply = uicontextmenu(app.UIFigure);
            app.apply_scatter_filter_to_all_variants = uimenu(app.ContextMenu_scatter_filter_apply);
            app.apply_scatter_filter_to_all_variants.MenuSelectedFcn = createCallbackFcn(app, @apply_scatter_filter_to_all_variantsMenuSelected, true);
            app.apply_scatter_filter_to_all_variants.Separator = 'on';
            app.apply_scatter_filter_to_all_variants.Text = 'Apply same polygon filter across all variants of selected dataset pack';
            % Assign app.ContextMenu_scatter_filter_apply
            app.SavepolygonfilterButton.ContextMenu = app.ContextMenu_scatter_filter_apply;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SCADR

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end